classdef main_20200510 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeuterosGUI                     matlab.ui.Figure
        Panel_Import                    matlab.ui.container.Panel
        DataImport_Heading              matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        ClusterBrowseButton             matlab.ui.control.Button
        ClusterFileTextBoxLabel         matlab.ui.control.Label
        ClusterFileTextBox              matlab.ui.control.EditField
        StateBDropDownLabel             matlab.ui.control.Label
        StateBDropDown                  matlab.ui.control.DropDown
        StateADropDown                  matlab.ui.control.DropDown
        StateADropDownLabel             matlab.ui.control.Label
        ProteinDropDown                 matlab.ui.control.DropDown
        ProteinDropDownLabel            matlab.ui.control.Label
        DeuteriumEditBoxLabel           matlab.ui.control.Label
        DeuteriumEditBox                matlab.ui.control.EditField
        Residue_StartEditBox            matlab.ui.control.EditField
        Residue_ColonLabel              matlab.ui.control.Label
        Residue_Label                   matlab.ui.control.Label
        Residue_EndEditBox              matlab.ui.control.EditField
        BXCorrectionLabel               matlab.ui.control.Label
        StateA_ControlDropDown          matlab.ui.control.DropDown
        StateB_ControlDropDown          matlab.ui.control.DropDown
        LogTextArea                     matlab.ui.control.TextArea
        TabGroup_Figure                 matlab.ui.container.TabGroup
        UptakePlotsTab                  matlab.ui.container.Tab
        KineticsPanel                   matlab.ui.container.Panel
        OptionsPanel                    matlab.ui.container.Panel
        PlotStylingLabel                matlab.ui.control.Label
        DataOptionsLabel                matlab.ui.control.Label
        StatisticsLabel                 matlab.ui.control.Label
        LogtimeSwitchLabel              matlab.ui.control.Label
        LogtimeSwitch                   matlab.ui.control.Switch
        DatapointsSwitchLabel           matlab.ui.control.Label
        DatapointsSwitch                matlab.ui.control.Switch
        LineSwitchLabel                 matlab.ui.control.Label
        LineSwitch                      matlab.ui.control.Switch
        LinewidthEditFieldLabel         matlab.ui.control.Label
        LinewidthEditField              matlab.ui.control.NumericEditField
        MarkersizeEditFieldLabel        matlab.ui.control.Label
        MarkersizeEditField             matlab.ui.control.NumericEditField
        ShowSDSwitchLabel               matlab.ui.control.Label
        ShowSDSwitch                    matlab.ui.control.Switch
        ShowCISwitchLabel               matlab.ui.control.Label
        ShowCISwitch                    matlab.ui.control.Switch
        PeptidesStatisticsTextArea      matlab.ui.control.TextArea
        CapsizeEditFieldLabel           matlab.ui.control.Label
        CapsizeEditField                matlab.ui.control.NumericEditField
        xaxisEditFieldLabel             matlab.ui.control.Label
        xaxis_low                       matlab.ui.control.EditField
        xaxis_high                      matlab.ui.control.EditField
        yaxisLabel                      matlab.ui.control.Label
        yaxis_low                       matlab.ui.control.EditField
        yaxis_high                      matlab.ui.control.EditField
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        alphaDropDown                   matlab.ui.control.DropDown
        alphaDropDownLabel              matlab.ui.control.Label
        KineticsPlotLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        PeptideTree                     matlab.ui.container.Tree
        PeptideTreeLabel                matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        StandardMenuTab                 matlab.ui.container.Tab
        Panel_Coverage                  matlab.ui.container.Panel
        Panel_Advanced                  matlab.ui.container.Panel
        Panel_PlotOptions               matlab.ui.container.Panel
        Coverage_Heading                matlab.ui.control.Label
        Advanced_Heading                matlab.ui.control.Label
        Coverage_DataTypeDropDownLabel  matlab.ui.control.Label
        Coverage_DataTypeDropDown       matlab.ui.control.DropDown
        Coverage_PlotButton             matlab.ui.control.Button
        Coverage_ExportButton           matlab.ui.control.Button
        Advanced_StatisticsDropDownLabel  matlab.ui.control.Label
        Advanced_StatisticsDropDown     matlab.ui.control.DropDown
        MG_Heading                      matlab.ui.control.Label
        Advanced_PlotButton             matlab.ui.control.Button
        Advanced_ExportButton           matlab.ui.control.Button
        MG_ExportCoverageCheckBox       matlab.ui.control.CheckBox
        MG_ColorCoverageDropDownLabel   matlab.ui.control.Label
        MG_ColorCoverageDropDown        matlab.ui.control.DropDown
        MG_ColorNoCoverageDropDownLabel  matlab.ui.control.Label
        MG_ColorNoCoverageDropDown      matlab.ui.control.DropDown
        MG_ColorRedundancyDropDownLabel  matlab.ui.control.Label
        MG_ColorRedundancyDropDown      matlab.ui.control.DropDown
        MG_ExportAdvancedCheckBox       matlab.ui.control.CheckBox
        MG_ExportButton                 matlab.ui.control.Button
        Coverage_PlotTypeDropDownLabel  matlab.ui.control.Label
        Coverage_PlotTypeDropDown       matlab.ui.control.DropDown
        MG_ExportToDropDownLabel        matlab.ui.control.Label
        MG_ExportToDropDown             matlab.ui.control.DropDown
        MG_ChainEditBoxLabel            matlab.ui.control.Label
        MG_ChainEditBox                 matlab.ui.control.EditField
        Advanced_ConfidenceDropDownLabel  matlab.ui.control.Label
        Advanced_ConfidenceDropDown     matlab.ui.control.DropDown
        Coverage_PeptidesDropDownLabel  matlab.ui.control.Label
        Coverage_PeptidesDropDown       matlab.ui.control.DropDown
        Coverage_BorderColorDropDownLabel  matlab.ui.control.Label
        Coverage_BorderColorDropDown    matlab.ui.control.DropDown
        Coverage_BarspacingEditFieldLabel  matlab.ui.control.Label
        Coverage_BarSpacingEditBox      matlab.ui.control.NumericEditField
        Coverage_BarthicknessEditFieldLabel  matlab.ui.control.Label
        Coverage_BarThicknessEditBox    matlab.ui.control.NumericEditField
        Advanced_UnitsDropDownLabel     matlab.ui.control.Label
        Advanced_UnitsDropDown          matlab.ui.control.DropDown
        Advanced_PlotTypeDropDownLabel  matlab.ui.control.Label
        Advanced_PlotTypeDropDown       matlab.ui.control.DropDown
        HighlightEditFieldLabel         matlab.ui.control.Label
        Advanced_HighlightEditField     matlab.ui.control.EditField
        Advanced_yaxisLabel             matlab.ui.control.Label
        Advanced_yaxis_low              matlab.ui.control.EditField
        Advanced_yaxis_high             matlab.ui.control.EditField
        Advanced_clear                  matlab.ui.control.Button
        DataTables                      matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        RawClusterTab                   matlab.ui.container.Tab
        RawCluster_UITable              matlab.ui.control.Table
        RawCluster_SaveButton           matlab.ui.control.Button
        RemovedPeptidesTab              matlab.ui.container.Tab
        Removed_UITable                 matlab.ui.control.Table
        Removed_SaveButton              matlab.ui.control.Button
        BXControlTab                    matlab.ui.container.Tab
        BXControl_UITable               matlab.ui.control.Table
        BXControl_SaveButton            matlab.ui.control.Button
        BXNoncorrectedTab               matlab.ui.container.Tab
        Correction_UITable              matlab.ui.control.Table
        Correction_SaveButton           matlab.ui.control.Button
        StateAStateTab                  matlab.ui.container.Tab
        StateAState_UITable             matlab.ui.control.Table
        StateAState_SaveButton          matlab.ui.control.Button
        StateBStateTab                  matlab.ui.container.Tab
        StateBState_UITable             matlab.ui.control.Table
        StateBState_SaveButton          matlab.ui.control.Button
        StatesABCommonTab               matlab.ui.container.Tab
        StatesABCommon_UITable          matlab.ui.control.Table
        StatesABCommon_SaveButton       matlab.ui.control.Button
        StateBStateATab                 matlab.ui.container.Tab
        StateBStateA_UITable            matlab.ui.control.Table
        StateBStateA_SaveButton         matlab.ui.control.Button
        FilteredPeptidesTab             matlab.ui.container.Tab
        FilteredData_UITable            matlab.ui.control.Table
        FilteredData_SaveButton         matlab.ui.control.Button
        DeuterosLogo                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        % Set things that you need to access outside of each callback
        
        cluster              % cluster contains filename, etc.
        startEndResidues     % start and end residues 
        A                    % Data structures
        B
        AB
        common
        diff
        combinedTables        % Stores a table of all peptides
        plotHandle                    % gives number of currently plotted plots

        cGreen =  [0.95, 0.64, 0.23];     % Green for common plot 
        cBlue =   [0.45, 0.67, 0.84];     % Blue for State A
        cPink =   [0.87, 0.57, 0.74];     % Pink for state B
        cPurple = [0.43, 0.57, 0.80];     % Purple for comparative coverage plot
        cSalmon = [1.00, 0.56, 0.42];     % Salmon for comparative coverage
        cRedun1 = [0.24, 0.15, 0.66];     % For redundancy 1 
        
        cWoodsGrey = [0.7 0.7 0.7];          % For nonsignificant peptides of woods plots
        cWoodsRed  = [0.92, 0.14, 0.20];     % For deprotected peptides
        cWoodsBlue = [0.00, 0.42, 0.88];     % For protected peptides
        
        blue_white_red  % Colour gradients  
        white_red
        
        statistics           % Stores the statistical values, e.g. SEM, df, N for Hybrid significance test
        filteredTable        % Statistically treated difference data
        plotOptions          % Holds advanced plot options
        
        timepointOrder       % order of timepoints, categories
        timepointOrder_Sum   % order of timepoints, categories with sum

        % Global controls for data printing
        printReps = 1; % print 'repeat' columns in data tables
        printStats = 1; % print statistical columns in data tables
        printCompact = 1;
 
        % Fontsizes
        fs_axisLabels
        fs_axisTickLabels
        fs_legend
        fs_figureTitle
        fs_figureText
        
        % Plot sizes
        kineticsPlotPosition
        volcanoMarkerSize
        
        % Plot handles
        BarcodePlotHandle
        bc1 % structure of barcode plot
        bc2 
        Advanced_scrollPanel % Description
    end
    
    methods (Access = private)
        
        % Functions for Import button
        
        function [cluster_filename,cluster_pathname,cluster_fullpathname] = readCluster(~,~)
            % Opens a UIgetfile dialog window to allow user to retrieve cluster file
            % Gets filename, pathname and fullpathname to import cluster data 
            
            % 1. File import
            check = evalin('base', 'exist(''previous_path'')' );
        
            if ~check
                disp('Previous path not found, please locate folder..');
                [cluster_filename, cluster_pathname] = uigetfile('*.csv','Select cluster file ..');
                if cluster_pathname==0, return; end
                
                assignin('base','previous_path',cluster_pathname)
                fprintf('Assigned %s as last accessed folder\n', cluster_pathname)
            else
                
                previous_path = evalin('base', 'previous_path');
                
                if ~previous_path | ~isstring(previous_path)
                    disp('Previous path not found, please locate folder..')
                
                    [cluster_filename, cluster_pathname] = uigetfile('*.csv','Select cluster file ..');
                    if cluster_pathname==0, return; end
                    
                    assignin('base','previous_path',cluster_pathname)
                    fprintf('Assigned %s as last accessed folder\n', cluster_pathname)
                else
                    previous_path = evalin('base','previous_path');
                    fprintf('Previous folder %s found\n', previous_path)
                    [cluster_filename, cluster_pathname] = uigetfile([previous_path,'/*.csv'],'Select cluster file ..');
                    if cluster_pathname==0, return; end
    
                    assignin('base','previous_path',cluster_pathname)
                    fprintf('Assigned %s as last accessed folder\n', cluster_pathname)
                end
            end
    
            cluster_fullpathname = strcat(cluster_pathname, cluster_filename);
        end
    
        function [selectedState, selectedCluster, selectedStatePeptides] = stateSelect(app,state,selection,D20_val)
            % Filters the cluster file based on a state selected in the import panel dropdown
            
            selectedCluster = state(strcmp(state.State,selection),:); % grab the data relevant to the dropdown menu selected
            selectedStatePeptides = unique(selectedCluster.Sequence,'Stable'); % get all unique peptides of that state 
            
            selectedState = app.aggregate(selectedCluster,D20_val,selectedStatePeptides);
                        
        end
    
        function aggregatedState = aggregate(~,state,D2OPercentage,listOfPeptides)
            % Takes cluster-style table and aggregates replicate data into one row, with one standard deviation, preserves charge states
            % cluster file already sorted by protein and state, need to get each charge state and then loop over the exposure + (MHP-Center*z)
            % Second input is D2OPercentage which is user input in the editbox
            
            % Get a list of timepoints to check later
            numberOfTimepoints = length(unique(state.Exposure));

            % Data headers for the following tables
            variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure','z','N',...
                             'RT_ave','RT_SD','RT_Reps','Inten_Reps','Center_ave','Center_SD','Center_Reps',...
                             'Uptake_ave','Uptake_SD','Uptake_Reps','RU_ave','RU_SD','RU_Reps'};
            
            % Store everything in new array
            aggregatedState = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames); 
            
            % Loop over unique peptides found in the cluster file
            for p = 1:length(listOfPeptides)
                % For each unique peptide, get all rows (all charge states and timepoints)
                peptideMatch = state(strcmp(state.Sequence,listOfPeptides(p)),:);
                
                % Get number of charge states found for that peptide
                numberOfChargeStates = unique(peptideMatch.z);
                
                % Loop over each charge state 
                chargeStateBlock = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames); 
                for n = 1:length(numberOfChargeStates)
                    
                    % Get rows of that charge state
                    chargeMatch = peptideMatch(peptideMatch.z == numberOfChargeStates(n),:);
                    
                    % Get the timepoints associated with that charge state, including references
                    listOfTimepoints = unique(chargeMatch.Exposure);
                    
                    % If only one timepoint is found, i.e. only reference, then continue
                    if length(unique(listOfTimepoints)) == 1
                        fprintf('\n   State %s: Only one timepoint found for charge state %d+ of peptide %d-%d: %s, skipping..',chargeMatch.State{1},numberOfChargeStates(n),peptideMatch.Start(1),peptideMatch.End(1),peptideMatch.Sequence{1})
                        continue
                    end
                    
                    % Check if reference (t0) exists in listOfTimepoints, if yes then loop over the remaining timepoints
                    if ismember(0,listOfTimepoints)
                        
                        % Get all reference rows
                        reference_rows = chargeMatch(chargeMatch.Exposure == 0, :);
                        numberOfReferences = height(reference_rows);
                        
                        % All averages and standard deviations need to be intensity weighted
                        
                        % Intensity - use this to weight every ave and SD value below
                        ref_Inten_reps = reference_rows.Inten';
                        
                        % RT
                        ref_RT_reps = reference_rows.RT';
                        ref_RT_ave = wmean(ref_RT_reps,ref_Inten_reps);
                        ref_RT_SD = std(ref_RT_reps,ref_Inten_reps);
                       
                        % Center
                        ref_Cen_reps = reference_rows.Center';
                        ref_Cen_ave = wmean(ref_Cen_reps,ref_Inten_reps);
                        ref_Cen_SD = std(ref_Cen_reps,ref_Inten_reps);
                        
                        % Uptake
                        ref_Uptake_reps = (numberOfChargeStates(n)*ref_Cen_reps)-reference_rows.MHP';
                        ref_Uptake_ave = wmean(ref_Uptake_reps,ref_Inten_reps)';
                        ref_Uptake_SD = std(ref_Uptake_reps);
                        
                        % Relative uptake
                        ref_RU_reps = ref_Uptake_reps./(reference_rows.MaxUptake.*str2double(D2OPercentage)/100)';
                        ref_RU_ave = nanmean(ref_RU_reps)';
                        ref_RU_SD = std(ref_RU_reps);

                        % If there's only the reference and no other exposures, then dont run this part
                        timeBlock = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames); 
                        if length(listOfTimepoints) > 1

                            for i=2:length(listOfTimepoints)
                                timeName = listOfTimepoints(i);
                                
                                % Get all timepoint rows
                                time_rows = chargeMatch(chargeMatch.Exposure == timeName, :);
                                numberOfReplicates = height(time_rows);
                                
                                % All averages and standard deviations need to be intensity weighted
                                
                                % Intensity - use this to weight every ave and SD value below
                                time_Inten_reps = time_rows.Inten';
                                time_ref_Inten_product = reshape(ref_Inten_reps'.*time_Inten_reps,[length(ref_Inten_reps)*length(time_Inten_reps),1]);
                                
                                % RT
                                time_RT_reps = time_rows.RT';
                                time_RT_ave = wmean(time_RT_reps,time_Inten_reps);
                                time_RT_SD = std(time_RT_reps,time_Inten_reps);
                               
                                % Center
                                time_Cen_reps = time_rows.Center';
                                time_Cen_ave = wmean(time_Cen_reps,time_Inten_reps);
                                time_Cen_SD = std(time_Cen_reps,time_Inten_reps);
                                
                                % Uptake
                                time_Uptake_reps = (numberOfChargeStates(n)*time_Cen_reps)-time_rows.MHP';
                                time_Uptake_ave_product = reshape(time_Uptake_reps-ref_Uptake_reps',[length(time_Uptake_reps)*length(ref_Uptake_reps),1]);
                                
                                % For uptake reps, divide the time_Uptake_ave_product into sets of number of references to get equivalent uptake
                                % values per replicate rather than use time_Uptake_reps which are the deuterium uptake values without
                                % subtracting reference
                                time_Uptake_reps_refs = [];
                                time_intensity_refs = [];
                                for w = 1:length(time_Uptake_ave_product)/numberOfReferences
                                    rep_ave = time_Uptake_ave_product((w*numberOfReferences)-(numberOfReferences-1):w*numberOfReferences);
                                    int_rep_ave = time_ref_Inten_product((w*numberOfReferences)-(numberOfReferences-1):w*numberOfReferences);
                                    
                                    rep_ave = wmean(rep_ave,int_rep_ave);
                                    
                                    time_Uptake_reps_refs = [time_Uptake_reps_refs rep_ave];
                                    time_intensity_refs = [time_intensity_refs mean(int_rep_ave)];
                                end
                                
                                time_Uptake_ave = wmean(time_Uptake_ave_product,time_ref_Inten_product);
                                time_Uptake_SD = std(time_Uptake_ave_product,time_ref_Inten_product);
                                
                                % Relative uptake
                                time_RU_reps = time_Uptake_reps./(time_rows.MaxUptake.*str2double(D2OPercentage)/100)';
                                time_RU_ave_product = reshape(time_RU_reps-ref_RU_reps',[length(time_RU_reps)*length(ref_RU_reps),1]);
                                
                                time_RU_reps_refs = [];
                                time_intensity_refs = [];
                                for w = 1:length(time_RU_ave_product)/numberOfReferences
                                    rep_ave = time_RU_ave_product((w*numberOfReferences)-(numberOfReferences-1):w*numberOfReferences);
                                    int_rep_ave = time_ref_Inten_product((w*numberOfReferences)-(numberOfReferences-1):w*numberOfReferences);
                                    
                                    rep_ave = wmean(rep_ave,int_rep_ave);
                                    
                                    time_RU_reps_refs = [time_RU_reps_refs rep_ave];
                                    time_intensity_refs = [time_intensity_refs mean(int_rep_ave)];
                                end
                                
                                time_RU_ave = wmean(time_RU_ave_product,time_ref_Inten_product);
                                time_RU_SD = std(time_RU_ave_product,time_ref_Inten_product);
                                
                                time_agg = table(time_rows.Protein(1), ...
                                        time_rows.Start(1), ...
                                        time_rows.End(1), ...
                                        time_rows.Sequence(1), ...
                                        time_rows.Modification(1), ...
                                        time_rows.Fragment(1), ...
                                        time_rows.MaxUptake(1), ...
                                        time_rows.MHP(1), ...
                                        time_rows.State(1), ...
                                        time_rows.Exposure(1), ...
                                        numberOfChargeStates(n), ...
                                        numberOfReplicates, ...
                                        time_RT_ave, ...
                                        time_RT_SD, ...
                                        {time_RT_reps}, ...
                                        {time_Inten_reps}, ...
                                        time_Cen_ave, ...
                                        time_Cen_SD, ...
                                        {time_Cen_reps}, ...
                                        time_Uptake_ave, ...
                                        time_Uptake_SD, ...
                                        {time_Uptake_reps_refs}, ...
                                        time_RU_ave, ...
                                        time_RU_SD, ...
                                        {time_RU_reps_refs}, ...
                                        'VariableNames',variableNames);
                                    
                                % If no timepoints other than 0, append reference rows to the charge state block
                                timeBlock = [timeBlock; time_agg];
                            end
                            
                            % If there were timepoints, set the reference to 0
                            ref_Uptake_reps = zeros(1,length(ref_Uptake_reps));
                            ref_Uptake_ave = 0;
                            ref_Uptake_SD = 0;
                            
                            % Relative uptake
                            ref_RU_reps = zeros(1,length(ref_RU_reps));
                            ref_RU_ave = 0;
                            ref_RU_SD = 0;
                        end
                        
                        % Now add to the 
                        ref_agg = table(chargeMatch.Protein(1), ...
                                        chargeMatch.Start(1), ...
                                        chargeMatch.End(1), ...
                                        chargeMatch.Sequence(1), ...
                                        chargeMatch.Modification(1), ...
                                        chargeMatch.Fragment(1), ...
                                        chargeMatch.MaxUptake(1), ...
                                        chargeMatch.MHP(1), ...
                                        chargeMatch.State(1), ...
                                        chargeMatch.Exposure(1), ...
                                        numberOfChargeStates(n), ...
                                        numberOfReferences, ...
                                        ref_RT_ave, ...
                                        ref_RT_SD, ...
                                        {ref_RT_reps}, ...
                                        {ref_Inten_reps}, ...
                                        ref_Cen_ave, ...
                                        ref_Cen_SD, ...
                                        {ref_Cen_reps}, ...
                                        ref_Uptake_ave, ...
                                        ref_Uptake_SD, ...
                                        {ref_Uptake_reps}, ...
                                        ref_RU_ave, ...
                                        ref_RU_SD, ...
                                        {ref_RU_reps}, ...
                                        'VariableNames',variableNames);
                                    
                        % If no timepoints other than 0, append reference rows to the charge state block
                        chargeStateBlock = [chargeStateBlock; ref_agg; timeBlock];
                    else
                        fprintf('\n   State %s: No non-reference timepoints found for charge state %d+ of peptide %d-%d: %s, skipping..',chargeMatch.State{1},numberOfChargeStates(n),peptideMatch.Start(1),peptideMatch.End(1),peptideMatch.Sequence{1})
                        break
                    end
                end
                
                % Now go into each chargeStateBlock and check how many unique charges are found, remove the series with the highest Uptake_SD
                if ~isempty(chargeStateBlock)
                    peptideCharges = unique(chargeStateBlock.z);
                    
                    if length(peptideCharges) > 1 % if more than 1 charge is present
                        fprintf('\n   Multiple charge states found assigned for peptide %d-%d: %s:',chargeStateBlock.Start(1),chargeStateBlock.End(1),chargeStateBlock.Sequence{1})
                        
                        chargePick_variableNames = {'Sequence','z','max_ref_SD','max_nonref_SD','max_SD','all_timepoints'};
                        chargePick = cell2table(cell(0,6),'VariableNames',chargePick_variableNames);
                        for z = 1:length(peptideCharges)
                            chargeMatch = chargeStateBlock(chargeStateBlock.z == peptideCharges(z),:);
                            
                            max_ref_SD = nanmax(chargeMatch.Uptake_SD(chargeMatch.Exposure == 0,:));
                            max_nonref_SD = nanmax(chargeMatch.Uptake_SD(chargeMatch.Exposure ~= 0,:));
                            max_SD = nanmax(chargeMatch.Uptake_SD);
                            
                            all_timepoints = length(unique(chargeMatch.Exposure)) == numberOfTimepoints;
                            
                            % Add to new table and appnd to chargePick table
                            charge_row = table(chargeMatch.Sequence(1),chargeMatch.z(1),max_ref_SD,max_nonref_SD,max_SD,all_timepoints,'VariableNames',chargePick_variableNames);
                        
                            chargePick = [chargePick; charge_row];
                            
                            fprintf('\n      Charge %d+',peptideCharges(z))
                            fprintf('\n          Max reference uptake SD: %.3f', charge_row.max_ref_SD)
                            fprintf('\n          Max non-reference uptake SD: %.3f', charge_row.max_nonref_SD)
                            fprintf('\n          Max overall uptake SD: %.3f', charge_row.max_SD)
                            
                            if charge_row.all_timepoints == 1
                                fprintf('\n          All timepoints are present')
                            else
                                fprintf('\n          Timepoints are missing')
                            end
                        end
                        
                        % Charge removal criteria
                        z_to_keep = chargePick.z(chargePick.max_SD == nanmin(chargePick.max_SD),:); % get charge state with lowest max_SD
                        
                        fprintf('\n      Keeping charge with lowest overall uptake SD (%d+) .. \n',z_to_keep)
                        chargeStateBlock = chargeStateBlock(chargeStateBlock.z == z_to_keep,:); % remove all other charges
                    end
                end
                
                % Append to the overall table
                aggregatedState = [aggregatedState; chargeStateBlock];
            end
            
            % Sort the data by Start residue first
            aggregatedState = sortrows(aggregatedState,'Start','ascend');
        end
    
        function residueList = stateToResidues(~,state,peptideList)
            % Takes a state table and list of unique peptides and returns a list of residues in the table
            
            [~,ia,~] = intersect(state.Sequence,peptideList); % get list of peptides common between both states from larger state table
            peptideTable = state(ia,:); % truncate
            
            residueList = [peptideTable.Start peptideTable.End]; % make [start end] vector
        end
    
        function lengths = getLengths(~,list)
            % run through the list of residues and get the lengths
            lengths = [];
            
            for i = 1:length(list)
                lengths = [lengths; length(list(i,1):1:list(i,2))];
            end  
        end
    
        function residueList = getuniqueresidues(~,listOfResidues)
            % Takes in a 2xn array of start end residues for each peptide and converts it
            % into a per-residue 1xn array
            
            residueList = [];
            for res = 1:length(listOfResidues)
                residueList = [residueList, listOfResidues(res,1):1:listOfResidues(res,2)];
            end
            
            residueList = unique(residueList);
        end
    
        function residueList = getnonuniqueresidues(~,listOfResidues)
            % Takes in a 2xn array of start end residues for each peptide and converts it
            % into a per-residue 1xn array
            
            residueList = [];
            for res = 1:length(listOfResidues)
                residueList = [residueList, listOfResidues(res,1):1:listOfResidues(res,2)];
            end
        end
    
        function state = getStateInfo(app,state,expectedResidues)
            % Given a state file, returns information
            
            state.shortestPeptide = min(min(state.state.End-state.state.Start));
            state.longestPeptide = max(max(state.state.End-state.state.Start));
            
            state.timepoints = round(unique(state.state.Exposure)*1000,3)/1000;
            state.averagePeptideLength = nanmean(state.peptideLengths);
            state.coverage = app.coverage(state.listOfResidues,expectedResidues); % calculate coverage
            
            state.averageRedundancy = app.getRedundancy(state.listOfResidues,app.startEndResidues);
            
            state.replicates = app.findMaxN_nonSum(state.state(state.state.Exposure ~= 0,:));
            state.repeatability = nanmean(state.state.Uptake_SD);
        end
        
        function redundancy = getRedundancy(app,listOfResidues,startEndRes)
            % Given a list of residues and start end res, return redundancy

            % Flatten residue list keeping redundant residue coverage
            redundantList = [];
            for i = 1:length(listOfResidues)
                redundantList = [redundantList listOfResidues(i,1):listOfResidues(i,2)];
            end
            
            % Append residues from theoretical start:end
            redundantList = [redundantList startEndRes(1):startEndRes(2)];
            tabList = tabulate(redundantList);
            tabList(:,2) = tabList(:,2)-1; % subtract 1 from the count 
            % redundancy = mean(tabList(:,2));
            redundancy = mean(tabList(tabList(:,2) > 0, 2)); % Get per-residue redundancy of only areas with coverage
        end

        function state = getStateInfoCommon(app,stateA,stateB,common,expectedResidues)
            % Given a state file, returns information
            
            state.shortestPeptide = min([min(stateA.End-stateA.Start),min(stateB.End-stateB.Start)]);
            state.longestPeptide = max([max(stateA.End-stateA.Start),max(stateB.End-stateB.Start)]);
            
            state.timepoints = round(unique(stateA.Exposure)*1000,3)/1000;
            state.averagePeptideLength = nanmean(common.peptideLengths);
            state.coverage = app.coverage(common.listOfResidues,expectedResidues); % calculate coverage
            
            state.averageRedundancy = app.getRedundancy(common.listOfResidues,app.startEndResidues);
            
            state.replicates = app.findMaxN_nonSum([stateA;stateB]);
            state.repeatability = nanmean([stateA.Uptake_SD; stateB.Uptake_SD]);
        end
        
        function stateCoverage = coverage(~,state,expectedResidues)
            % used to calculate the peptide coverage of any given state table
            residues = []; 
            for i=1:length(state)
                residues = [residues state(i,1):1:state(i,2)];
            end
            
            stateCoverage = (length(unique(residues))/expectedResidues)*100; % calculate coverage 
        end
    
        function NofRepeats = findMaxN_nonSum(~,datatable)
            % Finds the highest number of repeats in data that isnt SUM
                        
            NofRepeats = 0;
            for i = 1:height(datatable)
                if ~strcmp(datatable.Exposure(i),'Sum') && datatable.N(i) > NofRepeats
                    NofRepeats = datatable.N(i);
                end
            end
        end
    
        
        function [appA, appB, deletedRows, common] = getCommon(app,appA,appB,expectedResidues)
            % Given two state-type data tables, returns state.common data tables
            % for each state containing only common peptides between the two
            % states
            
            % Calculate common lists
            [appA.common, appB.common, deletedRows] = app.intersectAB(appA.state,appB.state);
            
            appA.common = sortrows(appA.common,{'Start'});
            appB.common = sortrows(appB.common,{'Start'});

            common.sequences = unique(appA.common.Sequence);
            common.listOfResidues = app.stateToResidues(appA.common,common.sequences);
            
            % Get linear list of all residues
            common.residues = app.getuniqueresidues(common.listOfResidues);
            common.nonUniqueResidues = app.getnonuniqueresidues(common.listOfResidues);
            common.peptideLengths = app.getLengths(common.listOfResidues); % contains the lengths of all peptides
            
            % Log things
            common.shortestPeptide = min([min(appA.common.End-appA.common.Start),min(appB.common.End-appB.common.Start)]);
            common.longestPeptide = max([max(appA.common.End-appA.common.Start),max(appB.common.End-appB.common.Start)]);
            
            common.timepoints = round(unique(appA.common.Exposure)*1000,3)/1000;
            common.averagePeptideLength = nanmean(common.peptideLengths);
            common.coverage = app.coverage(common.listOfResidues,expectedResidues); % calculate coverage
            
            common.averageRedundancy = app.getRedundancy(common.listOfResidues,app.startEndResidues);
            
            common.replicates = app.findMaxN_nonSum([appA.common; appB.common]);
            common.repeatability = nanmean([appA.common.Uptake_SD; appB.common.Uptake_SD]);
        end
        
        function [newStateA, newStateB, deletedRows] = intersectAB(~,stateA,stateB)
            % Takes as input, two state tables from the same input data - returns two filtered tables sharing only common peptides exposures
            deletedRows = [];
            
            % Get two A B columns that include peptide-exposure pairs for every row in states A and
            % B;
            listOfPeptideExposuresA = join([stateA.Sequence regexprep(cellstr(num2str(stateA.Exposure)), '\s', '')],'-');
            listOfPeptideExposuresB = join([stateB.Sequence regexprep(cellstr(num2str(stateB.Exposure)), '\s', '')],'-');
            
            [commonPeptideExposures,ida,idb] = intersect(listOfPeptideExposuresA,listOfPeptideExposuresB,'legacy'); % use intersect to get a list of common peptides between the two states
            
            [~,ia] = setdiff(listOfPeptideExposuresA,listOfPeptideExposuresB); % get the rows that are to be deleted
            [~,ib] = setdiff(listOfPeptideExposuresB,listOfPeptideExposuresA);
            
            if ~isempty(ia) % ia or ib will be empty if there are no peptides to delete
                deletedRows = [deletedRows; stateA(ia,:)];
            elseif ~isempty(ib)
                deletedRows = [deletedRows; stateB(ib,:)];
            end
                
            fprintf('\n\n   Number of peptides across all timepoints in state A: %d', length(listOfPeptideExposuresA));
            fprintf('\n   Number of peptides across all timepoints in state B: %d', length(listOfPeptideExposuresB));
            fprintf('\n\n   Number of common peptides across all timepoints between states A and B: %d', length(commonPeptideExposures));
            
            if isempty(commonPeptideExposures)
                errordlg('State A and State B do not share any peptides or timepoints','File Error')
                return
            else
                fprintf('\n\n   Removing all unique peptide timepoints in each state .. ');
                
                newStateA = sortrows(stateA(ida,:),'Start','ascend'); % extract a new table of only common peptide timepoints
                newStateB = sortrows(stateB(idb,:),'Start','ascend');
            end
        end
        
        function [sumTable, ignoredPeptides] = addSum(app,state)
            % Adds the sum datapoint to the data table 
            % Only sums the peptides which have all timepoints present
            
            % Gets a list of existing state table column headings as this is the same after sum
            variableNames = state.Properties.VariableNames;

            % Initialise two empty tables - one for the sum data and one for peptides with missing exposures
            sumTable = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames);
            ignoredPeptides = sumTable;
            
            % Now loop over the number of peptides
            listOfPeptides = unique(state.Sequence);
            listOfTimepoints = unique(state.Exposure);
            
            for p = 1:length(listOfPeptides)
                peptideData = state(strcmp(state.Sequence,listOfPeptides(p)),:);
                
                % If all timepoints are found, calculate sum
                if length(intersect(peptideData.Exposure,listOfTimepoints)) == length(listOfTimepoints)
                    
                    % Calculate Sum values
                    [sumCenter, sumCenter_SD, sumCenter_Reps] = app.getSumUptake(peptideData.Center_Reps);
                    [sumUptake, sumUptake_SD, sumUptake_Reps] = app.getSumUptake(peptideData.Uptake_Reps);
                    [sumRT, sumRT_SD, sumRT_Reps] = app.getSumUptake(peptideData.RT_Reps);
                    [sumRU, sumRU_SD, sumRU_Reps] = app.getSumUptake(peptideData.RU_Reps);
                    
                    % Other accessory values
                    sumMaxUptake = sum(peptideData.MaxUptake);
                    sumN = sum(peptideData.N);
                    
                    peptideRow = table(peptideData.Protein(1),...
                                       peptideData.Start(1),...
                                       peptideData.End(1),...
                                       peptideData.Sequence(1),...
                                       peptideData.Modification(1),...
                                       peptideData.Fragment(1),...
                                       sumMaxUptake,...
                                       peptideData.MHP(1),...
                                       peptideData.State(1),...
                                       {'Sum'},...
                                       peptideData.z(1),...
                                       sumN,...
                                       sumRT,...
                                       sumRT_SD,...
                                       sumRT_Reps,...
                                       NaN,...
                                       sumCenter,...
                                       sumCenter_SD,...
                                       sumCenter_Reps,...
                                       sumUptake,...
                                       sumUptake_SD,...
                                       sumUptake_Reps,...
                                       sumRU,...
                                       sumRU_SD,...
                                       sumRU_Reps,...
                                       peptideData.Correction(1),...
                                       'VariableNames',variableNames);
                                   
                    % Append the peptide non-sum data and sum data to the new table
                    % First need to change the exposure names of the non-sum data from
                    % double to string
                    peptideData.Exposure = string(peptideData.Exposure);
                    sumTable = [sumTable; peptideData; peptideRow];
                                   
                else % Otherwise add the peptides to the ignored Peptide table
                    ignoredPeptides = [ignoredPeptides; peptideData];
                end
            end
            
            % Sort the rows of the tables
            sumTable = sortrows(sumTable,'Start');
            ignoredPeptides = sortrows(ignoredPeptides,'Start');
        end

        function [uptake, Uptake_SD, combs_sum] = getSumUptake(~,replicates)
            n_reps = numel(replicates); % number of replicates
            
            combs = cell(1,n_reps); % pre-define to generate comma-separated list
            [combs{end:-1:1}] = ndgrid(replicates{end:-1:1}); % the reverse order in these two
            % comma-separated lists is needed to produce the rows of the result matrix 
            combs = cat(n_reps+1, combs{:}); %concat the n n-dim arrays along dimension n+1
            combs = reshape(combs,[],n_reps); %reshape to obtain desired matrix
            
            % combs provides all combinations of deriving the sum uptake
            % from replicate measurements
            combs_sum = nansum(combs'); % get the sum of each combination
            uptake = nanmean(combs_sum);
            
            % To get uptake standard deviation for sum, compute all
            % combinations of replicates of each timepoint and get
            % standard deviation of that population
            Uptake_SD = std(combs_sum);
        end

        function diff = getDifferenceInfo(app,stateA,stateB,common)
            diff.common = sortrows(app.stateDifference(stateA.common,stateB.common,common.sequences),{'Start'}); % this calculates (input B)-(input A)
            
            diff.sequences = common.sequences;
            diff.residues = common.residues;
            diff.timepoints = common.timepoints;
            diff.listOfResidues = common.listOfResidues;
            diff.nonUniqueResidues = app.getnonuniqueresidues(diff.listOfResidues);
            diff.averagePeptideLength = common.averagePeptideLength;
            diff.coverage = common.coverage;
            diff.averageRedundancy = common.averageRedundancy;
            diff.replicates = common.replicates;
            diff.repeatability = common.repeatability;
        end
        
        function differenceTable = stateDifference(app,stateA,stateB,listOfPeptides)
            % This takes two state tables, a list of common peptides, and calculates the difference in uptake/RU of each peptide/timepoint
            
            % Set up new difference table
            varNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure','z','N',...
                        'RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B',...
                        'Center_diff_ave','Center_diff_SD','Center_reps_A','Center_reps_B',...
                        'Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B'...
                        'RU_diff_ave','RU_diff_SD','RU_reps_A','RU_reps_B',...
                        'Correction','Welch_pValue'};
                    
            differenceTable = cell2table(cell(0,length(varNames)),'VariableNames',varNames);
            
            % Loop over the peptides in the common sequence list, find them in the state A
            % and state B tables and then calculate difference between the two
            for p = 1:length(listOfPeptides)
                peptideMatch_A = stateA(strcmp(stateA.Sequence,listOfPeptides{p}),:); % get the section of that peptide
                peptideMatch_B = stateB(strcmp(stateB.Sequence,listOfPeptides{p}),:); % get the section of that peptide
                
                % Get name of A and B states
                name_stateA = peptideMatch_A.State{1};
                name_stateB = peptideMatch_B.State{1};
                
                % Get timepoints in the peptideMatch tables
                stateTimepoints = unique(peptideMatch_A.Exposure);
                
                differenceTimeTable = cell2table(cell(0,length(varNames)),'VariableNames',varNames);
                for r = 1:length(stateTimepoints)
                    exposureMatch_A = peptideMatch_A(peptideMatch_A.Exposure == peptideMatch_A.Exposure(r),:); % get the section of that peptide
                    exposureMatch_B = peptideMatch_B(peptideMatch_B.Exposure == peptideMatch_B.Exposure(r),:); % get the section of that peptide
                    
                    % Calculate differences
                    diff_RT_reps_A = exposureMatch_A.RT_Reps{:};
                    diff_RT_reps_B = exposureMatch_B.RT_Reps{:};
                    diff_RT_reps = reshape((diff_RT_reps_B-diff_RT_reps_A')',1,[]);
                    diff_RT_ave = nanmean(diff_RT_reps);
                    diff_RT_SD = std(diff_RT_reps);
                    
                    diff_Center_reps_A = exposureMatch_A.Center_Reps{:};
                    diff_Center_reps_B = exposureMatch_B.Center_Reps{:};
                    diff_Center_reps = reshape((diff_Center_reps_B-diff_Center_reps_A')',1,[]);
                    diff_Center_ave = nanmean(diff_Center_reps);
                    diff_Center_SD = std(diff_Center_reps);
                    
                    diff_Uptake_reps_A = exposureMatch_A.Uptake_Reps{:};
                    diff_Uptake_reps_B = exposureMatch_B.Uptake_Reps{:};
                    diff_Uptake_reps = reshape((diff_Uptake_reps_B-diff_Uptake_reps_A')',1,[]);
                    diff_Uptake_ave = nanmean(diff_Uptake_reps);
                    diff_Uptake_SD = std(diff_Uptake_reps);
                    
                    diff_RU_reps_A = exposureMatch_A.RU_Reps{:};
                    diff_RU_reps_B = exposureMatch_B.RU_Reps{:};
                    diff_RU_reps = reshape((diff_RU_reps_B-diff_RU_reps_A')',1,[]);
                    diff_RU_ave = nanmean(diff_RU_reps);
                    diff_RU_SD = std(diff_RU_reps);
                    
                    % Perform Welch's T-test
                    [~,Welch_p] = app.Welch_ttest(exposureMatch_A.Uptake_Reps{:},exposureMatch_B.Uptake_Reps{:});
                    
                    % Check if state A's peptide correction status is the same as state B's peptide correction status
                    % If both are numbers, this is fine
                    if class(exposureMatch_A.Correction) ~= class(exposureMatch_B.Correction)
                        errordlg(sprintf('Correction status of State A and State B do not match for peptide %d-%d: %s!',exposureMatch_A.Start(1),exposureMatch_A.End(1),exposureMatch_A.Sequence(1)))
                        return
                    end
                    
                    % Add to a new table row and append to the differenRow for this block
                    differenceTimeRow = table(exposureMatch_A.Protein,...
                                              exposureMatch_A.Start,...
                                              exposureMatch_A.End,...
                                              exposureMatch_A.Sequence,...
                                              exposureMatch_A.Modification,...
                                              exposureMatch_A.Fragment,...
                                              exposureMatch_A.MaxUptake,...
                                              exposureMatch_A.MHP,...
                                              {sprintf('%s - %s',name_stateB,name_stateA)},...
                                              exposureMatch_A.Exposure,...
                                              exposureMatch_A.z,...
                                              0,...
                                              diff_RT_ave,...
                                              diff_RT_SD,...
                                              {diff_RT_reps_A},...
                                              {diff_RT_reps_B},...
                                              diff_Center_ave,...
                                              diff_Center_SD,...
                                              {diff_Center_reps_A},...
                                              {diff_Center_reps_B},...
                                              diff_Uptake_ave,...
                                              diff_Uptake_SD,...
                                              {diff_Uptake_reps_A},...
                                              {diff_Uptake_reps_B},...
                                              diff_RU_ave,...
                                              diff_RU_SD,...
                                              {diff_RU_reps_A},...
                                              {diff_RU_reps_B},...
                                              exposureMatch_A.Correction,...
                                              Welch_p,...
                                              'VariableNames',varNames);
                                          
                    % Append to the table
                    differenceTimeTable = [differenceTimeTable; differenceTimeRow];
                end
                
                % Append each peptide table to the overall difference table
                differenceTable = [differenceTable; differenceTimeTable];
            end
            
            % Final sort
            differenceTable = sortrows(differenceTable,'Start');
        end

        function SEM_table = calculateHybridSEM(~,data)
            % Calculates the squared error of the mean for significance testing later
            % Following Hageman & Weis (2019), Anal Chem, 91(13):8008-8016. doi: 10.1021/acs.analchem.9b01325.
            
            % SEM is calculated for all times of two states
            
            % First get non Sum and non 0 data
            dataTable = data(~strcmp(data.Exposure,'Sum') & ~strcmp(data.Exposure,'0'),:);
            
            listOfStates = unique(dataTable.State,'Stable');
            SEM_table = cell2table(cell(0,3),'VariableNames', {'Type','df','SEM'});
            
            % Now for each state, calculate the pooled SD
            sp_table = cell2table(cell(0,3), 'VariableNames', {'State','N','Sp'});
            
            for s = 1:length(listOfStates)
                stateData = dataTable(strcmp(dataTable.State,listOfStates(s)),:);
                stateName = unique(stateData.State,'stable');
                
                numerator = nansum((stateData.Uptake_SD.^2).*(stateData.N-1));
                denominator = nansum(stateData.N-1);
                
                sp = sqrt(numerator/denominator); % pooled standard deviation
                maxRep = nanmax(stateData.N);
                
                sp_row = table(stateName,maxRep,sp,'VariableNames',{'State','N','Sp'});
                sp_table = [sp_table;sp_row];
            end
            
            NonSumSEM = sp_table;
            
            % Now calculate the SEM
            spA = (sp_table.Sp(1)^2)/sp_table.N(1);
            spB = (sp_table.Sp(2)^2)/sp_table.N(2);
            df = sp_table.N(1)+sp_table.N(2)-2;
            
            SEM = sqrt(spA+spB);

            SEM_row = table({'Non-sum'},df,SEM,'VariableNames', {'Type','df','SEM'});
            SEM_table = [SEM_table; SEM_row];
            
            % Calculate for sum data
            dataTable = data(strcmp(data.Exposure,'Sum'),:);
            
            % Calculate pooled SD for each state
            sp_table = cell2table(cell(0,3), 'VariableNames', {'State','N','Sp'});
            for s = 1:length(listOfStates)
                stateData = dataTable(strcmp(dataTable.State,listOfStates(s)),:);
                stateName = unique(stateData.State);
                
                numerator = (nansum((stateData.Uptake_SD.^2).*(stateData.N-1)));
                denominator = nansum(stateData.N-1);
                
                sp = sqrt(numerator/denominator); % pooled standard deviation
                maxRep = nanmax(stateData.N);
                
                sp_row = table(stateName,maxRep,sp,'VariableNames',{'State','N','Sp'});
                sp_table = [sp_table;sp_row];
            end

            % Now calculate the SEM
            spA = (sp_table.Sp(1)^2)/NonSumSEM.N(1);
            spB = (sp_table.Sp(2)^2)/NonSumSEM.N(2);
            
            SEM = sqrt(spA+spB);
            
            SEM_row = table({'Sum'},df,SEM,'VariableNames', {'Type','df','SEM'});
            SEM_table = [SEM_table; SEM_row];
        end
    
        function [h,p] = Welch_ttest(~,stateAReps,stateBReps)
            % Calculate P-values for each differential comparison according to Welch's
            % t-test
            [h,p] = ttest2(stateAReps,stateBReps,'Vartype','unequal');
        end
    
        function diffData = peptideSignificance(~,diffData)
            % Appends a linear model to the difference table
            
            nonSumData = diffData(~strcmp(diffData.Exposure,'Sum'),:);
            
            states = strsplit(nonSumData.State{1},' - '); % {'B'}    {'A'}
            
            listOfPeptides = unique(nonSumData.Sequence);

            models = cell2table(cell(0,2),'VariableNames',{'Sequence','model'});
            for i = 1:length(listOfPeptides)
                peptideName = listOfPeptides{i};
                peptideData = nonSumData(strcmp(nonSumData.Sequence,peptideName),:);
                
                peptide = cell2table(cell(0,3),'VariableNames',{'State','Exposure','Uptake'});
                for j = 1:height(peptideData)
                    
                    exposureData = peptideData(j,:);
                    exposure = exposureData.Exposure;
                    
                    % State A data first
                    repeatsA = exposureData.RU_reps_A{:}';
                    numRepeatsA = length(repeatsA);

                    stateNameA = strings(numRepeatsA,1);
                    stateNameA(:) = states{2};
                    
                    exposureNameA = strings(numRepeatsA,1);
                    exposureNameA(:) = exposure;

                    repTableA = table(stateNameA, exposureNameA, repeatsA,'VariableNames',{'State','Exposure','Uptake'});
                    
                    % State B data
                    repeatsB = exposureData.RU_reps_B{:}';
                    numRepeatsB = length(repeatsB);

                    stateNameB = strings(numRepeatsB,1);
                    stateNameB(:) = states{1};
                    
                    exposureNameB = strings(numRepeatsB,1);
                    exposureNameB(:) = exposure;
                    
                    repTableB = table(stateNameB, exposureNameB, repeatsB,'VariableNames',{'State','Exposure','Uptake'});
                    
                    peptide = [peptide; repTableA; repTableB];
                end
                
                % Perform linear regression at this level of the loop
                
                % Order the peptides by state A first then state B
                peptide = [peptide(strcmp(peptide.State,states{2}),:);peptide(strcmp(peptide.State,states{1}),:)];
                
                lm = fitlm(peptide,'Uptake ~ 1 + State * Exposure','ResponseVar','Uptake',...
                                'PredictorVars',{'State','Exposure'},...
                                'CategoricalVar',{'State','Exposure'});
                            
                % Get coefficient rows that correspond to inter-state timepoint
                % comparisons and get those p-values
                numberOfExposures = length(unique(peptide.Exposure))-1; %-1 to remove 0
                stateRows = tail(lm.Coefficients,numberOfExposures); 
                rowNames = stateRows.Properties.RowNames;
                
                exposures = [];
                for j=1:length(rowNames)
                    temp = split(rowNames{j},':');
                    temp = split(temp(2),'_');
                    exposures = [exposures; str2double(temp(2))];
                end
                
                % Append the exposures to the coefficients table
                stateRows.Exposure = exposures;
                peptide_model.stateRows = stateRows;
                peptide_model.lm = lm;
                
                tableRow = table({peptideName},peptide_model,'VariableNames',{'Sequence','model'});
                models = [models; tableRow];
            end
            
            % Loop over difference table and for each peptide, get the model and append it
            model = {};
            for i = 1:height(diffData)
                diffSeq = diffData.Sequence(i);
                diffExposure = str2double(diffData.Exposure(i));
                
                modelData = models.model(strcmp(models.Sequence,diffSeq));
                timepointData = modelData.stateRows(modelData.stateRows.Exposure == diffExposure,:);
                pValue = timepointData.pValue;
                linearModel = modelData.lm;
                
                if isnan(diffExposure) || (diffExposure == 0)
                    model{i,1} = NaN;
                    model{i,2} = NaN;
                    model{i,3} = NaN;
                else
                    model{i,1} = pValue;
                    model{i,2} = linearModel;
                    model{i,3} = string(diffExposure);
                end
            end
            
            modelTable = cell2table(model,'VariableNames',{'Peptide_pValue','LinearModel','Exposure_test'});
            
            % Append the linear model data to the difference table
            diffData = [diffData modelTable];
            
            % Check if the exposures and exposure_test columns match up, otherwise error
            checkData = diffData(~strcmp(diffData.Exposure,'Sum') & ~strcmp(diffData.Exposure,'0'),:);
            if checkData.Exposure ~= checkData.Exposure_test
                errordlg('Exposures test not passed')
                return
            end
            
            % Remove exposuer text column
            diffData.Exposure_test = [];
            
        end
    
        function [state_corrected, not_corrected_peptides, backExchangeTextState, backExchangeText] = BXcorrection(app,state,allStates,controlName,deuteriumFraction)
            % This will calculate back exchange of a control data set and apply correction
            % data  to the state data table

            % Get the data for the control state
            [control.state, ~, ~] = app.stateSelect(allStates,controlName,deuteriumFraction);
            
            % Generate the back exchange LUT take from the control data - this just
            % formats the controlState cluster data into a [peptide m0 m100] format so its
            % easier to access m0 and m100 values for back exchange correction
            % deuteriumFraction is used to also calculate the abs and RFU of the data for
            % aggregate - nothing special
            
            control.state = app.backexchange_table(control.state,deuteriumFraction);
            
            control.meanBackExchange = nanmean(control.state.BackExchange);
            control.IQRBackExchange = iqr(control.state.BackExchange);
            
            backExchangeText = sprintf('%.1f%% / %.1f%%',control.meanBackExchange,control.IQRBackExchange);
            backExchangeTextState = app.StateA_ControlDropDown.Value;
            
            % Now correct DU, DU_reps, RFU and RFU_reps
            backExchangePeptides = unique(control.state.Sequence,'Stable');
            
            for i = 1:length(backExchangePeptides)
                peptide = state(strcmp(state.Sequence,backExchangePeptides(i)),:);
                correction = control.state(strcmp(control.state.Sequence,backExchangePeptides(i)),:);
                
                if ~isempty(peptide) && ~isempty(correction)
                    m = peptide.Center_ave .* peptide.z;
                    m0 = correction.Center_ave_m0 .* correction.Charge_m0;
                    m100 = correction.Center_ave_m100 .* correction.Charge_m100;
                    N = peptide.MaxUptake;

                    RU_corr = (m-m0)./(m100-m0);
                    Uptake_corr = RU_corr.*N;
                    
                    scalingFactor = Uptake_corr./peptide.Uptake_ave; % calculate the scaling factor for the peptide and apply it to the replicate data
                    scalingFactor = scalingFactor(end);

                    state.Uptake_ave(strcmp(state.Sequence,backExchangePeptides(i)),:) = Uptake_corr;
                    state.RU_ave(strcmp(state.Sequence,backExchangePeptides(i)),:) = RU_corr;
                    
                    uptake_replicates = state.Uptake_Reps(strcmp(state.Sequence,backExchangePeptides(i)),:);
                    RU_replicates = state.RU_Reps(strcmp(state.Sequence,backExchangePeptides(i)),:);
                    
                    uptake_replicates_corr = {};
                    RU_replicates_corr = {};
                    for j = 1:length(uptake_replicates)
                        uptake_replicates_corr{end+1,1} = uptake_replicates{j}*scalingFactor;
                        RU_replicates_corr{end+1,1} = RU_replicates{j}*scalingFactor;
                    end

                    state.Uptake_Reps(strcmp(state.Sequence,backExchangePeptides(i)),:) = uptake_replicates_corr;
                    state.RU_Reps(strcmp(state.Sequence,backExchangePeptides(i)),:) = RU_replicates_corr;
                    
                    state.Correction(strcmp(state.Sequence,backExchangePeptides(i)),:) = {correction.BackExchange};
                end
            end
            
            % Set the t=0 Uptake and RU to 0 because sometimes it comes out as a weird float
            state.Uptake_ave(state.Exposure == 0) = 0;
            state.RU_ave(state.Exposure == 0) = 0;
            
            state_corrected = state;
            not_corrected_peptides = state(strcmp(state.Correction,'Not corrected'),:);
            
            % Add back exchange table to the uitable
            printReps = 0;
            if printReps == 0 | app.printReps == 0
                repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), control.state.Properties.VariableNames);
                copy.control.state = removevars(control.state,control.state.Properties.VariableNames(repPosition));
            end
            
            printCompact = 1;
            if printCompact == 1 | app.printCompact == 1
                copy.control.state = removevars(copy.control.state,{'Modification','Fragment'});
            end
                    
            app.BXControl_UITable.Data = [app.BXControl_UITable.Data; copy.control.state];
            app.BXControl_UITable.ColumnName = copy.control.state.Properties.VariableNames';
        end
    
        function state = backexchange_table(~,state,d_Frac)
            % Converts back exchange state data into look up table for correction later
            
            listOfPeptides = unique(state.Sequence,'Stable');
            
            reference_data = state(state.Exposure == 0,:);
            correction_data = state(state.Exposure ~= 0,:);
            
            if length(unique(correction_data.Exposure)) ~= 1
                errordlg('Control data contains too many states')
                return
            end
            
            back_exchange = [];
            for i = 1:length(listOfPeptides)
                ref_block = reference_data(strcmp(reference_data.Sequence,listOfPeptides{i}),:);
                cor_block = correction_data(strcmp(correction_data.Sequence,listOfPeptides{i}),:);
                
                if ~isempty(ref_block) && ~isempty(cor_block)
                    m0 = ref_block.Center_ave*ref_block.z;
                    m100 = cor_block.Center_ave*cor_block.z;
                    N = cor_block.MaxUptake;
                    Dfrac = str2double(d_Frac)/100;
                    
                    bck = (1-(m100-m0)/(N*Dfrac))*100; % Back-exchange equation
                    
                    back_exchange = [back_exchange;...
                                     cor_block.Protein, cor_block.Start, cor_block.End, cor_block.Sequence, cor_block.Modification, cor_block.Fragment,...
                                     cor_block.MaxUptake, cor_block.State,...
                                     ref_block.Exposure, ref_block.z, ref_block.N,...
                                     ref_block.MHP, ref_block.RT_ave, ref_block.RT_SD, ref_block.RT_Reps,...
                                     ref_block.Inten_Reps,...
                                     ref_block.Center_ave, ref_block.Center_SD, ref_block.Center_Reps,...
                                     ref_block.Uptake_ave, ref_block.Uptake_SD, ref_block.Uptake_Reps, ...
                                     ref_block.RU_ave, ref_block.RU_SD, ref_block.RU_Reps, ...
                                     cor_block.Exposure, cor_block.z, cor_block.N,...
                                     cor_block.MHP, cor_block.RT_ave, cor_block.RT_SD, cor_block.RT_Reps,...
                                     cor_block.Inten_Reps,...
                                     cor_block.Center_ave, cor_block.Center_SD, cor_block.Center_Reps,...
                                     cor_block.Uptake_ave, cor_block.Uptake_SD, cor_block.Uptake_Reps, ...
                                     cor_block.RU_ave, cor_block.RU_SD, cor_block.RU_Reps, ...
                                     bck];
                end
                
            end
            
            varNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','State', ...
                        'Exposure_m0','Charge_m0','N_m0','MHP_m0','RT_ave_m0','RT_SD_m0','RT_Reps_m0','Inten_Reps_m0',...
                        'Center_ave_m0','Center_SD_m0','Center_Reps_m0','Uptake_ave_m0','Uptake_SD_m0','Uptake_Reps_m0','RU_ave_m0','RU_SD_m0','RU_Reps_m0',...
                        'Exposure_m100','Charge_m100','N_m100','MHP_m100','RT_ave_m100','RT_SD_m100','RT_Reps_m100','Inten_Reps_m100',...
                        'Center_ave_m100','Center_SD_m100','Center_Reps_m100','Uptake_ave_m100','Uptake_SD_m100','Uptake_Reps_m100','RU_ave_m100','RU_SD_m100','RU_Reps_m100',...
                        'BackExchange'};

            state = cell2table(back_exchange,'VariableNames',varNames);
        end
    
        % End of functions for import button
        
        % Start of Coverage section
        
        function polyplotCoverage(app,listOfResidues,axesName,color,stateName,stateCoverage,peptides)
            % Plots coverage map - does not plot overlapping peptides, only single
            % polygons for each stretch of region with coverage
            h = app.initialiseCoveragePlot(axesName); % Set up new axes with labels

            resStart = app.startEndResidues(1);
            resEnd = app.startEndResidues(2);
            y_ax_top = 4; y_ax_bot = -4;
            
            numberOfPeptides = length(listOfResidues);
            missingResidueColumns = app.getCoveragePeptides(unique(app.getuniqueresidues(listOfResidues)),resStart,resEnd);
            coverage = sprintf('%s (%.1f %% coverage across %d peptides)',stateName,stateCoverage,numberOfPeptides);

            % Plot one set of peptide coverage bar ---------------------------------------------------------
            
            y_top = 1; y_bot = -1; buffer = 0.5;
            app.coverageBars(h,missingResidueColumns,y_top,y_bot,resStart,resEnd,buffer,color);
            
            % Axis padding
            padding = (resEnd*1.05)-resEnd;
            
            if peptides == 0
                axis(h,[resStart-padding resEnd+padding y_ax_bot y_ax_top]);
            else
                % Set up colour table for peptides
                colourList = app.genColorList(listOfResidues,color);
                
                app.plotPeptides(h,colourList,-2);
                xlim(h,[resStart-padding resEnd+padding]);
            end
            
            % ----------------------------------------------------------------------------------------------
            % Plot the coverage text - x,y coordinates for the coverage label, State (%% coverage)
            textX = -(resEnd-resStart)*0.01;
            textY = y_top+(y_ax_top-y_ax_bot)/7;
            
            text(h,textX,textY,coverage,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none');
        end

        function polyplotCoverageDouble(app,listOfResiduesA,listOfResiduesB,axesName,stateAName,stateBName,colorA,colorB,peptides)
            % Takes a list of unique residues found in data and plots as a linear coverage map using polygons per residue
            
            h = app.initialiseCoveragePlot(axesName); % Set up new axes with labels
            
            resStart = app.startEndResidues(1);
            resEnd = app.startEndResidues(2);
            y_ax_top = 4.5; y_ax_bot = -1;
            
            missingResidueColumns_A = app.getCoveragePeptides(unique(app.getuniqueresidues(listOfResiduesA)),resStart,resEnd);
            missingResidueColumns_B = app.getCoveragePeptides(unique(app.getuniqueresidues(listOfResiduesB)),resStart,resEnd);
            
            coverageA = sprintf('%s (%.1f %% coverage)',stateAName,app.A.coverage);
            coverageB = sprintf('%s (%.1f %% coverage)',stateBName,app.B.coverage);
            
            % ----------------------------------------------------------------------------------------------
            % Plot peptide set A coverage bar --------------------------------------------------------------
            
            y_top = 1; y_bot = 0; buffer = 0.5;
            
            app.coverageBars(h,missingResidueColumns_A,y_top,y_bot,resStart,resEnd,buffer,colorA);
            
            % Plot the coverage text - x,y coordinates for the coverage label, State (%% coverage)
            textX = -(resEnd-resStart)*0.01;
            textY = y_top+(y_ax_top-y_ax_bot)/3;
            
            text(h,textX,textY,coverageA,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none');

            if peptides == 1
                % Set up colour table for peptides
                colourListA = app.genColorList(listOfResiduesA,colorA);
                
                peptideRow = app.plotPeptides(h,colourListA,-0.5);
                
                % These control where the state B coverage plot is moved to if peptides
                % are turned on
                interBarDistanceMultiplier = 5; % controls how far the bottom state's coverage bar is from the bottom of the redundancy peptides
                barStartHeight = peptideRow-(interBarDistanceMultiplier*app.Coverage_BarSpacingEditBox.Value);
                y_top = barStartHeight; y_bot = barStartHeight-1; buffer = 0.5;
            else
                y_top = 3; y_bot = 2; buffer = 0.5;
            end

            % ----------------------------------------------------------------------------------------------
            % Plot peptide set B coverage bar --------------------------------------------------------------
            textY = y_top+(y_ax_top-y_ax_bot)/3;

            app.coverageBars(h,missingResidueColumns_B,y_top,y_bot,resStart,resEnd,buffer,colorB);
            
            text(h,textX,textY,coverageB,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none');
            
            % Axis padding
            padding = (resEnd*1.05)-resEnd;

            if peptides == 0
                axis(h,[resStart-padding resEnd+padding -1 4.5]);
            else
                % Set up colour table for peptides
                colourListB = app.genColorList(listOfResiduesB,colorB);
                
                app.plotPeptides(h,colourListB,barStartHeight-1.5);
                xlim(h,[resStart-padding resEnd+padding]);
            end
        end
        
        function flattenedData = flatten2(app,dataTable,startResidue,endResidue,mode,dataType)
            % Takes single state data table and flattens to residue level for custom column
        
            ini = zeros(height(dataTable),endResidue-startResidue+1);
        
            for i = 1:height(dataTable)
                row = dataTable(i,:);
                if mode == 0
                    if dataType == 1
                        ini(i,row.Start:row.End) = row.Uptake_ave;
                    else
                        ini(i,row.Start:row.End) = row.RU_ave;
                    end
                else
                    if dataType == 1
                        ini(i,row.Start:row.End) = row.Uptake_diff_ave;
                    else
                        ini(i,row.Start:row.End) = row.RU_diff_ave;
                    end
                end
            end
        
            % Replace o with Nan then mean
            ini(ini == 0) = NaN;
            ini_mean = nanmean(ini);
        
            flattenedData = [startResidue:endResidue; ini_mean]';
        end
        
        function [BarcodePlotHandle, bc1, bc2] = polyplotBarcodeDouble(app,common_A,diff_common,axesName,dataType)
            % Splits by timepoints and plots one strip per timepoint
            
            % Create two new uipanels for subplotting - this is easier than messing with subplot with heatmaps
            p1 = uipanel('Parent',axesName,'Position',[1,102,954,102],'BackgroundColor',[1,1,1],'BorderType','none');
            p2 = uipanel('Parent',axesName,'Position',[1,1,954,102],'BackgroundColor',[1,1,1],'BorderType','none');
            
            % Plot state A first
            timelist = app.timepointOrder(~strcmp(app.timepointOrder,'0'));
            
            % use flatten2 and give 0 for mode for state A and 1 for mode for difference of B-A
            dataMat1 = [];
            for f = 1:length(timelist)
                timeData = common_A(strcmp(common_A.Exposure,timelist(f)),:);
                flattenedData = app.flatten2(timeData,app.startEndResidues(1),app.startEndResidues(2),0,dataType);
                dataMat1 = [dataMat1; flattenedData(:,2)'];
            end

            ext1 = nanmax(nanmax(abs(dataMat1)));
            m1 = heatmap(p1,dataMat1,'GridVisible','off');
            caxis(m1, [0 ext1]);
            
            set(m1,'Colormap',app.white_red,'YLabel','(min)','YData',timelist,'Position',[0.070, 0.1502, 0.83, 0.7048],'MissingDataColor',[0.9 0.9 0.9])
            
            % Edit the colourbar's highest value to show max DU
            bc1 = struct(m1); % convert the handle into a structure to access 
            bc1.XAxis.TickValues = ''; % hide the x axis tick labels
            
            cbar1_num_labels = bc1.Colorbar.Ticks;
            cbar1_num_labels(end) = ext1;

            if dataType == 1
                bc1.Colorbar.Label.String = {'mean DU';'(State A)'};
                new_cbar1_labels = arrayfun(@(x) sprintf('%.1f',x), cbar1_num_labels, 'UniformOutput', false); % also set dp
            else
                bc1.Colorbar.Label.String = {'mean RU';'(State A)'};
                new_cbar1_labels = arrayfun(@(x) sprintf('%.2f',x), cbar1_num_labels, 'UniformOutput', false); % also set dp
            end
            
            bc1.Colorbar.TickLabels = new_cbar1_labels;
            bc1.Colorbar.Ticks(end) = ext1;
            bc1.MissingDataColorbar.TickLabels = 'No cov';
            bc1.EnableInteractions = 0;
            
            if ispc
                fs_offset = 1;
                bc1.Colorbar.FontSize = app.fs_axisTickLabels-fs_offset;
                bc1.YAxis.FontSize = app.fs_axisTickLabels-fs_offset;
                bc1.XAxis.FontSize = app.fs_axisTickLabels-fs_offset;
                bc1.YLabelHandle.FontSize = app.fs_axisLabels-fs_offset;
                bc1.MissingDataColorbar.FontSize = app.fs_axisTickLabels-1-fs_offset;
            else
                bc1.Colorbar.FontSize = app.fs_axisTickLabels;
                bc1.YAxis.FontSize = app.fs_axisTickLabels;
                bc1.XAxis.FontSize = app.fs_axisTickLabels;
                bc1.YLabelHandle.FontSize = app.fs_axisLabels;
                bc1.MissingDataColorbar.FontSize = app.fs_axisTickLabels-1;
            end

            % Plot difference of state B 
            dataMat2 = [];
            for f = 1:length(timelist)
                timeData = diff_common(strcmp(diff_common.Exposure,timelist(f)),:);
                flattenedData = app.flatten2(timeData,app.startEndResidues(1),app.startEndResidues(2),1,dataType);
                dataMat2 = [dataMat2; flattenedData(:,2)'];
            end
            
            ext2 = nanmax(nanmax(abs(dataMat2)));
            m2 = heatmap(p2,dataMat2,'GridVisible','off');
            caxis(m2, [-ext2 ext2]);
            set(m2,'Colormap',app.blue_white_red,'YLabel','(min)','YData',timelist,'Position',[0.070, 0.2302, 0.83, 0.7048],'MissingDataColor',[0.9 0.9 0.9])

            % Edit the colourbar's highest value to show max DU
            bc2 = struct(m2); 
            cbar2_num_labels = bc2.Colorbar.Ticks;
            cbar2_num_labels(1) = -ext2;
            cbar2_num_labels(end) = ext2;
        
            if dataType == 1
                bc2.Colorbar.Label.String = {'mean ΔDU';'(State B-A)'};
                new_cbar2_labels = arrayfun(@(x) sprintf('%.1f',x), cbar2_num_labels, 'UniformOutput', false);
            else
                bc2.Colorbar.Label.String = {'mean ΔRU';'(State B-A)'};
                new_cbar2_labels = arrayfun(@(x) sprintf('%.2f',x), cbar2_num_labels, 'UniformOutput', false);
            end
            
            bc2.Colorbar.TickLabels = new_cbar2_labels;
            bc2.Colorbar.Ticks(1) = -ext2;
            bc2.Colorbar.Ticks(end) = ext2;
            bc2.XAxis.TickLabels(~endsWith(bc2.XAxis.TickLabels,'0')) = {''};
            bc2.EnableInteractions = 0;
            bc2.MissingDataColorbar.TickLabels = 'No cov';
            
            if ispc
                fs_offset = 1;
                bc2.Colorbar.FontSize = app.fs_axisTickLabels-fs_offset;
                bc2.YAxis.FontSize = app.fs_axisTickLabels-fs_offset;
                bc2.XAxis.FontSize = app.fs_axisTickLabels-fs_offset;
                bc2.YLabelHandle.FontSize = app.fs_axisLabels-fs_offset;
                bc2.MissingDataColorbar.FontSize = app.fs_axisTickLabels-1-fs_offset;
            else
                bc2.Colorbar.FontSize = app.fs_axisTickLabels;
                bc2.YAxis.FontSize = app.fs_axisTickLabels;
                bc2.XAxis.FontSize = app.fs_axisTickLabels;
                bc2.YLabelHandle.FontSize = app.fs_axisLabels;
                bc2.MissingDataColorbar.FontSize = app.fs_axisTickLabels-1;
            end
            
            BarcodePlotHandle(1) = p1;
            BarcodePlotHandle(2) = p2;
        end
            
        function [BarcodePlotHandle, bc1] = polyplotBarcode(app,state,axesName,dataType,state_name)
            % Splits by timepoints and plots one strip per timepoint
            
            % Check if the exposure in table is numeric or not - need to switch between state A/ State AB
            if ~isnumeric(state.Exposure)
                state = state(~strcmp(state.Exposure,'Sum'),:);
                state.Exposure = cellfun(@(x) str2num(x),state.Exposure);
            end
            
            % Create two new uipanels for subplotting - this is easier than messing with subplot with heatmaps
            p1 = axesName;
            
            % Plot state A first
            timelist = app.timepointOrder(~strcmp(app.timepointOrder,'0') & ~strcmp(app.timepointOrder,'Sum'));
            
            % use flatten2 and give 0 for mode for state A and 1 for mode for difference of B-A
            dataMat = [];
            for f = 1:length(timelist)
                timeData = state(state.Exposure == str2num(cell2mat(timelist(f))),:);
                flattenedData = app.flatten2(timeData,app.startEndResidues(1),app.startEndResidues(2),0,dataType);
                dataMat = [dataMat; flattenedData(:,2)'];
            end

            ext1 = nanmax(nanmax(abs(dataMat)));
            m1 = heatmap(p1,dataMat,'GridVisible','off');
            caxis(m1, [0 ext1]);
            
            set(m1,'Colormap',app.white_red,'YLabel','(min)','YData',timelist,'Position',[0.070, 0.25, 0.83, 0.548],'MissingDataColor',[0.9 0.9 0.9])
            
            % Edit the colourbar's highest value to show max DU
            bc1 = struct(m1); % convert the handle into a structure to access 
            
            cbar1_num_labels = bc1.Colorbar.Ticks;
            cbar1_num_labels(end) = ext1;

            if dataType == 1
                bc1.Colorbar.Label.String = {'mean DU';sprintf('(%s)',state_name)};
                new_cbar1_labels = arrayfun(@(x) sprintf('%.1f',x), cbar1_num_labels, 'UniformOutput', false); % also set dp
            else
                bc1.Colorbar.Label.String = {'mean RU';sprintf('(%s)',state_name)};
                new_cbar1_labels = arrayfun(@(x) sprintf('%.2f',x), cbar1_num_labels, 'UniformOutput', false); % also set dp
            end
            
            bc1.Colorbar.TickLabels = new_cbar1_labels;
            bc1.Colorbar.Ticks(end) = ext1;
            bc1.XAxis.TickLabels(~endsWith(bc1.XAxis.TickLabels,'0')) = {''};
            bc1.EnableInteractions = 0;
            bc1.MissingDataColorbar.TickLabels = 'No cov';
            
            if ispc
                fs_offset = 1;
                bc1.Colorbar.FontSize = app.fs_axisTickLabels-fs_offset;
                bc1.YAxis.FontSize = app.fs_axisTickLabels-fs_offset;
                bc1.XAxis.FontSize = app.fs_axisTickLabels-fs_offset;
                bc1.YLabelHandle.FontSize = app.fs_axisLabels-fs_offset;
                bc1.MissingDataColorbar.FontSize = app.fs_axisTickLabels-1-fs_offset;
            else
                bc1.Colorbar.FontSize = app.fs_axisTickLabels;
                bc1.YAxis.FontSize = app.fs_axisTickLabels;
                bc1.XAxis.FontSize = app.fs_axisTickLabels;
                bc1.YLabelHandle.FontSize = app.fs_axisLabels;
                bc1.MissingDataColorbar.FontSize = app.fs_axisTickLabels-1;
            end
            
            BarcodePlotHandle(1) = p1;
        end
            
    
        function polyplotDifference(app,listOfResiduesA,listOfResiduesB,axesName,peptides)
            % Plots the coverage bar with peptides for states A and B and displays
            % differences between them
            
            h = app.initialiseCoveragePlot(axesName); % Set up new axes with labels

            resStart = app.startEndResidues(1);
            resEnd = app.startEndResidues(2);
            y_ax_top = 4; y_ax_bot = -4;
            
            % Find unique and common peptides between A and B
            AB_table = app.residueListIntersect(listOfResiduesA,listOfResiduesB);
            listOfResidues = [AB_table.Start AB_table.End];

            missingResidueColumns = app.getCoveragePeptides(unique(app.getuniqueresidues(listOfResidues)),resStart,resEnd);
            coverage = sprintf('Comparative (Common: %.1f%% / %s: %.1f%% / %s: %.1f%%)',app.common.coverage,app.StateADropDown.Value,app.A.coverage,app.StateBDropDown.Value,app.B.coverage);
            
            % Plotting starts here
            y_top = 1; y_bot = -1; buffer = 0.5;
            app.coverageBars(h,missingResidueColumns,y_top,y_bot,resStart,resEnd,buffer,app.cGreen);
            
            % Plot the coverage text - x,y coordinates for the coverage label, State (%% coverage)
            textX = -(resEnd-resStart)*0.01;
            textY = y_top+(y_ax_top-y_ax_bot)/10;
            
            text(h,textX,textY,coverage,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none');
            
            % Axis padding
            padding = (resEnd*1.05)-resEnd;
        
            if peptides == 0
                axis(h,[resStart-padding resEnd+padding y_ax_bot y_ax_top]);
            else
                % Set up colour table for peptides
                commonCol = app.cGreen;
                stateACol = app.cBlue;
                stateBCol = app.cPink; 
                
                colorList = [];
                for i = 1:height(AB_table)
                    if strcmp(AB_table.Label(i),'Common')
                        colorList = [colorList; AB_table.Start(i) AB_table.End(i) commonCol];
                    elseif strcmp(AB_table.Label(i),'State A')
                        colorList = [colorList; AB_table.Start(i) AB_table.End(i) stateACol];
                    elseif strcmp(AB_table.Label(i),'State B')
                        colorList = [colorList; AB_table.Start(i) AB_table.End(i) stateBCol];
                    end
                end
                
                app.plotPeptides(h,colorList,-2)
                app.peptideLegendBox(h,AB_table,stateACol,stateBCol,commonCol)
                xlim(h,[resStart-padding resEnd+padding]);
            end            
        end
        
        function axesHandle = initialiseCoveragePlot(app,axesName)
            % Clears existing axes and sets new position of new axes 
            
            delete(allchild(axesName)) % reset the axis first
            axesHandle = axes(axesName);
            disableDefaultInteractivity(axesHandle)
            
            % positional options
            set(axesHandle,'Position',[0.02, 0.25, 0.95, 0.6]); % leave alone
            
            %Axis decoration
            xlabel(axesHandle, 'Residue Number','FontSize',app.fs_axisLabels)
            ylabel(axesHandle,'','FontSize',app.fs_axisLabels)
            title(axesHandle,strrep(app.ProteinDropDown.Value, '_', ' '),'FontSize',app.fs_figureTitle)
            set(axesHandle,'YColor','none','XColor','black','TickDir','out','FontSize',app.fs_axisTickLabels)
        end
        
        function missingResidueColumns = getCoveragePeptides(~,uniqueResidues,resStart,resEnd)
            % Takes a list of unique peptides and finds start and end of each consecutive
            % segment to reduce the number of polygons to plot for coverage maps
            
            fullArray = resStart:1:resEnd;
            
            % Find the common values between a full array of all residues and the state
            % residue list
            missingResidues = intersect(fullArray,uniqueResidues);
            
            consecs = diff(missingResidues)==1;
            
            missingResidueColumns = [];
            sRes = 1;
            for i = 1:length(consecs)
                if consecs(i) == 1 && sRes == 1
                    missingResidueColumns = [missingResidueColumns; missingResidues(i)];
                    sRes = 0;
                elseif consecs(i) == 0 && sRes == 0
                    missingResidueColumns = [missingResidueColumns; missingResidues(i)];
                    sRes = 1;
                end
                
                if i == length(consecs)
                    missingResidueColumns = [missingResidueColumns; missingResidues(end)];
                end
            end
            
            % missingResidueColumns = [missingResidueColumns; endRes];
            missingResidueColumns = [missingResidueColumns(1:2:end) missingResidueColumns(2:2:end)];
        end
        
        function coverageBars(~,axesHandle,residueList,y_top,y_bot,resStart,resEnd,buffer,color)
            hold(axesHandle,'on')
            
            plot(axesHandle, polyshape([resStart y_bot; resEnd y_bot; resEnd y_top ; resStart y_top]),'FaceAlpha',0.8,'FaceColor',[0.8 0.8 0.8], 'LineStyle','none')
            
            % loop over axes and plot polygon for each residue
            [rows, ~] = size(residueList);
            
            for res = 1:rows
                topLeft = [residueList(res,2)+buffer y_top];
                topRight = [residueList(res,1)-buffer y_top];
                bottomLeft = [residueList(res,1)-buffer y_bot];
                bottomRight = [residueList(res,2)+buffer y_bot];
                
                plot(axesHandle, polyshape([topLeft; topRight; bottomLeft; bottomRight]),'FaceAlpha',1,'FaceColor',color,'LineStyle','none')
            end
            
            hold(axesHandle,'off');
        end
        
        function colourList = genColorList(~,listOfResidues,color)
            colourList = [listOfResidues, ...
                            ones(length(listOfResidues),1)*color(1), ...
                            ones(length(listOfResidues),1)*color(2), ...
                            ones(length(listOfResidues),1)*color(3)];
        end
        
        function peptideRow = plotPeptides(app,axesHandle,listOfResidues,rowStart)
            
            hold(axesHandle,'on');
            
            % Plotting peptide bars for redundancy
            listOfResidues = sortrows(listOfResidues);

            barSpacing = app.Coverage_BarSpacingEditBox.Value; % controls thickness of the peptide bars
            barThickness = app.Coverage_BarThicknessEditBox.Value;
            barEdgeThickness = 0.2; % controls edge colour
            
            if strcmp(app.Coverage_BorderColorDropDown.Value,'Black')
                barEdgeColor = 'k';
                barEdgeLineStyle = '-';
            elseif strcmp(app.Coverage_BorderColorDropDown.Value,'White')
                barEdgeColor = 'w';
                barEdgeLineStyle = '-';
            else
                barEdgeColor = 'k';
                barEdgeLineStyle = 'None';
            end
            
            notPlotted = [];
            for i=1:length(listOfResidues)
                color = listOfResidues(i,3:5);
            
                if i == 1
                    peptideRow = rowStart;
                    plot(axesHandle, polyshape([listOfResidues(1,1)-0.49 peptideRow+barThickness ; listOfResidues(1,2)+0.49 peptideRow+barThickness; listOfResidues(1,2)+0.49 peptideRow-barThickness ; listOfResidues(1,1)-0.49 peptideRow-barThickness]),'FaceAlpha',1,'FaceColor',color,'LineStyle',barEdgeLineStyle,'EdgeColor',barEdgeColor,'linewidth',barEdgeThickness)
                    refPep = listOfResidues(1,1:2);
                else
                    overlapType = app.overlap(refPep,listOfResidues(i,1:2));
                    if overlapType == 0
                        plot(axesHandle, polyshape([listOfResidues(i,1)-0.49 peptideRow+barThickness ; listOfResidues(i,2)+0.49 peptideRow+barThickness; listOfResidues(i,2)+0.49 peptideRow-barThickness ; listOfResidues(i,1)-0.49 peptideRow-barThickness]),'FaceAlpha',1,'FaceColor',color,'LineStyle',barEdgeLineStyle,'EdgeColor',barEdgeColor,'linewidth',barEdgeThickness)
                        refPep = listOfResidues(i,1:2);
                    else
                        notPlotted = [notPlotted; i];
                    end
                end
            end
            
            while ~isempty(notPlotted)
                peptideRow = peptideRow - barSpacing;
                
                notPlotted_2 = [];
                for i = 1:length(notPlotted)
                    color = listOfResidues(notPlotted(i),3:5);
                    
                    if i == 1
                        plot(axesHandle, polyshape([listOfResidues(notPlotted(i),1)-0.49 peptideRow+barThickness ; listOfResidues(notPlotted(i),2)+0.49 peptideRow+barThickness; listOfResidues(notPlotted(i),2)+0.49 peptideRow-barThickness ; listOfResidues(notPlotted(i),1)-0.49 peptideRow-barThickness]),'FaceAlpha',1,'FaceColor',color,'LineStyle',barEdgeLineStyle,'EdgeColor',barEdgeColor,'linewidth',barEdgeThickness)
                        refPep = listOfResidues(notPlotted(i),1:2);
                    else
                        overlapType = app.overlap(refPep,listOfResidues(notPlotted(i),1:2));
                        if overlapType == 0
                            plot(axesHandle, polyshape([listOfResidues(notPlotted(i),1)-0.49 peptideRow+barThickness ; listOfResidues(notPlotted(i),2)+0.49 peptideRow+barThickness; listOfResidues(notPlotted(i),2)+0.49 peptideRow-barThickness ; listOfResidues(notPlotted(i),1)-0.49 peptideRow-barThickness]),'FaceAlpha',1,'FaceColor',color,'LineStyle',barEdgeLineStyle,'EdgeColor',barEdgeColor,'linewidth',barEdgeThickness)
                            refPep = listOfResidues(notPlotted(i),1:2);
                        else
                            notPlotted_2 = [notPlotted_2; notPlotted(i)];
                        end
                    end
                end
                
                notPlotted = notPlotted_2;
            end
            
            % Axis padding
            if peptideRow > -10
                axis(axesHandle,[app.startEndResidues(1)-20 app.startEndResidues(2)+20 -10 3]);
            elseif peptideRow < -10
                axis(axesHandle,[app.startEndResidues(1)-20 app.startEndResidues(2)+20 peptideRow-(3*barSpacing) 3]);
            end
            
            hold(axesHandle,'off');
        end
        
        function overlapType = overlap(~,peptideA,peptideB)
            % Determines between two peptides, the type of overlap
            
            % 1. Is there overlap between peptideA and B
            if isempty(intersect(peptideA(1):1:peptideA(end),peptideB(1):1:peptideB(end)))
                overlapType = 0;
            else
                overlapType = 1;
            end
        end
        
        function AB_table = residueListIntersect(~,listOfResiduesA,listOfResiduesB)
            
            uniqueA = setdiff(listOfResiduesA,listOfResiduesB,'rows');
            uniqueB = setdiff(listOfResiduesB,listOfResiduesA,'rows');
            
            [rowsA, ~] = size(uniqueA);
            [rowsB, ~] = size(uniqueB);
            
            % Find unique and common peptides between A and B
            commonAB = intersect(listOfResiduesA,listOfResiduesB,'rows');
            
            % Add text annotation
            [commonAB_label{1:length(commonAB)}] = deal('Common');
            common_table = table(commonAB(:,1),commonAB(:,2),commonAB_label','VariableNames',{'Start','End','Label'});
            
            [uniqueA_label{1:rowsA}] = deal('State A');
            uniqueA_table = table(uniqueA(:,1),uniqueA(:,2),uniqueA_label','VariableNames',{'Start','End','Label'});
            
            [uniqueB_label{1:rowsB}] = deal('State B');
            uniqueB_table = table(uniqueB(:,1),uniqueB(:,2),uniqueB_label','VariableNames',{'Start','End','Label'});
           
            AB_table = [common_table; uniqueA_table; uniqueB_table];
        end
        

        function peptideLegendBox(app,axesHandle,AB_table,stateACol,stateBCol,commonCol)
            % Adds legend to the comparative peptide coverage map
            
            numberOfStateAPeptides = height(AB_table(strcmp(AB_table.Label,'State A'),:));
            numberOfStateBPeptides = height(AB_table(strcmp(AB_table.Label,'State B'),:));
            numberOfCommonPeptides = height(AB_table(strcmp(AB_table.Label,'Common'),:));
            
            % Draw legend bar stretching from 50-100% of the protein length
            box_spacing = (app.startEndResidues(2)-app.startEndResidues(1))*0.010;
            
            x1_start = (app.startEndResidues(2)-app.startEndResidues(1))*0.60;
            x1_finish = x1_start+box_spacing;
            
            x2_start = (app.startEndResidues(2)-app.startEndResidues(1))*0.75;
            x2_finish = x2_start+box_spacing;
            
            x3_start = (app.startEndResidues(2)-app.startEndResidues(1))*0.90;
            x3_finish = x3_start+box_spacing;
            
            y_start = 1.5;
            y_finish = 2.5;
            
            hold(axesHandle,'on');
            
            plot(axesHandle, polyshape([x2_start, y_start;
                               x2_finish, y_start;
                               x2_finish, y_finish; 
                               x2_start, y_finish]),'FaceAlpha',1,'FaceColor',stateACol,'LineStyle','None','EdgeColor','none')
                           
            text(axesHandle,x2_finish+box_spacing/2,2,sprintf('%s: %d',app.StateADropDown.Value, numberOfStateAPeptides),'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none')
                           
            plot(axesHandle, polyshape([x3_start, y_start;
                               x3_finish, y_start;
                               x3_finish, y_finish; 
                               x3_start, y_finish]),'FaceAlpha',1,'FaceColor',stateBCol,'LineStyle','None','EdgeColor','none')   
                           
            text(axesHandle,x3_finish+box_spacing/2,2,sprintf('%s: %d',app.StateBDropDown.Value, numberOfStateBPeptides),'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none');
            
            plot(axesHandle, polyshape([x1_start, y_start;
                               x1_finish, y_start;
                               x1_finish, y_finish; 
                               x1_start, y_finish]),'FaceAlpha',1,'FaceColor',commonCol,'LineStyle','None','EdgeColor','none')
            
            text(axesHandle,x1_finish+box_spacing/2,2,sprintf('Common: %d',numberOfCommonPeptides),'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter', 'none')

            hold(axesHandle,'off');
        end
        
        function polyplotRedundancy(app,stateResiduesNonUnique,listOfResidues,axesName,stateName,stateCoverage,plotPeptides)
            % Produces the redundancy style coverage plot 
            
            h = app.initialiseCoveragePlot(axesName); % Set up new axes with labels

            resStart = app.startEndResidues(1);
            resEnd = app.startEndResidues(2);
            y_ax_top = 4; y_ax_bot = -4;
            
            % Takes a list of unique residues found in data and plots as a linear coverage map using polygons per residue
            tabState = tabulate(stateResiduesNonUnique);
            numberOfPeptides = length(listOfResidues);
            maxRedundancy = max(tabState(:,2));
            cm_parula = parula(maxRedundancy);
            
            % Plot one set of peptide coverage bar ---------------------------------------------------------

            y_top = 1; y_bot = -1; buffer = 0.5;
            app.plotRedundancyBars(h,tabState,cm_parula,maxRedundancy,y_top,y_bot,buffer)
            
            % Add text
            textX = -(resEnd-resStart)*0.01;
            textY = y_top+(y_ax_top-y_ax_bot)/7;
            
            coverage = sprintf('%s (%.1f %% coverage across %d peptides)',stateName,stateCoverage,numberOfPeptides);
            text(h,textX,textY,coverage,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter','None');

            % Axis padding
            padding = (resEnd*1.05)-resEnd;
            
            if plotPeptides == 0
                axis(h,[resStart-padding resEnd+padding y_ax_bot y_ax_top]);
            else
                % Set up colour table for peptides
                colourList = app.genColorList(listOfResidues,app.cRedun1);
        
                app.plotPeptides(h,colourList,-1.5);
                xlim(h,[resStart-padding resEnd+padding]);
            end            
        end
    
        function polyplotRedundancyDouble(app,stateAResiduesNonUnique,stateBResiduesNonUnique,listOfResiduesA,listOfResiduesB,...
                axesName,stateNameA,stateNameB,plotPeptides)
            % Produces the redundancy style coverage plot 
            
            h = app.initialiseCoveragePlot(axesName); % Set up new axes with labels

            resStart = app.startEndResidues(1);
            resEnd = app.startEndResidues(2);
            y_ax_top = 4.5; y_ax_bot = -1;
            
            % Takes a list of unique residues found in data and plots as a linear coverage map using polygons per residue
            tabStateA = tabulate(stateAResiduesNonUnique);
            tabStateB = tabulate(stateBResiduesNonUnique);
            
            numberOfPeptidesA = length(listOfResiduesA);
            numberOfPeptidesB = length(listOfResiduesB);
            
            maxRedundancy = max([max(tabStateA(:,2)), max(tabStateB(:,2))]);
            cm_parula = parula(maxRedundancy);
 
            % Plot one set of peptide coverage bar ---------------------------------------------------------

            y_top = 1; y_bot = 0; buffer = 0.5;
            app.plotRedundancyBars(h,tabStateA,cm_parula,maxRedundancy,y_top,y_bot,buffer)
            
            % Add text
            textX = -(resEnd-resStart)*0.01;
            textY = y_top+(y_ax_top-y_ax_bot)/4;
            
            coverage = sprintf('%s (%.1f %% coverage across %d peptides)',stateNameA,app.A.coverage,numberOfPeptidesA);
            text(h,textX,textY,coverage,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter','None');

            if plotPeptides == 1
                % Set up colour table for peptides
                colourListA = app.genColorList(listOfResiduesA,app.cRedun1);
                
                peptideRow = app.plotPeptides(h,colourListA,-1);
                
                % These control where the state B coverage plot is moved to if peptides
                % are turned on
                interBarDistanceMultiplier = 5; % controls how far the bottom state's coverage bar is from the bottom of the redundancy peptides
                barStartHeight = peptideRow-(interBarDistanceMultiplier*app.Coverage_BarSpacingEditBox.Value);
                y_top = barStartHeight; y_bot = barStartHeight-1; buffer = 0.5;
            else
                y_top = 3; y_bot = 2; buffer = 0.5;
            end
            
            % ----------------------------------------------------------------------------------------------
            % Plot peptide set B coverage bar --------------------------------------------------------------
            textY = y_top+(y_ax_top-y_ax_bot)/4;
            
            coverage = sprintf('%s (%.2f %% coverage across %d peptides)',stateNameB,app.B.coverage,numberOfPeptidesB);
            text(h,textX,textY,coverage,'HorizontalAlignment','left','FontWeight','Bold','FontSize',app.fs_figureText,'Interpreter','None');

            app.plotRedundancyBars(h,tabStateB,cm_parula,maxRedundancy,y_top,y_bot,buffer)
            
            % Axis padding
            padding = (resEnd*1.05)-resEnd;

            if plotPeptides == 0
                axis(h,[resStart-padding resEnd+padding y_ax_bot y_ax_top]);
            else
                % Set up colour table for peptides
                colourListB = app.genColorList(listOfResiduesB,app.cRedun1);
                
                app.plotPeptides(h,colourListB,barStartHeight-2);
                xlim(h,[resStart-padding resEnd+padding]);
            end
        end
        
        function plotRedundancyBars(app,axesHandle,tabState,cm_parula,maxRedundancy,y_top,y_bot,buffer)
            %Plot full length of protein, bottom of plot is A, top of plot is B
            hold(axesHandle,'on');
            plot(axesHandle, polyshape([app.startEndResidues(1) y_bot; app.startEndResidues(2) y_bot; app.startEndResidues(2) y_top; app.startEndResidues(1) y_top]),'FaceAlpha',0.8,'FaceColor',[0.8 0.8 0.8], 'LineStyle','none')

            %loop over axes and plot polygon for each residue
            for res = 1:length(tabState)
                if tabState(res,2) > 0
                    plot(axesHandle, polyshape([tabState(res,1)-buffer y_bot; tabState(res,1)+buffer y_bot; tabState(res,1)+buffer y_top; tabState(res,1)-buffer y_top]),'FaceAlpha',1,'FaceColor',cm_parula(tabState(res,2),:),'LineStyle','none');
                end
            end
            
            colormap(axesHandle,cm_parula);
            cb = colorbar(axesHandle);
            cb.Label.String = 'Redundancy';
            caxis(axesHandle,[1 maxRedundancy]);
            
            %Axis decoration
            xlabel(axesHandle, 'Residue Number','FontSize',app.fs_axisLabels)
            ylabel(axesHandle,'','FontSize',app.fs_axisLabels)
            title(axesHandle,strrep(app.ProteinDropDown.Value, '_', ' '),'FontSize',app.fs_figureTitle)
            set(axesHandle,'YColor','none','XColor','black','TickDir','out')

            hold(axesHandle,'off');
        end
    

        function plotStore = redundancyCols(~,stateResiduesNonUnique,scheme)
            % Calculates the colours equivalent to the redundancy plot
            
            % Define colours for gradient mixing
            colors = readtable('colors_gradient_def.txt');
            
            tabState = tabulate(stateResiduesNonUnique);
            colLen = max(tabState(:,2)); % get length of the data

            matlab_schemes = {'parula','jet','hsv','hot','cool','spring','summer','autumn','winter','gray','bone','copper','pink'};
            custom_schemes = {'White_Blue1','White_Blue2','White_Blue3','White_Purple1','White_Purple2','White_Purple3','White_Red','White_Pink1','White_Pink2','White_Brown',...
                              'White_Orange','White_Yellow','White_Black','White_Green1','White_Green2'};
                          
            % direct to either matlab schemes or custom gradient
            if ismember(scheme,matlab_schemes)
                fh = str2func(scheme); % get function handle from selected colormap
                col = fh(max(tabState(:,2)));
            elseif ismember(scheme,custom_schemes)
                % get colors from the external file
                selCols = split(scheme,'_');
                
                colRGB = [];
                for i = 1:length(selCols)
                    findCol = colors(strcmp(colors.Color,selCols{i}),:);
                    
                    if ~isempty(findCol)
                        colRGB = [colRGB; findCol.r findCol.g findCol.b];
                    end
                end
                
                if isempty(colRGB)
                    errordlg('Selected color not found in colour_gradient_def.txt')
                    return
                else
                    col = [linspace(colRGB(1,1),colRGB(2,1),colLen)', linspace(colRGB(1,2),colRGB(2,2),colLen)', linspace(colRGB(1,3),colRGB(2,3),colLen)'];
                end
            else
                errordlg('Select valid colour from Redundancy drop down menu')
                return
            end
            
            % Store the colours 
            plotStore = [];
            for res = 1:length(tabState)
                if tabState(res,2) > 0
                    plotStore = [plotStore; tabState(res,1) col(tabState(res,2),:)];
                end
            end
        end
        
        % Start of Advanced plot sections 
        function [g, plotID] = initialiseSubplots(app,axesName,dataTable,plotOptions,plotOrder)
            % Clear existing axis
            delete(allchild(axesName)) % reset the axis first
            
            % From Data, get the number of timepoints and rows to plot
            numberOfTimepoints = length(unique(dataTable.Exposure)); % i.e. initialise this many plots
            numberOfRows = round(numberOfTimepoints/2);
            
            % Create panel for storing the grid layout inside
            % Will automatically create as many plots as necessary
            g = uigridlayout(axesName,'Scrollable','on');
            g.RowHeight = num2cell(ones(1,numberOfRows)*250);
            g.ColumnWidth = {'1x','1x'};
            
            % Createaxes
            for i = 1:length(plotOrder)
                timepointName = plotOrder{i};
                plotID(i) = uiaxes(g,'BackgroundColor',[1.00,1.00,1.00]);
                
                % Plot formatting
                set(plotID(i),'color',[229/255, 229/255, 229/255],...
                          'GridColor',[1,1,1],...
                          'GridAlpha',1,...
                          'TickDir','out',...
                          'FontSize',app.fs_axisTickLabels);
                      
                grid(plotID(i),'on');
                box(plotID(i),'off');
                
                % Title
                if strcmp(timepointName,'Sum')
                    title(plotID(i),'Sum Exposure','FontSize',app.fs_figureTitle);
                else
                    title(plotID(i),sprintf('%s min Exposure',timepointName),'FontSize',app.fs_figureTitle);
                end

                % Axis Labels
                if mod(i,2) ~= 0
                    if ~isempty(strfind(plotOptions.PlotType,'Differential'))
                        if strcmp(plotOptions.DataType,'Absolute')
                            ylabel(plotID(i),'ΔDU (Da)','FontSize',app.fs_axisLabels)
                        else
                            ylabel(plotID(i),'ΔRU','FontSize',app.fs_axisLabels)
                        end
                    elseif ~isempty(strfind(plotOptions.PlotType,'Single')) | ~isempty(strfind(plotOptions.PlotType,'Double'))
                        if strcmp(plotOptions.DataType,'Absolute')
                            ylabel(plotID(i),'DU (Da)','FontSize',app.fs_axisLabels)
                        else
                            ylabel(plotID(i),'RU','FontSize',app.fs_axisLabels)
                        end
                    elseif strcmp(plotOptions.PlotType,'Volcano')
                        ylabel(plotID(i),'-log_{10}(p-value)','FontSize',app.fs_axisLabels)
                            
                    end
                end

                if (i == length(plotOrder)) | (i == length(plotOrder)-1)
                    if ~isempty(strfind(plotOptions.PlotType,'Woods')) | strcmp(plotOptions.DataType,'AUC')
                        xlabel(plotID(i),'Residues','FontSize',app.fs_axisLabels)
                    elseif ~isempty(strfind(plotOptions.PlotType,'Butterfly'))
                        xlabel(plotID(i),'Peptides','FontSize',app.fs_axisLabels)
                    elseif ~isempty(strfind(plotOptions.PlotType,'Volcano'))
                        if strcmp(plotOptions.DataType,'Absolute')
                            xlabel(plotID(i),'ΔDU (Da)','FontSize',app.fs_axisLabels)
                        else
                            xlabel(plotID(i),'ΔRU','FontSize',app.fs_axisLabels)
                        end
                    end
                end
            end
        end
    
        function plotID = initialiseSinglePlot(app,axesName,plotTitle,xName,yName)
            
            % Clear existing axis
            delete(allchild(axesName)) % reset the axis first

            % Create panel for storing the grid layout inside
            % Will automatically create as many plots as necessary
            g = uigridlayout(axesName,'Scrollable','on');
            g.RowHeight = num2cell(ones(0,1)*280);
            g.ColumnWidth = {'1x'};
            
            plotID = uiaxes(g,'BackgroundColor',[1.00,1.00,1.00]);
            
            % Plot formatting
            set(plotID,'color',[229/255, 229/255, 229/255],...
                      'GridColor',[1,1,1],...
                      'GridAlpha',1,...
                      'TickDir','out',...
                      'FontSize',app.fs_axisTickLabels);
                  
            grid(plotID,'on');
            box(plotID,'off');
            
            % Axes Names
            title(plotID,plotTitle,'FontSize',app.fs_figureTitle,'Interpreter','None');
            xlabel(plotID,xName,'FontSize',app.fs_axisLabels)
            ylabel(plotID,yName,'FontSize',app.fs_axisLabels)
        end
    
        function plotOrder = determineOrder(~,data)
            plotOrder = cellstr(num2str(sort(str2double(unique(data.Exposure)),'ascend')));
            
            if ~isempty(find(strcmp(data.Exposure,'Sum'))) % 0 if cant find, 1 if can
                plotOrder{end} = 'Sum';
            end
        end
    
        
        function filteredTable = plotWoodsDifferential(app,axesName,dataTable,plotOptions)
            % Takes dataTable, e.g. app.diff.common or app.diff.common_pValues, applies filtering and plots 
            
            % Axis limits
            axis_buffer = 1.3; % additional buffer zone to add to y-axis
            
            % Axis scaling settings
            x_lims = app.startEndResidues;

            % Loop over the number of plots, get the name of the plot and then find the same data from the datatable, filter and plot
            
            if strcmp(plotOptions.Statistics,'Hybrid')
                variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure',...
                                 'z','N','RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B','Center_diff_ave','Center_diff_SD','Center_reps_A',...
                                 'Center_reps_B','Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B','RU_diff_ave',...
                                 'RU_diff_SD','RU_reps_A','RU_reps_B','Correction','Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue',...
                                 'BH_sig','Significance','ColorMap','CI','Confidence'};
            else
                variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure',...
                                 'z','N','RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B','Center_diff_ave','Center_diff_SD','Center_reps_A',...
                                 'Center_reps_B','Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B','RU_diff_ave',...
                                 'RU_diff_SD','RU_reps_A','RU_reps_B','Correction','Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue',...
                                 'BH_sig','Significance','ColorMap','CI'};
            end
            
            filteredTable = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames);
            for i = 1:length(axesName)
                hold(axesName(i),'on');
                
                % Controls how thick the peptide bars are
                ln_width = 3;
                legendBox_y_buffer = 0.85;
                
                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Get the max Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxY = nanmax(abs(dataTable.Uptake_diff_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                else
                    maxY = nanmax(abs(dataTable.Uptake_diff_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                end
                
                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                
                plot(axesName(i),x_lims,[0, 0],'k') % plot the line at 0 uptake
                
                numbers = {}; % counter for how many peptides in each category
                if strcmp(plotOptions.Statistics,'Hybrid')
                    % Get relevant statistics row from stats table
                    if ~strcmp(plotName,'Sum')
                        statsRow = plotOptions.hybrid(strcmp(plotOptions.hybrid.Type,'Non-sum'),:);
                    else
                        statsRow = plotOptions.hybrid(strcmp(plotOptions.hybrid.Type,'Sum'),:);
                    end
                    
                    filter = statsRow.CI;
                    
                    % Get protected, deprotected and non-significant data
                    protected    = timeData((timeData.Uptake_diff_ave < -statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence),:);
                    deprotected  = timeData((timeData.Uptake_diff_ave >  statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence),:);
                    nonsignificant = timeData(((timeData.Uptake_diff_ave < statsRow.CI) & (timeData.Uptake_diff_ave > -statsRow.CI)) | ((timeData.Uptake_diff_ave >  statsRow.CI) & (timeData.Welch_pValue > plotOptions.Confidence)) | (timeData.Uptake_diff_ave < -statsRow.CI) & (timeData.Welch_pValue > plotOptions.Confidence),:);
                    
                    % Add confidence interval
                    protected.CI(:) = statsRow.CI;
                    deprotected.CI(:) = statsRow.CI;
                    nonsignificant.CI(:) = statsRow.CI;
                    
                    protected.Confidence(:) = plotOptions.Confidence;
                    deprotected.Confidence(:) = plotOptions.Confidence;
                    nonsignificant.Confidence(:) = plotOptions.Confidence;
                    
                    % Add confidence interval lines
                    plot(axesName(i),x_lims,[filter, filter],'k--') % plot the line at +CI
                    plot(axesName(i),x_lims,[-filter, -filter],'k--') % plot the line at -CI
                    
                    legendText = sprintf('Hybrid significance test: %.1f%% CI: %.2f Da / p-value < %s',(1-plotOptions.Confidence)*100,filter,num2str(plotOptions.Confidence));
                    
                    numbers{end+1} = sprintf('Deprotected: %d',height(deprotected));
                    numbers{end+1} = sprintf('Non-sig: %d',height(nonsignificant));
                    numbers{end+1} = sprintf('Protected: %d',height(protected));
                    
                elseif strcmp(plotOptions.Statistics,'Peptide')
                    % Get protected, deprotected and non-significant data
                    
                    % According to B-H adjusted p-value
                    protected    = timeData((timeData.Uptake_diff_ave < 0) & (timeData.BH_pValue < plotOptions.Confidence),:);
                    deprotected  = timeData((timeData.Uptake_diff_ave > 0) & (timeData.BH_pValue < plotOptions.Confidence),:);
                    nonsignificant = timeData((timeData.BH_pValue > plotOptions.Confidence),:);
                    
                    % Also add the confidence interval as a new column
                    protected.CI(:) = plotOptions.Confidence;
                    deprotected.CI(:) = plotOptions.Confidence;
                    nonsignificant.CI(:) = plotOptions.Confidence;
                    
                    % Depending on the significance level, change the BH_sig value to significant
                    protected.BH_sig(protected.BH_pValue < plotOptions.Confidence) = 1;
                    deprotected.BH_sig(deprotected.BH_pValue < plotOptions.Confidence) = 1;
                    
                    legendText = sprintf('Peptide significance test: B-H adjusted p-value < %s',num2str(plotOptions.Confidence));
                    
                    numbers{end+1} = sprintf('Deprotected: %d',height(deprotected));
                    numbers{end+1} = sprintf('Non-sig: %d',height(nonsignificant));
                    numbers{end+1} = sprintf('Protected: %d',height(protected));
                end
                
                % If stats or no stats selected
                if ~strcmp(plotOptions.Statistics,'None')
                    
                    % Add significance outcome from the tests and append to a single table
                    protected.Significance(:)    = {'Protected'};
                    deprotected.Significance(:)  = {'Deprotected'};
                    nonsignificant.Significance(:) = {'Non significant'};
                    
                    % Additionally add colour map values to the new column so that plotting is
                    % easier later
                    protected.ColorMap(:) = {app.cWoodsBlue};
                    deprotected.ColorMap(:) = {app.cWoodsRed};
                    nonsignificant.ColorMap(:) = {app.cWoodsGrey};
                    
                    % Put nonsig first so that they are plotted first
                    statsData = [nonsignificant; deprotected; protected];
                    
                    filteredTable = [filteredTable; statsData];

                    % Plot the three catergories
                    lines = plot(axesName(i),[statsData.Start.'; statsData.End.'],[statsData.Uptake_diff_ave.'; statsData.Uptake_diff_ave.'],'LineWidth',ln_width);
                    colors = cell2mat(statsData.ColorMap);
                    
                    for pc = 1:length(lines)
                        set(lines(pc),'Color',colors(pc,:))
                    end
                else % no statistics selected
                    positive = timeData(timeData.Uptake_diff_ave > 0,:);
                    negative = timeData(timeData.Uptake_diff_ave < 0,:);
                    
                    positive.ColorMap(:) = {app.cBlue};
                    negative.ColorMap(:) = {app.cPink};
                    
                    timeData = [positive; negative];
                    
                    % Plot pos and neg lines
                    lines = plot(axesName(i),[timeData.Start.'; timeData.End.'],[timeData.Uptake_diff_ave.'; timeData.Uptake_diff_ave.'],'LineWidth',ln_width);
                    colors = cell2mat(timeData.ColorMap);
                    
                    for pc = 1:length(lines)
                        set(lines(pc),'Color',colors(pc,:))
                    end

                    legendText = sprintf('No significance test applied');
                end
                
                % Axis scaling settings
                % If the user has entered values into the axis boxes, register them
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [-maxY maxY];
                end
                
                % Set axes
                xlim(axesName(i),[x_lims(1) x_lims(2)]);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[x_lims(1) x_lims(2)],[-maxY -maxY],'w-','LineWidth',2)
                plot(axesName(i),[x_lims(1) x_lims(1)],[-maxY maxY],'w-','LineWidth',2)
                
                % Highlight strips
                [~, cols] = size(plotOptions.Highlight);
                if cols == 1
                    plotOptions.Highlight = plotOptions.Highlight';
                end
                
                if ~isempty(plotOptions.Highlight)
                    highlight = plotOptions.Highlight;
                    [hei, ~] = size(highlight);
                    
                    for p = 1:hei
                        highlight_strip = polyshape([highlight(p,1),highlight(p,2),highlight(p,2),highlight(p,1)],...
                                                    [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY*legendBox_y_buffer]);
                               
                        plot(axesName(i),highlight_strip,'FaceAlpha',0.3,'LineStyle','none','FaceColor',app.cPink);
                    end
                end
                
                % Plot the legend bar across the top
                ps_top = polyshape([x_lims(1) x_lims(2) x_lims(2) x_lims(1)],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);

                % Add the confidence text
                x_buffer = 0.02;
                
                text_x = (x_lims(2)-x_lims(1))*x_buffer;
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Add legend bar across the bottom and count how many in each category
                ps_bot = polyshape([x_lims(1) x_lims(2) x_lims(2) x_lims(1)],...
                                   [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer -maxY -maxY]);
                           
                plot(axesName(i),ps_bot,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Define x-y and width of the boxes
                text_x_spacer = (x_lims(2)-x_lims(1))*0.05;
                text_x = [x_lims(1)+text_x_spacer, x_lims(2)-text_x_spacer];
                text_y = -(maxY-((maxY-maxY*legendBox_y_buffer)/2));
                
                box_width = 1.35;
                box_height = -(maxY-((maxY-maxY*legendBox_y_buffer)/4));
                    
                if ~strcmp(plotOptions.Statistics,'None')
                    % If statistics selected, peptides will be deprot, prot and non-sig
                    % categories, therefore make 3 boxes across the bottom axes, equally
                    % spaced
                    
                    % Loop and plot the boxes
                    for w = 0:2
                        offset = ((text_x(2)-text_x(1))*0.33*w);
                        
                        box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                        [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                    
                        % Also count and display the number of peptides in each category
                        x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;

                        if w == 0
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsRed);
                            text(axesName(i),x_text,text_y,numbers{1},'FontSize',app.fs_figureText)
                        elseif w == 2
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsBlue);
                            text(axesName(i),x_text,text_y,numbers{3},'FontSize',app.fs_figureText)
                        else
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsGrey);
                            text(axesName(i),x_text,text_y,numbers{2},'FontSize',app.fs_figureText)
                        end
                    end
                else
                    % If no statistics, then just count how many are positive/negative differences
                    for w = 0:1
                        offset = ((text_x(2)-text_x(1))*0.5*w);
                        
                        box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                        [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                    
                        % Also count and display the number of peptides in each category
                        x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;

                        if w == 0
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cPink);
                            numberPositive = height(timeData(timeData.Uptake_diff_ave > 0,:));
                            
                            text(axesName(i),x_text,text_y,sprintf('Positive differences: %d',numberPositive),'FontSize',app.fs_figureText)
                        elseif w == 1 
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cBlue);
                            numberNegative = height(timeData(timeData.Uptake_diff_ave < 0,:));
                            
                            text(axesName(i),x_text,text_y,sprintf('Negative differences: %d',numberNegative),'FontSize',app.fs_figureText)
                        end
                    end
                    
                end
                
                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                
                % If not no statistics applied
                if strcmp(plotOptions.Statistics,'Peptide')
                    % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                    peptide_names = dataTipTextRow('Peptide',regexprep(strcat(num2str(statsData.Start),'-',num2str(statsData.End),': ',statsData.Sequence), '\s', ''));
                    exposure_names = dataTipTextRow('Exposure',strcat(statsData.Exposure,' min'));
                    uptakes = dataTipTextRow('ΔDU',strcat(string(statsData.Uptake_diff_ave),' Da'));
                    category = dataTipTextRow('Category',statsData.Significance);
                    significance = dataTipTextRow('Significance',statsData.BH_pValue);
                    bx = dataTipTextRow('Back-exchange',statsData.Correction);
                    
                    for labs = 1:length(lines)
                        lines(labs).DataTipTemplate.DataTipRows(1) = sprintf('Peptide: %s',peptide_names.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(2) = sprintf('Exposure: %s',exposure_names.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(3) = sprintf('ΔDU: %s',uptakes.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(4) = sprintf('Category: %s',category.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(5) = sprintf('p-value (adjusted): %.2e',significance.Value(labs));
                        lines(labs).DataTipTemplate.DataTipRows(6) = sprintf('BX corr: %s',string(bx.Value{labs}));
                    end
                elseif strcmp(plotOptions.Statistics,'Hybrid')
                    % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                    peptide_names = dataTipTextRow('Peptide',regexprep(strcat(num2str(statsData.Start),'-',num2str(statsData.End),': ',statsData.Sequence), '\s', ''));
                    exposure_names = dataTipTextRow('Exposure',strcat(statsData.Exposure,' min'));
                    uptakes = dataTipTextRow('ΔDU',strcat(string(statsData.Uptake_diff_ave),' Da'));
                    category = dataTipTextRow('Category',statsData.Significance);
                    significance = dataTipTextRow('Significance',statsData.Welch_pValue);
                    bx = dataTipTextRow('Back-exchange',statsData.Correction);
                    
                    for labs = 1:length(lines)
                        lines(labs).DataTipTemplate.DataTipRows(1) = sprintf('Peptide: %s',peptide_names.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(2) = sprintf('Exposure: %s',exposure_names.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(3) = sprintf('ΔDU: %s',uptakes.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(4) = sprintf('Category: %s',category.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(5) = sprintf("p-value (Welch's): %.2e",significance.Value(labs));
                        lines(labs).DataTipTemplate.DataTipRows(6) = sprintf('BX corr: %s',string(bx.Value{labs}));
                    end
                else % no statistics selected
                    % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                    peptide_names = dataTipTextRow('Peptide',regexprep(strcat(num2str(timeData.Start),'-',num2str(timeData.End),': ',timeData.Sequence), '\s', ''));
                    exposure_names = dataTipTextRow('Exposure',strcat(timeData.Exposure,' min'));
                    uptakes = dataTipTextRow('ΔDU',strcat(string(timeData.Uptake_diff_ave),' Da'));
                    bx = dataTipTextRow('Back-exchange',timeData.Correction);
                    
                    for labs = 1:length(lines)
                        lines(labs).DataTipTemplate.DataTipRows(1) = sprintf('Peptide: %s',peptide_names.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(2) = sprintf('Exposure: %s',exposure_names.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(3) = sprintf('ΔDU: %s',uptakes.Value{labs});
                        lines(labs).DataTipTemplate.DataTipRows(4) = sprintf('BX corr: %s',string(bx.Value{labs}));
                    end
                end
            end
        end
        
        function filteredTable = plotWoodsDouble(app,axesName,dataTable,plotOptions,stateA_name,stateB_name)
            % Takes dataTable for double woods or butterfly plot and plots

            % Axis limits
            axis_buffer = 1.3; % additional buffer zone to add to y-axis

            % Loop over the number of plots, get the name of the plot and then find the same data from the datatable, filter and plot
            filteredTable = [];
            for i = 1:length(axesName)
                hold(axesName(i),'on');
                
                % Controls how thick the peptide bars are
                ln_width = 3;
                legendBox_y_buffer = 0.85;

                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Get the max Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxY = nanmax(abs(dataTable.Uptake_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                else
                    maxY = nanmax(abs(dataTable.Uptake_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                end
                
                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                
                plot(axesName(i),app.startEndResidues,[0, 0],'k') % plot the line at 0 uptake

                % State A data goes on top and state B data goes on bottom
                
                stateA_data = timeData(strcmp(timeData.State,stateA_name),:);
                stateB_data = timeData(strcmp(timeData.State,stateB_name),:);
                
                % Reverse the uptake values for state B data
                stateB_data.Uptake_ave = -stateB_data.Uptake_ave; 
                
                % Add colour
                stateA_data.ColorMap(:) = {app.cBlue};
                stateB_data.ColorMap(:) = {app.cPink};
                
                timeData = [stateA_data; stateB_data];
                
                % Plot pos and neg lines
                lines = plot(axesName(i),[timeData.Start.'; timeData.End.'],[timeData.Uptake_ave.'; timeData.Uptake_ave.'],'LineWidth',ln_width);
                colors = cell2mat(timeData.ColorMap);
                
                for pc = 1:length(lines)
                    set(lines(pc),'Color',colors(pc,:))
                end

                legendText = sprintf('No significance test applied');

                % Axis scaling settings
                % If the user has entered values into the axis boxes, register them
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [-maxY maxY];
                end
                
                % Set axes
                xlim(axesName(i),app.startEndResidues);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[app.startEndResidues(1) app.startEndResidues(2)],[-maxY -maxY],'w-','LineWidth',2)
                plot(axesName(i),[app.startEndResidues(1) app.startEndResidues(1)],[-maxY maxY],'w-','LineWidth',2)
                
                % Highlight strips
                [~, cols] = size(plotOptions.Highlight);
                if cols == 1
                    plotOptions.Highlight = plotOptions.Highlight';
                end
                
                if ~isempty(plotOptions.Highlight)
                    highlight = plotOptions.Highlight;
                    [hei, ~] = size(highlight);
                    
                    for p = 1:hei
                        highlight_strip = polyshape([highlight(p,1),highlight(p,2),highlight(p,2),highlight(p,1)],...
                                                    [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY*legendBox_y_buffer]);
                               
                        plot(axesName(i),highlight_strip,'FaceAlpha',0.3,'LineStyle','none','FaceColor',app.cPink);
                    end
                end
                
                % Plot the legend bar across the top
                ps_top = polyshape([app.startEndResidues(1) app.startEndResidues(2) app.startEndResidues(2) app.startEndResidues(1)],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Add the confidence text
                x_buffer = 0.02;
                
                text_x = (app.startEndResidues(2)-app.startEndResidues(1))*x_buffer;
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Add legend bar across the bottom and count how many in each category
                ps_bot = polyshape([app.startEndResidues(1) app.startEndResidues(2) app.startEndResidues(2) app.startEndResidues(1)],...
                                   [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer -maxY -maxY]);
                           
                plot(axesName(i),ps_bot,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Define x-y and width of the boxes
                text_x_spacer = (app.startEndResidues(2)-app.startEndResidues(1))*0.05;
                text_x = [app.startEndResidues(1)+text_x_spacer, app.startEndResidues(2)-text_x_spacer];
                text_y = -(maxY-((maxY-maxY*legendBox_y_buffer)/2));
                
                box_width = 1.35;
                box_height = -(maxY-((maxY-maxY*legendBox_y_buffer)/4));
                    
                % If no statistics, then just count how many are positive/negative
                % differences
                
                for w = 0:1
                    offset = ((text_x(2)-text_x(1))*0.5*w);
                    
                    box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                    [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                
                    % Also count and display the number of peptides in each category
                    x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;

                    if w == 0
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cBlue);
                        numberPositive = height(timeData(timeData.Uptake_ave > 0,:));
                        
                        text(axesName(i),x_text,text_y,sprintf('%s: %d',stateA_name,numberPositive),'FontSize',app.fs_figureText)
                    elseif w == 1 
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cPink);
                        numberNegative = height(timeData(timeData.Uptake_ave < 0,:));
                        
                        text(axesName(i),x_text,text_y,sprintf('%s: %d',stateB_name,numberNegative),'FontSize',app.fs_figureText)
                    end
                end

                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                peptide_names = dataTipTextRow('Peptide',regexprep(strcat(num2str(timeData.Start),'-',num2str(timeData.End),': ',timeData.Sequence), '\s', ''));
                exposure_names = dataTipTextRow('Exposure',strcat(timeData.Exposure,' min'));
                uptakes = dataTipTextRow('ΔDU',strcat(string(timeData.Uptake_ave),' Da'));
                bx = dataTipTextRow('Back-exchange',timeData.Correction);
                
                for labs = 1:length(lines)
                    lines(labs).DataTipTemplate.DataTipRows(1) = peptide_names.Value{labs};
                    lines(labs).DataTipTemplate.DataTipRows(2) = exposure_names.Value{labs};
                    lines(labs).DataTipTemplate.DataTipRows(3) = uptakes.Value{labs};
                    lines(labs).DataTipTemplate.DataTipRows(4) = bx.Value{labs};
                end
                
                filteredTable = [filteredTable; timeData];
            end
        end
        
        function filteredTable = plotWoodsSingle(app,axesName,dataTable,plotOptions,stateA_name)
            % Takes dataTable for double woods or butterfly plot and plots

            % Axis limits
            axis_buffer = 1.3; % additional buffer zone to add to y-axis

            % Loop over the number of plots, get the name of the plot and then find the
            % same data from the datatable, filter and plot

            filteredTable = [];
            for i = 1:length(axesName)
                
                hold(axesName(i),'on');
                
                % Controls how thick the peptide bars are
                ln_width = 3;
                legendBox_y_buffer = 0.92;
                
                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Get the max Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxY = nanmax(abs(dataTable.Uptake_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                else
                    maxY = nanmax(abs(dataTable.Uptake_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                end
                
                % Axis scaling settings
                % If the user has entered values into the axis boxes, register them
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [0 maxY];
                end
                
                % Set axes
                xlim(axesName(i),app.startEndResidues);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[app.startEndResidues(1) app.startEndResidues(2)],[0 0],'w-','LineWidth',2)
                plot(axesName(i),[app.startEndResidues(1) app.startEndResidues(1)],[0 maxY],'w-','LineWidth',2)
                
                % Highlight strips
                [~, cols] = size(plotOptions.Highlight);
                if cols == 1
                    plotOptions.Highlight = plotOptions.Highlight';
                end
                
                if ~isempty(plotOptions.Highlight)
                    highlight = plotOptions.Highlight;
                    [hei, ~] = size(highlight);
                    
                    for p = 1:hei
                        highlight_strip = polyshape([highlight(p,1),highlight(p,2),highlight(p,2),highlight(p,1)],...
                                                    [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY*legendBox_y_buffer]);
                               
                        plot(axesName(i),highlight_strip,'FaceAlpha',0.3,'LineStyle','none','FaceColor',app.cPink);
                    end
                end

                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                stateA_data = timeData(strcmp(timeData.State,stateA_name),:);
                stateA_data.ColorMap(:) = {app.cBlue};
                timeData = stateA_data;
                
                % Plot pos and neg lines
                lines = plot(axesName(i),[timeData.Start.'; timeData.End.'],[timeData.Uptake_ave.'; timeData.Uptake_ave.'],'LineWidth',ln_width);
                colors = cell2mat(timeData.ColorMap);
                
                for pc = 1:length(lines)
                    set(lines(pc),'Color',colors(pc,:))
                end

                legendText = sprintf('No significance test applied');

                % Plot the legend bar across the top
                ps_top = polyshape([app.startEndResidues(1) app.startEndResidues(2) app.startEndResidues(2) app.startEndResidues(1)],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Add the confidence text
                x_buffer = 0.02;
                
                text_x = (app.startEndResidues(2)-app.startEndResidues(1))*x_buffer;
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Add legend bar across the bottom and count how many in each category
                ps_bot = polyshape([app.startEndResidues(1) app.startEndResidues(2) app.startEndResidues(2) app.startEndResidues(1)],...
                                   [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer -maxY -maxY]);
                           
                plot(axesName(i),ps_bot,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Define x-y and width of the boxes
                text_x_spacer = (app.startEndResidues(2)-app.startEndResidues(1))*0.05;
                text_x = [app.startEndResidues(1)+text_x_spacer, app.startEndResidues(2)-text_x_spacer];
                text_y = -(maxY-((maxY-maxY*legendBox_y_buffer)/2));
                
                box_width = 1.35;
                box_height = -(maxY-((maxY-maxY*legendBox_y_buffer)/4));
                    
                % If no statistics, then just count how many are positive/negative differences
                for w = 0:1
                    offset = ((text_x(2)-text_x(1))*0.5*w);
                    
                    box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                    [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                
                    % Also count and display the number of peptides in each category
                    x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;

                    if w == 0
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cPink);
                        numberPositive = height(timeData(timeData.Uptake_ave > 0,:));
                        
                        text(axesName(i),x_text,text_y,sprintf('%s: %d',stateA_name,numberPositive),'FontSize',app.fs_figureText)
                    end
                end

                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                peptide_names = dataTipTextRow('',regexprep(strcat(num2str(timeData.Start),'-',num2str(timeData.End),': ',timeData.Sequence), '\s', ''));
                exposure_names = dataTipTextRow('',arrayfun(@(x) sprintf('Exposure: %.2f min',x),timeData.Exposure,'UniformOutput',false));
                uptakes = dataTipTextRow('',arrayfun(@(x) sprintf('DU: %.1f Da',x),timeData.Uptake_ave,'UniformOutput',false));
                bx = dataTipTextRow('',cellfun(@(x) sprintf('Back exchange: %.1f%%',x),timeData.Correction,'UniformOutput',false));
                
                for labs = 1:length(lines)
                    lines(labs).DataTipTemplate.DataTipRows(1) = peptide_names.Value{labs};
                    lines(labs).DataTipTemplate.DataTipRows(2) = exposure_names.Value{labs};
                    lines(labs).DataTipTemplate.DataTipRows(3) = uptakes.Value{labs};
                    lines(labs).DataTipTemplate.DataTipRows(4) = bx.Value{labs};
                end
                
                filteredTable = [filteredTable; timeData];
            end
        end
        
        function filteredTable = plotButterflyDifferential(app,axesName,dataTable,plotOptions)
            % Takes dataTable, e.g. app.diff.common or app.diff.common_pValues, applies
            % filtering and plots 

            % Axis limits
            axis_buffer = 1.3; % additional buffer zone to add to y-axis

            % Loop over the number of plots, get the name of the plot and then find the same data from the datatable, filter and plot
            variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure',...
                             'z','N','RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B','Center_diff_ave','Center_diff_SD','Center_reps_A',...
                             'Center_reps_B','Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B','RU_diff_ave',...
                             'RU_diff_SD','RU_reps_A','RU_reps_B','Correction','Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue',...
                             'BH_sig','Index','Significance','ColorMap'};
            
            filteredTable = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames);
            for i = 1:length(axesName)
                hold(axesName(i),'on');
                
                % Controls how thick the peptide bars are
                ln_width = 1.5;
                legendBox_y_buffer = 0.85;
                
                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Get the max Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxY = nanmax(abs(dataTable.Uptake_diff_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                else
                    maxY = nanmax(abs(dataTable.Uptake_diff_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                end
                
                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                timeData.Index = [1:1:height(timeData)]';
                
                plot(axesName(i),[0, height(timeData)],[0, 0],'k') % plot the line at 0 uptake
                
                numbers = {}; % counter for how many peptides in each category
                if strcmp(plotOptions.Statistics,'Hybrid')
                    % Get relevant statistics row from stats table
                    if ~strcmp(plotName,'Sum')
                        statsRow = plotOptions.hybrid(strcmp(plotOptions.hybrid.Type,'Non-sum'),:);
                    else
                        statsRow = plotOptions.hybrid(strcmp(plotOptions.hybrid.Type,'Sum'),:);
                    end
                    
                    filter = statsRow.CI;
                    
                    % Get protected, deprotected and non-significant data
                    protected    = timeData((timeData.Uptake_diff_ave < -statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence),:);
                    deprotected  = timeData((timeData.Uptake_diff_ave >  statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence),:);
                    nonsignificant = timeData(((timeData.Uptake_diff_ave < statsRow.CI) & (timeData.Uptake_diff_ave > -statsRow.CI)) | ((timeData.Uptake_diff_ave >  statsRow.CI) & (timeData.Welch_pValue > plotOptions.Confidence)) | (timeData.Uptake_diff_ave < -statsRow.CI) & (timeData.Welch_pValue > plotOptions.Confidence),:);
                    
                    % Add confidence interval lines
                    plot(axesName(i),[0, height(timeData)],[filter, filter],'k--') % plot the line at +CI
                    plot(axesName(i),[0, height(timeData)],[-filter, -filter],'k--') % plot the line at -CI
                    
                    legendText = sprintf('Hybrid significance test: %.1f%% CI: %.2f Da / p-value < %s',(1-plotOptions.Confidence)*100,filter,num2str(plotOptions.Confidence));
                    
                    numbers{end+1} = sprintf('Deprotected: %d',height(deprotected));
                    numbers{end+1} = sprintf('Non-sig: %d',height(nonsignificant));
                    numbers{end+1} = sprintf('Protected: %d',height(protected));
                elseif strcmp(plotOptions.Statistics,'Peptide')
                    % According to B-H adjusted p-value
                    protected    = timeData((timeData.Uptake_diff_ave < 0) & (timeData.BH_pValue < plotOptions.Confidence),:);
                    deprotected  = timeData((timeData.Uptake_diff_ave > 0) & (timeData.BH_pValue < plotOptions.Confidence),:);
                    nonsignificant = timeData((timeData.BH_pValue > plotOptions.Confidence),:);
                    
                    % Depending on the significance level, change the BH_sig value to significant
                    protected.BH_sig(protected.BH_pValue < plotOptions.Confidence) = 1;
                    deprotected.BH_sig(deprotected.BH_pValue < plotOptions.Confidence) = 1;
                    
                    legendText = sprintf('Peptide significance test: B-H adjusted p-value < %s',num2str(plotOptions.Confidence));
                    
                    numbers{end+1} = sprintf('Deprotected: %d',height(deprotected));
                    numbers{end+1} = sprintf('Non-sig: %d',height(nonsignificant));
                    numbers{end+1} = sprintf('Protected: %d',height(protected));
                end
                
                % If stats or no stats selected
                if ~strcmp(plotOptions.Statistics,'None')
                    % Add significance outcome from the tests and append to a single table
                    protected.Significance(:) = {'Protected'};
                    deprotected.Significance(:) = {'Deprotected'};
                    nonsignificant.Significance(:) = {'Non significant'};
                    
                    % Additionally add colour map values to the new column so that plotting is
                    % easier later
                    protected.ColorMap(:) = {app.cWoodsBlue};
                    deprotected.ColorMap(:) = {app.cWoodsRed};
                    nonsignificant.ColorMap(:) = {app.cWoodsGrey};
                    
                    % Put nonsig first so that they are plotted first
                    statsData = sortrows([nonsignificant; deprotected; protected],{'Start','End'});
                    filteredTable = sortrows([filteredTable; statsData],{'Start','End'});
                    
                    % Plot spline points
                    xx = statsData.Index(1):0.1:statsData.Index(end);
                    yy = spline(statsData.Index,statsData.Uptake_diff_ave,xx);
                    
                    l = plot(axesName(i),xx,yy,'-','Color',[0.3 0.3 0.3],'LineWidth',ln_width);

                    % Plot the three catergories
                    s = scatter(axesName(i),statsData.Index,statsData.Uptake_diff_ave,30,cell2mat(statsData.ColorMap),'filled');
                else % no statistics selected
                    stateA = timeData(timeData.Uptake_diff_ave > 0,:);
                    stateB = timeData(timeData.Uptake_diff_ave < 0,:);
                    
                    % Label as no significance applied
                    stateA.Significance(:) = {'None applied'};
                    stateB.Significance(:) = {'None applied'};
                    
                    % Additionally add colour map values to the new column so that plotting is
                    % easier later
                    stateA.ColorMap(:) = {app.cBlue};
                    stateB.ColorMap(:) = {app.cPink};
                    
                    % Put nonsig first so that they are plotted first
                    statsData = sortrows([stateA; stateB],{'Start','End'});
                    filteredTable = [filteredTable; statsData];
                    
                    % Plot the three catergories
                    l = plot(axesName(i),statsData.Index,statsData.Uptake_diff_ave,'k-','LineWidth',ln_width);
                    s = scatter(axesName(i),statsData.Index,statsData.Uptake_diff_ave,30,cell2mat(statsData.ColorMap),'filled');
                    
                    legendText = sprintf('No significance test applied');
                end
                
                % Axis scaling settings
                % If the user has entered values into the axis boxes, register them
                x_lims = [1 max(statsData.Index)];
                
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [-maxY maxY];
                end
                
                % Set axes
                xlim(axesName(i),[x_lims(1) x_lims(2)]);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[x_lims(1) x_lims(2)],[-maxY -maxY],'w-','LineWidth',2)
                plot(axesName(i),[x_lims(1) x_lims(1)],[-maxY maxY],'w-','LineWidth',2)

                % Plot the legend bar across the top
                ps_top = polyshape([1 max(statsData.Index) max(statsData.Index) 1],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Add the confidence text
                x_buffer = 0.02;
                
                text_x = (max(statsData.Index)-1)*x_buffer;
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Add legend bar across the bottom and count how many in each category
                ps_bot = polyshape([1 max(statsData.Index) max(statsData.Index) 1],...
                                   [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer -maxY -maxY]);
                           
                plot(axesName(i),ps_bot,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Define x-y and width of the boxes
                text_x_spacer = (max(statsData.Index)-1)*0.05;
                text_x = [1+text_x_spacer, max(statsData.Index)-text_x_spacer];
                text_y = -(maxY-((maxY-maxY*legendBox_y_buffer)/2));
                
                box_width = 1.35;
                box_height = -(maxY-((maxY-maxY*legendBox_y_buffer)/4));
                    
                if ~strcmp(plotOptions.Statistics,'None')
                    % If statistics selected, peptides will be deprot, prot and non-sig
                    % categories, therefore make 3 boxes across the bottom axes, equally
                    % spaced
                    
                    % Loop and plot the boxes
                    for w = 0:2
                        offset = ((text_x(2)-text_x(1))*0.33*w);
                        
                        box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                        [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                    
                        % Also count and display the number of peptides in each category
                        x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;

                        if w == 0
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsRed);
                            text(axesName(i),x_text,text_y,numbers{1},'FontSize',app.fs_figureText)
                        elseif w == 2
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsBlue);
                            text(axesName(i),x_text,text_y,numbers{3},'FontSize',app.fs_figureText)
                        else
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsGrey);
                            text(axesName(i),x_text,text_y,numbers{2},'FontSize',app.fs_figureText)
                        end
                    end
                else
                    % If no statistics, then just count how many are positive/negative
                    % differences
                    
                    for w = 0:1
                        offset = ((text_x(2)-text_x(1))*0.5*w);
                        
                        box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                        [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                    
                        % Also count and display the number of peptides in each category
                        x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;

                        if w == 0
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cBlue);
                            numberPositive = height(timeData(timeData.Uptake_diff_ave > 0,:));
                            
                            text(axesName(i),x_text,text_y,sprintf('Positive differences: %d',numberPositive),'FontSize',app.fs_figureText)
                        elseif w == 1 
                            plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cPink);
                            numberNegative = height(timeData(timeData.Uptake_diff_ave < 0,:));
                            
                            text(axesName(i),x_text,text_y,sprintf('Negative differences: %d',numberNegative),'FontSize',app.fs_figureText)
                        end
                    end
                end

                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                
                % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                peptide_names = dataTipTextRow('Peptide',regexprep(strcat(num2str(statsData.Start),'-',num2str(statsData.End),': ',statsData.Sequence), '\s', ''));
                s.DataTipTemplate.DataTipRows(1) = peptide_names;
    
                % Exposure time in (min): 0.25 min
                exposure_names = dataTipTextRow('Exposure',strcat(statsData.Exposure,' min'));
                s.DataTipTemplate.DataTipRows(2) = exposure_names;
                
                % Uptake difference
                uptakes = dataTipTextRow('ΔDU',strcat(string(statsData.Uptake_diff_ave),' Da'));
                s.DataTipTemplate.DataTipRows(3) = uptakes;
                
                % Uptake Significance
                uptakes = dataTipTextRow('Significance',statsData.Significance);
                s.DataTipTemplate.DataTipRows(4) = uptakes;
                
                % Significance
                uptakes = dataTipTextRow('Back-exchange',statsData.Correction);
                s.DataTipTemplate.DataTipRows(5) = uptakes;
            end
        end
        
        function filteredTable = plotButterflyDouble(app,axesName,dataTable,plotOptions,stateA_name,stateB_name)
            % Takes dataTable, e.g. app.diff.common or app.diff.common_pValues, applies
            % filtering and plots 

            % Axis limits
            axis_buffer = 1.3; % additional buffer zone to add to y-axis

            % Loop over the number of plots, get the name of the plot and then find the same data from the datatable, filter and plot
            variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure',...
                             'z','N','RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B','Center_diff_ave','Center_diff_SD','Center_reps_A',...
                             'Center_reps_B','Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B','RU_diff_ave',...
                             'RU_diff_SD','RU_reps_A','RU_reps_B','Correction','Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue',...
                             'Index','Significance','ColorMap'};
            
            filteredTable = [];
            for i = 1:length(axesName)
                hold(axesName(i),'on');
                
                % Controls how thick the peptide bars are
                ln_width = 1.5;
                legendBox_y_buffer = 0.85;
                
                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Get the max Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxY = nanmax(abs(dataTable.Uptake_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                else
                    maxY = nanmax(abs(dataTable.Uptake_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                end

                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                
                % State A data goes on top and state B data goes on bottom
                
                stateA_data = timeData(strcmp(timeData.State,stateA_name),:);
                stateB_data = timeData(strcmp(timeData.State,stateB_name),:);
                
                % Reverse the uptake values for state B data
                stateB_data.Uptake_ave = -stateB_data.Uptake_ave; 
                
                % Add colour
                stateA_data.ColorMap(:) = {app.cBlue};
                stateB_data.ColorMap(:) = {app.cPink};
                
                % Assign unique peptide list with index
                timeData = sortrows([stateA_data; stateB_data],{'Start','End'});
                uniquePeptides = unique(timeData.Sequence,'stable');
                uniquePeptides = table(uniquePeptides,[1:length(uniquePeptides)]','VariableNames',{'Sequence','Index'});
                
                indexTable = [];
                for w = 1:height(timeData)
                    rowData = timeData(w,:);
                    rowData.Index = uniquePeptides.Index(strcmp(uniquePeptides.Sequence,rowData.Sequence),:);
                    indexTable = [indexTable; rowData];
                end
                
                % Split into state A and B again
                stateA_data = indexTable(strcmp(indexTable.State,stateA_name),:);
                stateB_data = indexTable(strcmp(indexTable.State,stateB_name),:);
                
                plot(axesName(i),[0, max(indexTable.Index)],[0, 0],'k') % plot the line at 0 uptake

                % Plot the two categories
                lA = plot(axesName(i),stateA_data.Index,stateA_data.Uptake_ave,'k-','LineWidth',ln_width);
                sA = scatter(axesName(i),stateA_data.Index,stateA_data.Uptake_ave,30,cell2mat(stateA_data.ColorMap),'filled');
                
                lB = plot(axesName(i),stateB_data.Index,stateB_data.Uptake_ave,'k-','LineWidth',ln_width);
                sB = scatter(axesName(i),stateB_data.Index,stateB_data.Uptake_ave,30,cell2mat(stateB_data.ColorMap),'filled');
                
                legendText = sprintf('No significance test applied');
                
                % Axis scaling settings
                % If the user has entered values into the axis boxes, register them
                x_lims = [1 max(indexTable.Index)];
                
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [-maxY maxY];
                end
                
                % Set axes
                xlim(axesName(i),[x_lims(1) x_lims(2)]);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[x_lims(1) x_lims(2)],[-maxY -maxY],'w-','LineWidth',2)
                plot(axesName(i),[x_lims(1) x_lims(1)],[-maxY maxY],'w-','LineWidth',2)

                % Plot the legend bar across the top
                ps_top = polyshape([1 max(indexTable.Index) max(indexTable.Index) 1],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Add the confidence text
                x_buffer = 0.02;
                
                text_x = (max(indexTable.Index)-1)*x_buffer;
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Add legend bar across the bottom and count how many in each category
                ps_bot = polyshape([1 max(indexTable.Index) max(indexTable.Index) 1],...
                                   [-maxY*legendBox_y_buffer -maxY*legendBox_y_buffer -maxY -maxY]);
                           
                plot(axesName(i),ps_bot,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Define x-y and width of the boxes
                text_x_spacer = (max(indexTable.Index)-1)*0.05;
                text_x = [1+text_x_spacer, max(indexTable.Index)-text_x_spacer];
                text_y = -(maxY-((maxY-maxY*legendBox_y_buffer)/2));
                
                box_width = 1.35;
                box_height = -(maxY-((maxY-maxY*legendBox_y_buffer)/4));

                % If no statistics, then just count how many are positive/negative differences
                for w = 0:1
                    offset = ((text_x(2)-text_x(1))*0.5*w);
                    
                    box = polyshape([text_x(1) text_x(1)*box_width text_x(1)*box_width text_x(1)]+offset,...
                                    [box_height box_height text_y+(text_y-box_height) text_y+(text_y-box_height)]);
                                
                    % Also count and display the number of peptides in each category
                    x_text = text_x(1)*(1+((box_width-1)*1.5))+offset;
                    if w == 0
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cBlue);
                        numberA = height(stateA_data);
                        text(axesName(i),x_text,text_y,sprintf('%s: %d',stateA_name,numberA),'FontSize',app.fs_figureText)
                    elseif w == 1 
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cPink);
                        numberB = height(stateB_data);
                        text(axesName(i),x_text,text_y,sprintf('%s: %d',stateB_name,numberB),'FontSize',app.fs_figureText)
                    end
                end
                
                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                
                % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                peptide_namesA = dataTipTextRow('Peptide',regexprep(strcat(num2str(stateA_data.Start),'-',num2str(stateA_data.End),': ',stateA_data.Sequence), '\s', ''));
                sA.DataTipTemplate.DataTipRows(1) = peptide_namesA;
                
                peptide_namesB = dataTipTextRow('Peptide',regexprep(strcat(num2str(stateB_data.Start),'-',num2str(stateB_data.End),': ',stateB_data.Sequence), '\s', ''));
                sB.DataTipTemplate.DataTipRows(1) = peptide_namesB;
    
                % Exposure time in (min): 0.25 min
                exposure_namesA = dataTipTextRow('Exposure',strcat(stateA_data.Exposure,' min'));
                sA.DataTipTemplate.DataTipRows(2) = exposure_namesA;
                
                exposure_namesB = dataTipTextRow('Exposure',strcat(stateB_data.Exposure,' min'));
                sB.DataTipTemplate.DataTipRows(2) = exposure_namesB;
                
                % Uptake difference
                uptakesA = dataTipTextRow('DU',strcat(string(stateA_data.Uptake_ave),' Da'));
                sA.DataTipTemplate.DataTipRows(3) = uptakesA;
                
                uptakesB = dataTipTextRow('DU',strcat(string(stateB_data.Uptake_ave),' Da'));
                sB.DataTipTemplate.DataTipRows(3) = uptakesB;

                % Significance
                signficanceA = dataTipTextRow('Back-exchange',stateA_data.Correction);
                sA.DataTipTemplate.DataTipRows(4) = signficanceA;
                
                signficanceB = dataTipTextRow('Back-exchange',stateB_data.Correction);
                sB.DataTipTemplate.DataTipRows(4) = signficanceB;
                
                filteredTable = [filteredTable; stateA_data; stateB_data];
            end
        end
        
        function filteredTable = plotButterflySingle(app,axesName,dataTable,plotOptions,stateA_name)
            % Takes dataTable, e.g. app.diff.common or app.diff.common_pValues, applies filtering and plots 

            % Axis limits
            axis_buffer = 1.3; % additional buffer zone to add to y-axis

            % Loop over the number of plots, get the name of the plot and then find the same data from the datatable, filter and plot
            variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure',...
                             'z','N','RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B','Center_diff_ave','Center_diff_SD','Center_reps_A',...
                             'Center_reps_B','Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B','RU_diff_ave',...
                             'RU_diff_SD','RU_reps_A','RU_reps_B','Correction','Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue',...
                             'Index','Significance','ColorMap'};
            
            filteredTable = [];
            for i = 1:length(axesName)
                hold(axesName(i),'on');
                
                % Controls how thick the peptide bars are
                ln_width = 1.5;
                legendBox_y_buffer = 0.92;
                
                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Get the max Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxY = nanmax(abs(dataTable.Uptake_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                else
                    maxY = nanmax(abs(dataTable.Uptake_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                end

                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                
                % State A data goes on top and state B data goes on bottom
                stateA_data = timeData(strcmp(timeData.State,stateA_name),:);

                % Add colour
                stateA_data.ColorMap(:) = {app.cBlue};

                % Assign unique peptide list with index
                timeData = sortrows(stateA_data,{'Start','End'});
                uniquePeptides = unique(timeData.Sequence,'stable');
                uniquePeptides = table(uniquePeptides,[1:length(uniquePeptides)]','VariableNames',{'Sequence','Index'});
                
                indexTable = [];
                for w = 1:height(timeData)
                    rowData = timeData(w,:);
                    rowData.Index = uniquePeptides.Index(strcmp(uniquePeptides.Sequence,rowData.Sequence),:);
                    indexTable = [indexTable; rowData];
                end
                
                % Axis scaling settings
                % If the user has entered values into the axis boxes, register them
                x_lims = [1 max(indexTable.Index)];
                
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [0 maxY];
                end
                
                % Set axes
                xlim(axesName(i),x_lims);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[x_lims(1) x_lims(2)],[0 0],'w-','LineWidth',2)
                plot(axesName(i),[x_lims(1) x_lims(1)],[0 maxY],'w-','LineWidth',2)
                
                % Split into state A and B again
                stateA_data = indexTable(strcmp(indexTable.State,stateA_name),:);

                % Plot the two categories
                lA = plot(axesName(i),stateA_data.Index,stateA_data.Uptake_ave,'k-','LineWidth',ln_width);
                sA = scatter(axesName(i),stateA_data.Index,stateA_data.Uptake_ave,30,cell2mat(stateA_data.ColorMap),'filled');

                legendText = sprintf('No significance test applied');

                % Plot the legend bar across the top
                ps_top = polyshape([1 max(indexTable.Index) max(indexTable.Index) 1],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Add the confidence text
                x_buffer = 0.02;
                
                text_x = (max(indexTable.Index)-1)*x_buffer;
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Define x-y and width of the boxes
                text_x_spacer = (max(indexTable.Index)-1)*0.05;
                text_x = [1+text_x_spacer, max(indexTable.Index)-text_x_spacer];
                text_y = -(maxY-((maxY-maxY*legendBox_y_buffer)/2));
                
                box_width = 1.35;
                box_height = -(maxY-((maxY-maxY*legendBox_y_buffer)/4));
                    
                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                
                % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                peptide_namesA = dataTipTextRow('Peptide',regexprep(strcat(num2str(stateA_data.Start),'-',num2str(stateA_data.End),': ',stateA_data.Sequence), '\s', ''));
                sA.DataTipTemplate.DataTipRows(1) = peptide_namesA;

                % Exposure time in (min): 0.25 min
                exposure_namesA = dataTipTextRow('Exposure',strcat(stateA_data.Exposure,' min'));
                sA.DataTipTemplate.DataTipRows(2) = exposure_namesA;

                % Uptake difference
                uptakesA = dataTipTextRow('DU',strcat(string(stateA_data.Uptake_ave),' Da'));
                sA.DataTipTemplate.DataTipRows(3) = uptakesA;

                % Significance
                signficanceA = dataTipTextRow('Back-exchange',stateA_data.Correction);
                sA.DataTipTemplate.DataTipRows(4) = signficanceA;
                
                filteredTable = [filteredTable; stateA_data];
            end
        end
    

        function filteredTable = plotVolcano(app,axesName,dataTable,plotOptions)
            % Takes dataTable, e.g. app.diff.common or app.diff.common_pValues, applies filtering and plots 
            variableNames = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure',...
                             'z','N','RT_diff_ave','RT_diff_SD','RT_reps_A','RT_reps_B','Center_diff_ave','Center_diff_SD','Center_reps_A',...
                             'Center_reps_B','Uptake_diff_ave','Uptake_diff_SD','Uptake_reps_A','Uptake_reps_B','RU_diff_ave',...
                             'RU_diff_SD','RU_reps_A','RU_reps_B','Correction','Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue',...
                             'BH_sig','Significance','ColorMap'};
            
            filteredTable = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames);
            
            % Loop over the number of plots, get the name of the plot and then find the
            % same data from the datatable, filter and plot
            for i = 1:length(axesName)
                plotName = strsplit(strtrim(axesName(i).Title.String),' ');
                plotName = plotName{1};
                
                % Time sorted data
                timeData = dataTable(strcmp(dataTable.Exposure,plotName),:);
                hold(axesName(i),'on');
                
                numbers = {}; % counter for how many peptides in each category
                if strcmp(plotOptions.Statistics,'Hybrid')
                    % Get relevant statistics row from stats table
                    if ~strcmp(plotName,'Sum')
                        statsRow = plotOptions.hybrid(strcmp(plotOptions.hybrid.Type,'Non-sum'),:);
                    else
                        statsRow = plotOptions.hybrid(strcmp(plotOptions.hybrid.Type,'Sum'),:);
                    end
                    
                    filter = statsRow.CI;
                    
                    % Get protected, deprotected and non-significant data
                    protected    = timeData((timeData.Uptake_diff_ave < -statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence),:);
                    deprotected  = timeData((timeData.Uptake_diff_ave >  statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence),:);
                    nonsignificant = timeData((timeData.Welch_pValue > plotOptions.Confidence) | ((timeData.Uptake_diff_ave < statsRow.CI) & (timeData.Uptake_diff_ave > -statsRow.CI) & (timeData.Welch_pValue < plotOptions.Confidence)),:);

                    legendText = sprintf('Hybrid significance test: %.1f%% CI: %.2f Da / p-value < %s',(1-plotOptions.Confidence)*100,filter,num2str(plotOptions.Confidence));
                    
                    numbers{end+1} = sprintf('Deprotected: %d',height(deprotected));
                    numbers{end+1} = sprintf('Non-sig: %d',height(nonsignificant));
                    numbers{end+1} = sprintf('Protected: %d',height(protected));
                end
  
                % Add significance outcome from the tests and append to a single table
                protected.Significance(:)    = {'Protected'};
                deprotected.Significance(:)  = {'Deprotected'};
                nonsignificant.Significance(:) = {'Non significant'};
                
                % Additionally add colour map values to the new column so that plotting is
                % easier later
                protected.ColorMap(:) = {app.cWoodsBlue};
                deprotected.ColorMap(:) = {app.cWoodsRed};
                nonsignificant.ColorMap(:) = {app.cWoodsGrey};
                
                % Put nonsig first so that they are plotted first
                statsData = [nonsignificant; protected; deprotected];
                ColorMap = cell2mat(statsData.ColorMap);
                
                filteredTable = [filteredTable; statsData];

                % Plot the data
                markerSize = app.volcanoMarkerSize;
                
                % Axis Limits
                axis_buffer = 1.2; % additional buffer zone to add to y-axis
                legendBox_y_buffer = 0.9;
                
                s = scatter(axesName(i),statsData.Uptake_diff_ave,-log10(statsData.Welch_pValue),markerSize,ColorMap,'filled','MarkerEdgeColor',[1 1 1],'LineWidth',1.2); % plot the volcano
            
                % Get the max X and Y value depending on sum or not 
                if ~strcmp(plotName,'Sum')
                    maxX = nanmax(abs(dataTable.Uptake_diff_ave(~strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                    maxY = nanmax(-log10(dataTable.Welch_pValue(~strcmp(dataTable.Exposure,'Sum') & dataTable.Welch_pValue ~= 0,:)))*axis_buffer;
                else
                    maxX = nanmax(abs(dataTable.Uptake_diff_ave(strcmp(dataTable.Exposure,'Sum'),:)))*axis_buffer;
                    maxY = nanmin(-log10(dataTable.Welch_pValue(strcmp(dataTable.Exposure,'Sum'),:)));
                end

                % This is to make an equal sized buffer gap on the bottom of the plot below 0
                topY = nanmax(-log10(dataTable.Welch_pValue(~strcmp(dataTable.Exposure,'Sum') & dataTable.Welch_pValue ~= 0,:)))*axis_buffer;
                topY = (topY*axis_buffer)-topY;
                bottomY = 0-(topY/1.5);

                % If the user has entered values into the axis boxes, register them
                x_lims = [-maxX maxX];
                y_cond1 = ~isempty(app.Advanced_yaxis_low.Value);
                y_cond2 = ~isempty(app.Advanced_yaxis_high.Value);
                
                if y_cond1 && y_cond2
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                elseif y_cond1
                    y_lims = [str2double(app.Advanced_yaxis_low.Value) maxY];
                elseif y_cond2
                    y_lims = [bottomY str2double(app.Advanced_yaxis_high.Value)];
                    maxY = y_lims(2);
                else
                    y_lims = [bottomY maxY];
                end
                
                % Set axes
                xlim(axesName(i),[x_lims(1) x_lims(2)]);
                ylim(axesName(i),[y_lims(1) y_lims(2)]);
                
                % Remove the annoying x and y axis lines
                plot(axesName(i),[x_lims(1) x_lims(2)],[bottomY bottomY],'w-','LineWidth',2)
                plot(axesName(i),[x_lims(1) x_lims(1)],[bottomY maxY],'w-','LineWidth',2)
                
                % Plot the legend bar across the top
                ps_top = polyshape([-maxX maxX maxX -maxX],...
                                   [maxY*legendBox_y_buffer maxY*legendBox_y_buffer maxY maxY]);
                               
                plot(axesName(i),ps_top,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Add the confidence text
                x_buffer = 0.02;
                barWidth = maxY-(maxY*legendBox_y_buffer);
                
                text_x = -maxX+(maxX*x_buffer);
                text_y = maxY-((maxY-maxY*legendBox_y_buffer)/2);
                text(axesName(i),text_x,text_y,legendText,'FontSize',app.fs_figureText)
                
                % Add confidence interval lines
                p_line = -log10(plotOptions.Confidence);
                line_top = maxY*legendBox_y_buffer;
                line_bottom = bottomY+barWidth;
                line_left = -maxX;
                line_right = maxX;
                
                % Black-line filters
                color = [0 0 0]; 
                linewidth = 1.2;
                
                plot(axesName(i),[line_left, -filter],[p_line p_line],'--','Color',color,'LineWidth',linewidth)
                plot(axesName(i),[filter  line_right],[p_line p_line],'--','Color',color,'LineWidth',linewidth)
                plot(axesName(i),[-filter,-filter],[p_line line_top],'--','Color',color,'LineWidth',linewidth)
                plot(axesName(i),[filter,filter],[p_line line_top],'--','Color',color,'LineWidth',linewidth)
                
                % Grey lines
                color = [0.6 0.6 0.6];
                linewidth = 0.8;
                
                plot(axesName(i),[-filter,filter],[p_line,p_line],'--','Color',color,'LineWidth',linewidth)
                plot(axesName(i),[-filter,-filter],[line_bottom,p_line],'--','Color',color,'LineWidth',linewidth)
                plot(axesName(i),[filter,filter],[line_bottom,p_line],'--','Color',color,'LineWidth',linewidth)
                
                % -----------------------------------------------------------------------------------------------
                % Make a custom data tip
                
                % Sequence in the format of Start-Seq-End: 45-53: KQKEOQKFP
                peptide_names = dataTipTextRow('Peptide',regexprep(strcat(num2str(statsData.Start),'-',num2str(statsData.End),': ',statsData.Sequence), '\s', ''));
                s.DataTipTemplate.DataTipRows(1) = peptide_names;
    
                % Exposure time in (min): 0.25 min
                exposure_names = dataTipTextRow('Exposure',strcat(statsData.Exposure,' min'));
                s.DataTipTemplate.DataTipRows(2) = exposure_names;
                
                % Uptake difference
                uptakes = dataTipTextRow('DU',strcat(string(statsData.BH_pValue),' Da'));
                s.DataTipTemplate.DataTipRows(3) = uptakes;

                if strcmp(plotOptions.Statistics,'Hybrid')
                    pValues = dataTipTextRow('Welch P-value',statsData.Welch_pValue);
                elseif strcmp(plotOptions.Statistics,'Peptide')
                    pValues = dataTipTextRow('B-H adjusted P-value',statsData.BH_pValue);
                end
                
                s.DataTipTemplate.DataTipRows(4) = pValues;
                
                % ΔMass (Da): XXX (B:X A:X)
                repeatsA = {};
                repeatsB = {};
                for j=1:height(statsData)
                    tempRepsA = arrayfun( @(x) sprintf('%.2f',x), statsData.Uptake_reps_A{j}, 'UniformOutput', false);
                    tempRepsB = arrayfun( @(x) sprintf('%.2f',x), statsData.Uptake_reps_B{j}, 'UniformOutput', false);
                    
                    repeatsA{end+1,1} = sprintf("%s Da",strjoin(tempRepsA,", "));
                    repeatsB{end+1,1} = sprintf("%s Da",strjoin(tempRepsB,", "));
                end
                
                s.DataTipTemplate.DataTipRows(5) = dataTipTextRow(sprintf('Reps_{%s}',app.StateADropDown.Value),repeatsA);
                s.DataTipTemplate.DataTipRows(6) = dataTipTextRow(sprintf('Reps_{%s}',app.StateBDropDown.Value),repeatsB);
                
                % -----------------------------------------------------------------------------------------------
                
                % Add legend bar across the bottom and count how many in each category
                ps_bot = polyshape([-maxX maxX maxX -maxX],...
                                   [bottomY bottomY bottomY+barWidth bottomY+barWidth]);
                           
                plot(axesName(i),ps_bot,'FaceAlpha',0.7,'LineStyle','none','FaceColor',[1 1 1]);
                
                % Define x-y and width of the boxes
                text_x_spacer = maxX*0.05;
                text_x = [-maxX+text_x_spacer maxX-text_x_spacer];
                text_y = bottomY+(barWidth/2);
                
                box_width = (text_x(2)-text_x(1))/40;

                % Loop and plot the boxes
                for w = 0:2
                    offset = ((text_x(2)-text_x(1))*0.33*w);
                    
                    box = polyshape([text_x(1) text_x(1)+box_width text_x(1)+box_width text_x(1)]+offset,...
                                    [text_y-(barWidth/4) text_y-(barWidth/4) text_y+(barWidth/4) text_y+(barWidth/4)]);
                                
                    % Also count and display the number of peptides in each category
                    x_text = text_x(1)+(box_width*2)+offset;

                    if w == 0
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsRed);
                        text(axesName(i),x_text,text_y,numbers{1},'FontSize',app.fs_figureText)
                    elseif w == 2
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsBlue);
                        text(axesName(i),x_text,text_y,numbers{3},'FontSize',app.fs_figureText)
                    else
                        plot(axesName(i),box,'FaceAlpha',1,'LineStyle','none','FaceColor',app.cWoodsGrey);
                        text(axesName(i),x_text,text_y,numbers{2},'FontSize',app.fs_figureText)
                    end
                end
            end
        end
        
        function ax = initialiseKineticsPlot(app,axesName,optionTable)
            delete(allchild(axesName)) % reset the axis first
            ax = axes(axesName);
            
            set(ax,'Position',app.kineticsPlotPosition)
            
            hold(ax,'on')
            
            % X axis label
            if strcmp(optionTable.logTime,'On')
                xlabel(ax, 'Time log(min)','FontSize',app.fs_axisLabels)
            else
                xlabel(ax, 'Time (min)','FontSize',app.fs_axisLabels)
            end
            
            % Y axis label
            ylabel(ax, 'Deuterium uptake (Da)','FontSize',app.fs_axisLabels)

            % Formatting options

            set(ax,'color',[229/255, 229/255, 229/255],'GridColor',[1,1,1],'GridAlpha',1,'TickDir','out','FontSize',app.fs_axisTickLabels);
            grid(ax,'on')
            box(ax,'off')
    
            ax.XRuler.Axle.LineStyle = 'none';
            ax.YRuler.Axle.LineStyle = 'none';
            ax.YAxis.TickLabelFormat = '%.1f';
        end
        
        function plotKinetics(app,ax,selectedPeptideData,numberOfStates,optionTable)
            % Get stateA and state B data depending on what's available
            stateAName = app.StateADropDown.Value;
            stateAData = selectedPeptideData(strcmp(selectedPeptideData.State,stateAName),:);
            stateALegend = sprintf('%s (MHP: %.02f Da / Z: %d+)',stateAName,nanmax(stateAData.MHP),nanmax(stateAData.z));
            
            % Check if stateB data is available
            stateBName = app.StateBDropDown.Value;
            stateBData = selectedPeptideData(strcmp(selectedPeptideData.State,stateBName),:);
            stateBLegend = sprintf('%s (MHP: %.02f Da / Z: %d+)',stateBName,nanmax(stateBData.MHP),nanmax(stateBData.z));
            
            % Make empty statsTable for bug fixing
            statsTable = 0;
            
            if ~strcmp(stateBName,'None') & (~isempty(stateBData) & ~isempty(stateAData))
                % If stateB's name is not None and the data is not empty, plot both
                sequenceLable = sprintf('%d-%d: %s (%s)', stateAData.Start(1),stateAData.End(1),stateAData.Sequence{1},stateAData.Correction{1});

                % Plot two hidden points for legend
                plot(ax,NaN,NaN,'-','Color',app.cBlue,'LineWidth',optionTable.LineWidth);
                plot(ax,NaN,NaN,'-','Color',app.cPink,'LineWidth',optionTable.LineWidth);
                
                % If the per-peptide filter is turned on, then perform per-peptide linear regression fitting
                if strcmp(optionTable.Filter,'Peptide')
                    alpha = str2double(optionTable.alpha);
                    
                    % Fit each uptake curve with linear regression and get coefficients
                    lmCoefficients = app.peptideFitting(stateAData,stateBData,alpha);
                    lmCoefficients.Sequence = sequenceLable;
                    lmCoefficients.stateAName = stateAName;
                    lmCoefficients.stateBName = stateBName;

                    app.kinetics(ax,stateAData,optionTable,app.cBlue,lmCoefficients.stateA,numberOfStates); % Plot kinetics of state A
                    app.kinetics(ax,stateBData,optionTable,app.cPink,lmCoefficients.stateB,numberOfStates); % Plot kinetics of state B

                    % Check if the stats Log GUI is open or not
                    if evalin('base','exist(''logUI_handle'')')
                        % If already open, then update values directly via handles
                        logUI_handle = evalin('base', 'logUI_handle');
                    else % if not then open first and store handle in base as opened
                        logUI_handle = stats();
                        assignin('base','logUI_handle',logUI_handle);
                    end

                    app.statsToLog(logUI_handle,lmCoefficients);
                    
                elseif strcmp(optionTable.Filter,'Global')
                    % Grab the SEM table from import and pass into kinetics ploting function
                    statsTable = app.statistics.Hybrid;

                    app.kinetics(ax,stateAData,optionTable,app.cBlue,statsTable,numberOfStates); % Plot kinetics of state A
                    app.kinetics(ax,stateBData,optionTable,app.cPink,statsTable,numberOfStates); % Plot kinetics of state B
                end

                % Add appropriate Legend
                leg = legend(ax,stateALegend,stateBLegend,'Location','NorthWest','FontSize',app.fs_legend,'Interpreter','none','EdgeColor','white');
            
            elseif ~isempty(stateAData) & isempty(stateBData)
                % Just plot state A's 
                
                stateAName = app.StateADropDown.Value;
                stateAData = selectedPeptideData(strcmp(selectedPeptideData.State,stateAName),:);

                stateALegend = sprintf('%s (MHP: %.02f Da / Z: %d+)',stateAName,nanmax(stateAData.MHP),nanmax(stateAData.z));
                sequenceLable = sprintf('%d-%d: %s (%s)', stateAData.Start(1),stateAData.End(1),stateAData.Sequence{1},stateAData.Correction{1});
                
                % Plot kinetics of state A
                plot(ax,NaN,NaN,'-','Color',app.cBlue,'LineWidth',optionTable.LineWidth);
                app.kinetics(ax,stateAData,optionTable,app.cBlue,statsTable,numberOfStates);
                
                % Add appropriate Legend
                leg = legend(ax,stateALegend,'Location','NorthWest','FontSize',app.fs_legend,'Interpreter','none','EdgeColor','white');
                    
            elseif ~isempty(stateBData) & isempty(stateAData)
                % Just plot state B's 
                
                stateBName = app.StateBDropDown.Value;
                stateBData = selectedPeptideData(strcmp(selectedPeptideData.State,stateBName),:);

                stateBLegend = sprintf('%s (MHP: %.02f Da / Z: %d+)',stateBName,nanmax(stateBData.MHP),nanmax(stateBData.z));
                sequenceLable = sprintf('%d-%d: %s (%s)', stateBData.Start(1),stateBData.End(1),stateBData.Sequence{1},stateBData.Correction{1});
                
                % Plot kinetics of state B
                plot(ax,NaN,NaN,'-','Color',app.cPink,'LineWidth',optionTable.LineWidth);

                app.kinetics(ax,stateBData,optionTable,app.cPink,statsTable,numberOfStates);
                
                % Add appropriate Legend
                leg = legend(ax,stateBLegend,'Location','NorthWest','FontSize',app.fs_legend,'Interpreter','none','EdgeColor','white');
            end

            % Final legend formatting
            title(leg,sequenceLable,'FontSize',app.fs_figureTitle)
        end
        
        function plotHandle = kinetics(app,ax,data,options,color,statsTable,numberOfStates)
            % Get y data depending on absolute or relative uptake
            y_data = data.Uptake_ave;
            y_err = data.Uptake_SD;
            dt_label = 'Absolute Uptake (Da)';

            % Get x data depending on log or not
            x_data = data.Exposure;
            
            if strcmp(options.logTime,'On'); set(ax, 'XScale', 'log'); end
            values = table(x_data,y_data,y_err,'VariableNames',{'X','Y','SD'});

            % All filtering controls go here
            if numberOfStates == 2
                if strcmp(options.Filter,'Global')
                    alpha = str2double(options.alpha);
                    
                    statsText = {'Global ΔHDX significance threshold (from Hageman & Weis, 2019)';
                                 sprintf('α: %.3f / confidence: %.1f%%\t (two-tailed)',alpha,(1-alpha)*100); ''};
                             
                    % Calculate global significance threshold from Hageman & Weis, 2019 Anal chem
                    SEM = statsTable.SEM(strcmp(statsTable.Type,'Non-sum'),:);
                    df = statsTable.df(strcmp(statsTable.Type,'Non-sum'),:);
                    k = tinv(1-(alpha/2),df);
                    CI = k*SEM; % global significance threshold
                    
                    % Use the threshold to calculate the upper and lower Y values 
                    CI_limits = table(values.Y-CI,values.Y+CI,'VariableNames',{'y_upper','y_lower'});
                    values = [values CI_limits]; % Adds y_upper and y_lower to the plotting data
    
                    statsText{end+1} = sprintf('Global SEM: %.3f\tdegrees of freedom: %d\tcritical value:%.3f',SEM,df,k);
                    statsText{end+1} = sprintf('CI: 0±%.3f Da',CI);
                    statsText{end+1} = '';
    
                    app.PeptidesStatisticsTextArea.Value = statsText; % print the stats summary text to the log window
                elseif strcmp(options.Filter,'Peptide')
                    % new method of CI using predict
                    statsTable.Properties.VariableNames{'CI_upper_pred'} = 'y_upper';
                    statsTable.Properties.VariableNames{'CI_lower_pred'} = 'y_lower';
                    
                    values = [values statsTable(:,{'y_upper','y_lower'})];
                end
            end
            
            % Marker options
            Markersize = options.Markersize;
            LineWidth = options.LineWidth;
            CapSize = options.CapSize;
            
            % Plot line if on
            if strcmp(options.Line,'On')
                plot(ax,values.X,values.Y,'-','Color',color,'LineWidth',LineWidth)
            end
            
            % Plot datapoints if on
            if strcmp(options.Datapoints,'On')
                MarkerFaceColor = color;
                
                plotHandle = scatter(ax,values.X,values.Y,Markersize,MarkerFaceColor,'filled');
            
                plotHandle.DataTipTemplate.DataTipRows(1).Label = 'Exposure (min)';
                plotHandle.DataTipTemplate.DataTipRows(2).Label = dt_label;
                
                SD = dataTipTextRow('Standard Deviation',values.SD);
                plotHandle.DataTipTemplate.DataTipRows(end+1) = SD;
            end

            % Plot errorbars if on
            if strcmp(options.ShowSD,'On')
                Color = color;
                
                errorbar(ax,values.X,values.Y,values.SD,'-','MarkerEdgeColor','none',...
                'Color',Color,'LineWidth',LineWidth,'CapSize',CapSize,'LineStyle','none');
            end
            
            % Confidence intervals
            if numberOfStates == 2
                if strcmp(options.Confidencelimits,'On')
                    plot(ax,values.X,values.y_upper,'--','Color',color,'LineWidth',LineWidth/2)
                    plot(ax,values.X,values.y_lower,'--','Color',color,'LineWidth',LineWidth/2)
                end
            end
            
            % Set the default axis to show the full extent of deuteration
            axis(ax,[0 nanmax(values.X) 0 nanmax(data.MaxUptake)])
        end
        
        function lmCoefficients = peptideFitting(app,stateAData,stateBData,alpha)
            % Given cluster uptake data for two states, performs linear regression fitting
            % to determine statistical significance
            
            % First convert state data into suitable table form
            clusterA = app.state2Cluster(stateAData);
            clusterB = app.state2Cluster(stateBData);
            
            % Perform comparison in direction of A to B
            df1 = [clusterA; clusterB];
            listOfTimepoints = unique(df1.Exposure);
            
            % Perform linear regression to data
            lm = fitlm(df1,'Uptake ~ 1 + State * Exposure','ResponseVar','Uptake',...
                'PredictorVars',{'State','Exposure'},...
                'CategoricalVar',{'State','Exposure'});
            
            coefficients = lm.Coefficients;
            
            % New confidence interval calculations
            [~, ci_A] = predict(lm,clusterA,'alpha',alpha);
            [~, ci_B] = predict(lm,clusterB,'alpha',alpha);
            
            % Must include rounding here because otherwise MATLAB has weird bug where it
            % thinks two of the same values are not the same and you get indexing problems
            ci_A = unique(round(ci_A,5),'Rows');
            ci_B = unique(round(ci_B,5),'Rows');

            % Append the new CI onto stateA and B
            [stateA, stateB] = app.coefficientsToTable(coefficients,listOfTimepoints,unique(clusterB.State));
            
            stateA = [stateA table(ci_A(:,1),ci_A(:,2),'VariableNames',{'CI_lower_pred','CI_upper_pred'})];
            stateB = [stateB table(ci_B(:,1),ci_B(:,2),'VariableNames',{'CI_lower_pred','CI_upper_pred'})];

            % Store everything
            lmCoefficients.stateA = stateA;
            lmCoefficients.stateB = stateB;
            lmCoefficients.lm = lm;
        end
        
        function clusterTable = state2Cluster(~,stateData)
            % New table variable names
            variableNames = {'Protein','Start','End','Sequence','Modification',...
                            'Fragment','MaxUptake','MHP','State','Exposure','Charge','N','Uptake','Correction'};
            
            % Make empty table to store expanded repeat values
            clusterTable = cell2table(cell(0,length(variableNames)),'VariableNames',variableNames);

            % Loop over each timepoint and each repeat block
            for i = 1:height(stateData)
                timepoint = stateData(i,:);
                repeats = cell2mat(timepoint.Uptake_Reps);
                
                for j = 1:length(repeats)
                     clusterRow = table(timepoint.Protein,...
                                         timepoint.Start,...
                                         timepoint.End,...
                                         timepoint.Sequence,...
                                         timepoint.Modification,...
                                         timepoint.Fragment,...
                                         timepoint.MaxUptake,...
                                         timepoint.MHP,...
                                         timepoint.State,...
                                         timepoint.Exposure,...
                                         timepoint.z,...
                                         timepoint.N,... 
                                         repeats(j),... % this is the important one to expand
                                         timepoint.Correction,...
                                         'VariableNames',variableNames);
                                     
                     clusterTable = [clusterTable; clusterRow];
                end
            end
        end
        
        function [stateA, stateB] = coefficientsToTable(~,coefficients,listOfTimepoints,nameOfstateB)
            % Get Apo data
            stateAFitted = [];
            
            % Add zero timepoint first
            intercept_estimate = coefficients{{'(Intercept)'},{'Estimate'}};
            stateAFitted = [stateAFitted; listOfTimepoints(1), intercept_estimate];
            
            for i = 2:length(listOfTimepoints)
               exposure = listOfTimepoints(i);
               exposure_estimate = coefficients{{sprintf('Exposure_%s',num2str(exposure))},{'Estimate'}};
               
               stateAFitted = [stateAFitted; exposure, intercept_estimate + exposure_estimate];
            end
            
            % Get Holo data
            stateBFitted = [];
            
            % Add zero timepoint first
            stateName = sprintf('State_%s',nameOfstateB{:});
            stateB_estimate = coefficients{{stateName},{'Estimate'}};
            stateBFitted = [stateBFitted; listOfTimepoints(1), intercept_estimate + stateB_estimate];
            
            for i = 2:length(listOfTimepoints)
               exposure = listOfTimepoints(i);
               
               stateA_exposure_estimate = coefficients{{sprintf('Exposure_%s',num2str(exposure))},{'Estimate'}};
               stateB_exposure_estimate = coefficients{{sprintf('%s:Exposure_%s',stateName,num2str(exposure))},{'Estimate'}};
               
               stateBFitted = [stateBFitted; exposure, intercept_estimate + stateB_estimate + stateA_exposure_estimate + stateB_exposure_estimate];
            end
            
            stateA  = table(stateAFitted(:,1),stateAFitted(:,2),'VariableNames',{'Exposure','mean_uptake'});
            stateB  = table(stateBFitted(:,1),stateBFitted(:,2),'VariableNames',{'Exposure','mean_uptake'});
        end
        
        function statsToLog(app,logUI_handle,lmCoefficients)
            % Displays the lmCoefficients information in the separate statsLog GUI
            
            logUI_handle.SequenceTextLabel.Text = sprintf('Per-peptide significance testing for %s', lmCoefficients.Sequence);
            
            stateAName = lmCoefficients.stateAName;
            stateBName = lmCoefficients.stateBName;
            
            % Get the AB fitting, assign the significance category and then set to table
            [dataTable, compTable] = app.stats2Table(lmCoefficients.lm.Coefficients);
            logUI_handle.TopTextLabel.Text = sprintf('Kinetics fitting for %s', stateAName);
            logUI_handle.TopUITable.Data = dataTable;

            % Set the comparison table
            logUI_handle.ComparisonTextLabel.Text = sprintf('Comparison of %s and %s', stateAName, stateBName);
            logUI_handle.ComparisonUITable.Data = compTable;
        end
        
        function [tempTable, compTable] = stats2Table(app,coefficientsTable)
            % Converts the standard coefficients table into one that is the correct format
            % for the statsLog UiTable
            
            % Changes the rownames of the coefficients table to a column instead
            dataTable = app.addPredictors(coefficientsTable);
            
            % Get all rows that contain the string 'Exposure_' but not ':'
            tempTable = app.getExposureOnly(dataTable);
            compTable = app.getComparisonOnly(dataTable);

            % Add the significance category to the table
            tempTable = app.addSignificance(tempTable);
            compTable = app.addSignificance(compTable);
            
            % Change the tempTable predictors 
            tempTable = app.modifyPredictors(tempTable);
            compTable = app.modifyPredictors2(compTable);
        end
        
        function dataTable = addPredictors(~,coefficientsTable)
            % Get row names first
            PredictorNames = coefficientsTable.Properties.RowNames;
            Predictors = table(PredictorNames,'VariableName',{'Predictors'});

            dataTable = [Predictors coefficientsTable(:,{'Estimate','SE','tStat','pValue'})];
        end
        
        function dataTable = getExposureOnly(~,dataTable)
            % Get all rows that contain the string 'Exposure_' but not ':'
            cond1 = find(~cellfun(@isempty,strfind(dataTable.Predictors,'Exposure_')));
            cond2 = find(~cellfun(@isempty,strfind(dataTable.Predictors,':Exposure_')));
            uniques = setdiff(cond1,cond2);
            
            % This returns only the 'Exposure_0.25', 'Exposure_5' etc time rows
            dataTable = dataTable(uniques,:);
        end
        
        function dataTable = getComparisonOnly(~,dataTable)
            % This returns only the 'State:Exposure_0.25' rows
            dataTable = dataTable(find(~cellfun(@isempty,strfind(dataTable.Predictors,':Exposure_'))),:);
        end
        
        function dataTable = addSignificance(~,tempTable)
            % Loop over the table and add significance category
            significanceTable = {};
            pValTable = {};
            
            for i = 1:height(tempTable)
                % add '***' according to how significant the p-value is
                if tempTable.pValue(i) < 0.001 % 99.9% significance / ****
                    significance = '**** (≥ 99.9%)';
                elseif tempTable.pValue(i) < 0.01 % 99% significance / ***
                    significance = '*** (≥ 99.0%)';
                elseif tempTable.pValue(i) < 0.02 % 98% significance / **
                    significance = '** (≥ 98%)';
                elseif tempTable.pValue(i) < 0.05 % 95% significance / *
                    significance = '* (≥ 95%)';
                else
                    significance = ' ';
                end
                
                significanceTable{end+1,1} = significance;
                pValTable{end+1,1} = sprintf('%.2d',tempTable.pValue(i));
            end
            
            % Convert significance '***' into table
            significanceTable = table(significanceTable,'Variablenames',{'Significance'});
            pValTable = table(pValTable,'Variablenames',{'pValue'});
            
            % Combine with the original stats table
            dataTable = [tempTable(:,{'Predictors','Estimate','SE','tStat'}) pValTable significanceTable];
        end
        
        function tempTable = modifyPredictors(~,tempTable)
            newPredictorTable = {};
            for i = 1:height(tempTable)
                newPredictor = split(tempTable.Predictors(i),'_');
                newPredictorTable{end+1,1} = sprintf('0 min to %s min',newPredictor{2});
            end
            
            newPredictorTable = table(newPredictorTable,'VariableNames',{'Comparison'});
            
            tempTable = [newPredictorTable tempTable(:,{'Estimate','SE','tStat','pValue','Significance'})];
        end
        
        function compTable = modifyPredictors2(~,dataTable)
            % Loop over each row, get the exposure values and make new name
            newPredictors = {};
            for i = 1:height(dataTable)
                newName = split(dataTable.Predictors(i),':'); % 'State_AcrB_WT_Cip:Exposure_30' to 'State_AcrB_WT_Cip' and 'Exposure_30'
                newName = split(newName{2},'_'); % 'Exposure_30' to 'Exposure' and '30' <-- we want 30
                
                exposure = newName{2};
                predictor = sprintf('%s min of both states',exposure);
                
                newPredictors{end+1,1} = predictor;
            end
            
            newPredictors = table(newPredictors,'VariableNames',{'Predictors'});
            compTable = [newPredictors dataTable(:,{'Estimate','SE','tStat','pValue','Significance'})];
        end
        
        function BHTable = FDRcorrection(~,dataTable)
            % Applies the Benjamini and Hochberg false-discovery rate correction procedure
            % for P-values calculated from peptide signifiacnce test
            
            % Get non sum and non reference data 
            nonSum_nonZero_dataTable = dataTable(~strcmp(dataTable.Exposure,'Sum') & ~strcmp(dataTable.Exposure,'0'),:);
            sum_zero_dataTable = dataTable(strcmp(dataTable.Exposure,'Sum') | strcmp(dataTable.Exposure,'0'),:);
            
            % Do FDR correction per timepoint
            uniqueTimepoints = unique(nonSum_nonZero_dataTable.Exposure,'Stable');
            
            % Loop over each timepoint, get the data and then FDR correct
            timepoint_rows = cell2table(cell(0,length(nonSum_nonZero_dataTable.Properties.VariableNames)+3), ...
                                        'VariableNames',horzcat(nonSum_nonZero_dataTable.Properties.VariableNames,'FDR_Rank','BH_pValue','BH_sig'));
            for t = 1:length(uniqueTimepoints)
                timepoint = uniqueTimepoints(t);
                timepointData = sortrows(nonSum_nonZero_dataTable(strcmp(nonSum_nonZero_dataTable.Exposure,timepoint),:),'Peptide_pValue','ascend');
                timepointData.FDR_Rank(:) = 1:1:height(timepointData);
                
                % Calcualte B-H adjusted p-value according to p-value' = p-value*(num_comparisons/FDR rank)
                timepointData.BH_pValue = timepointData.Peptide_pValue.*(height(timepointData)./timepointData.FDR_Rank);
                
                % Create blank signifiance table column for later
                timepointData.BH_sig(:) = 0;
                
                % append to new table
                timepoint_rows = [timepoint_rows; timepointData];
            end
            
            % Also for sum and zero rows, set everything else to NaN
            sum_zero_dataTable.FDR_Rank(:) = NaN;
            sum_zero_dataTable.BH_pValue(:) = NaN;
            sum_zero_dataTable.BH_sig(:) = NaN;
            
            % Append to new table and sort by residue numbers
            BHTable = sortrows([timepoint_rows; sum_zero_dataTable],{'Start','End'});
        end
        
        function coverageTxtBlock = coverage2MG(app,state,colorCoverage,colorNoCoverage,chainStr,StateName,MG)
            % Formats coverage data for both states into PyMOL compatible text block

            % This contains the text
            coverageTxtBlock = {};
            coverageTxtBlock{end+1,1} = ' ';
            
            coverageTxtBlock{end+1,1} = sprintf('# Peptide coverage for %s, %s',app.ProteinDropDown.Value, StateName); % just title comment
            
            if strcmp(MG,'PyMOL')
                % Get colours first
                colors = readtable('colors.txt'); % import table of colours from colors.txt external file
    
                findNoCol = colors(strcmp(colors.Text,colorNoCoverage),:);
                
                if ~isempty(findNoCol)
                    colorNoCoverage = char(findNoCol.Color);
                elseif strcmp(colorNoCoverage,' ') || isempty(findNoCol)
                    errordlg('Select a colour for areas with no coverage')
                    return
                end
                
                findCol = colors(strcmp(colors.Text,colorCoverage),:);
                
                if ~isempty(findCol)
                    colorCoverage = char(findCol.Color);
                elseif strcmp(colorCoverage,' ') || isempty(findCol)
                    errordlg('Select a colour for areas with coverage')
                    return
                end
                
                coverageTxtBlock{end+1,1} = ' ';
                coverageTxtBlock{end+1,1} = sprintf('hide everything, not %s;', chainStr);
                coverageTxtBlock{end+1,1} = ' ';
                
                coverageTxtBlock{end+1,1} = sprintf('color %s, %s;',colorNoCoverage,chainStr); % apply base coat

                for i = 1:length(state)
                    coverageTxtBlock{end+1,1} = sprintf('color %s, resi %d & %s;',colorCoverage,state(i),chainStr); % colour individually each residue
                end
    
                coverageTxtBlock{end+1,1} = sprintf('scene Coverage: %s, store, view=0;',StateName); % Add scene
            else
                coverageTxtBlock{end+1,1} = sprintf('runCommand("~ribbon ~ :%s")', strjoin(chainStr,','));
                coverageTxtBlock{end+1,1} = ' ';
                coverageTxtBlock{end+1,1} = sprintf('runCommand("color %s :%s")', colorNoCoverage, strjoin(chainStr,','));
                
                for i = 1:length(state)
                    coverageTxtBlock{end+1,1} = sprintf('runCommand("color %s :%s")', colorCoverage, strjoin(strcat(sprintf('%d',state(i)),chainStr),','));
                end
            end
        end
    
        function redundancyTxtBlock = redundancy2MG(app,calculatedColours,colorNoCoverage,chainStr,StateName,label,MG)
            % Formats redundancy data for both states into PyMOL compatible text block
            
            if strcmp(MG,'PyMOL')
                colors = readtable('colors.txt');
    
                findNoCol = colors(strcmp(colors.Text,colorNoCoverage),:);
                
                if ~isempty(findNoCol)
                    colorNoCoverage = char(findNoCol.Color);
                elseif strcmp(colorNoCoverage,' ') || isempty(findNoCol)
                    errdlg('Select a colour for areas with no coverage')
                end
                
                % This contains the text
                redundancyTxtBlock = {};
    
                redundancyTxtBlock{end+1,1} = ' ';
                redundancyTxtBlock{end+1,1} = sprintf('# Peptide redundancy for %s, %s',app.ProteinDropDown.Value, StateName); % just title comment
                redundancyTxtBlock{end+1,1} = ' ';
                redundancyTxtBlock{end+1,1} = sprintf('hide everything, not %s;', chainStr);
                redundancyTxtBlock{end+1,1} = ' ';
                redundancyTxtBlock{end+1,1} = sprintf('color %s, %s;',colorNoCoverage,chainStr); % apply base coat
                
                setColorBlock = {};
                for i = 1:length(calculatedColours)
                    residue = calculatedColours(i,1);
                    color = sprintf('[%.3f, %.3f, %.3f]',calculatedColours(i,2),calculatedColours(i,3),calculatedColours(i,4));
                    
                    setColorBlock{end+1,1} = sprintf('set_color %s_Color_%d, %s;',label,residue,color);
                end
                
                colorBlock = {};
                for i = 1:length(calculatedColours)
                    residue = calculatedColours(i,1);
    
                    colorBlock{end+1,1} = sprintf('color %s_Color_%d, resi %d & %s;',label,residue,residue,chainStr);
                end
                
                sceneText = sprintf('scene Redundancy: %s, store, view=0;',StateName); % Add scene
                redundancyTxtBlock = [redundancyTxtBlock; setColorBlock; ' '; colorBlock; sceneText];
            else
                % This contains the text
                redundancyTxtBlock = {};
    
                redundancyTxtBlock{end+1,1} = ' ';
                redundancyTxtBlock{end+1,1} = sprintf('# Peptide redundancy for %s, %s',app.ProteinDropDown.Value, StateName); % just title comment
                redundancyTxtBlock{end+1,1} = sprintf('runCommand("~ribbon ~ :%s")', strjoin(chainStr,','));
                redundancyTxtBlock{end+1,1} = ' ';
                redundancyTxtBlock{end+1,1} = sprintf('runCommand("color %s :%s")', colorNoCoverage, strjoin(chainStr,','));
                
                for i = 1:length(calculatedColours)
                    residue = calculatedColours(i,1);
                    color = app.rgb2hex(ceil([calculatedColours(i,2),calculatedColours(i,3),calculatedColours(i,4)]*255));
                    
                    redundancyTxtBlock{end+1,1} = sprintf('runCommand("color %s :%s")',color,strjoin(strcat(sprintf('%d',residue),chainStr),','));
                end
            end
        end
        
        function sortedTable = sortTable(~,unsortedTable,timepointOrder,sortOrder)
            % Change exposure to categorical and order by start, end and exposure
            if isstring(unsortedTable.Exposure)
                unsortedTable.Exposure = reordercats(categorical(cellstr(unsortedTable.Exposure)),timepointOrder);
                
                sortedTable = sortrows(unsortedTable,sortOrder);
                
                % Convert back to non-categorical array
                sortedTable.Exposure = cellstr(sortedTable.Exposure);
            else
                sortedTable = sortrows(unsortedTable,sortOrder);
            end
        end
        
        function gradient = array2rgb(app,array,gradations,startColor,endColor)
            customGrad = [linspace(startColor(1),endColor(1),gradations)', linspace(startColor(2),endColor(2),gradations)', linspace(startColor(3),endColor(3),gradations)'];
            
            gradient = [];
            for w = 1:length(array)
                row = array(w);
                
                if ~isnan(row)
                    color = customGrad(ceil(row*10),:);
                else
                    color = app.cWoodsGrey;
                end

                gradient = [gradient; color];
            end
        end
        
        function flattenedData = flatten(app,dataTable)
            % Takes single state data table and flattens to residue level for custom column
            
            startResidue = app.startEndResidues(1);
            endResidue = app.startEndResidues(2);
            
            ini = zeros(height(dataTable),endResidue-startResidue+1);
            
            for i = 1:height(dataTable)
                row = dataTable(i,:);
                ini(i,row.Start:row.End) = row.Uptake_ave;
            end
            
            % Replace o with Nan then mean
            ini(ini == 0) = NaN;
            ini_mean = nanmean(ini);
            
            flattenedData = [startResidue:endResidue; ini_mean]';
        end
        
        function [ hex ] = rgb2hex(~,rgb)
            % rgb2hex converts rgb color values to hex color format. 
            % 
            % This function assumes rgb values are in [r g b] format on the 0 to 1
            % scale.  If, however, any value r, g, or b exceed 1, the function assumes
            % [r g b] are scaled between 0 and 255. 
            % 
            % * * * * * * * * * * * * * * * * * * * * 
            % SYNTAX:
            % hex = rgb2hex(rgb) returns the hexadecimal color value of the n x 3 rgb
            %                    values. rgb can be an array. 
            % 
            % * * * * * * * * * * * * * * * * * * * * 
            % EXAMPLES: 
            % 
            % myhexvalue = rgb2hex([0 1 0])
            %    = #00FF00
            % 
            % myhexvalue = rgb2hex([0 255 0])
            %    = #00FF00
            % 
            % myrgbvalues = [.2 .3 .4;
            %                .5 .6 .7; 
            %                .8 .6 .2;
            %                .2 .2 .9];
            % myhexvalues = rgb2hex(myrgbvalues) 
            %    = #334D66
            %      #8099B3
            %      #CC9933
            %      #3333E6
            % 
            % * * * * * * * * * * * * * * * * * * * * 
            % Chad A. Greene, April 2014
            % 
            % Updated August 2014: Functionality remains exactly the same, but it's a
            % little more efficient and more robust. Thanks to Stephen Cobeldick for
            % his suggestions. 
            % 
            % * * * * * * * * * * * * * * * * * * * * 
            % See also hex2rgb, dec2hex, hex2num, and ColorSpec. 
            
            %% Check inputs: 
            
%             assert(nargin==1,'This function requires an RGB input.') 
            assert(isnumeric(rgb)==1,'Function input must be numeric.') 
            
            sizergb = size(rgb); 
            assert(sizergb(2)==3,'rgb value must have three components in the form [r g b].')
            assert(max(rgb(:))<=255& min(rgb(:))>=0,'rgb values must be on a scale of 0 to 1 or 0 to 255')
            
            %% If no value in RGB exceeds unity, scale from 0 to 255: 
            if max(rgb(:))<=1
                rgb = round(rgb*255); 
            else
                rgb = round(rgb); 
            end
            
            %% Convert (Thanks to Stephen Cobeldick for this clever, efficient solution):
            hex(:,2:7) = reshape(sprintf('%02X',rgb.'),6,[]).'; 
            hex(:,1) = '#';
        end
        
    end % methods function end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        if ispc
            % System FontSize Control
            % Windows:
            app.fs_axisLabels = 10;
            app.fs_axisTickLabels = 10;
            app.fs_legend = 10;
            app.fs_figureTitle = 12;
            app.fs_figureText = 10;
            
            app.volcanoMarkerSize = 40;
            app.kineticsPlotPosition = [0.08,0.095,0.9,0.85];
        elseif ismac
            % Mac
            app.fs_axisLabels = 12;
            app.fs_axisTickLabels = 12;
            app.fs_legend = 12;
            app.fs_figureTitle = 12;
            app.fs_figureText = 12;
            
            app.volcanoMarkerSize = 65;
            app.kineticsPlotPosition = [0.08,0.085,0.9,0.85];
        end
        
        % Define colour gradients
        app.blue_white_red = [linspace(app.cWoodsBlue(1),1,100)', linspace(app.cWoodsBlue(2),1,100)', linspace(app.cWoodsBlue(3),1,100)';
                              linspace(1,app.cWoodsRed(1),100)', linspace(1,app.cWoodsRed(2),100)', linspace(1,app.cWoodsRed(3),100)'];
                      
        app.white_red = [linspace(1,app.cWoodsRed(1),100)', linspace(1,app.cWoodsRed(2),100)', linspace(1,app.cWoodsRed(3),100)'];
            
        end

        % Button pushed function: ClusterBrowseButton
        function ClusterBrowseButtonPushed(app, event)
            % Cluster file import button
            %   1. Get file and import into variable
            %   2. Check that correct columns are there - format is correct / modification dialogs

            [app.cluster.filename, app.cluster.pathname, app.cluster.fullpathname] = app.readCluster();
            
            app.ClusterFileTextBox.Value = app.cluster.filename; % set the edit textbox to show the filename
            app.cluster.table = readtable(app.cluster.fullpathname); % raw data table
            
            fprintf('\n--------------- File Import ----------------\n\n')
            
            % Cluster file should contain certain columns of data - check that these exist
            expected_headings = {'Protein','Start','End','Sequence','Modification','Fragment','MaxUptake','MHP','State','Exposure','File','z','RT','Inten','Center'};
            table_headings = app.cluster.table.Properties.VariableNames;
            
            if sum(ismember(expected_headings,table_headings)) ~= 15
                errordlg('Cluster file does not contain correct headings')
                return
            end
            
            % Check how many proteins are inside the file, for each protein, get a list of the states belonging to each protein
            app.cluster.listOfProteins = unique(app.cluster.table.Protein,'stable');
            app.cluster.numberOfProteins = length(app.cluster.listOfProteins);
            
            app.cluster.perProteinStates = [];
            for protein = 1:length(app.cluster.listOfProteins) % loop over proteins in the cluster file
                uniqueStates = {unique(app.cluster.table(strcmp(app.cluster.table.Protein,app.cluster.listOfProteins(protein)),:).State)'};
                app.cluster.perProteinStates = [app.cluster.perProteinStates; uniqueStates];
            end

            % Divide table by proteins and states and populate dropdown menus with states found in the file
            app.ProteinDropDown.Items = app.cluster.listOfProteins; % populate the protein dropdown menu with a list of proteins

            fprintf('   %d protein(s) found in the cluster file: %s\n',length(app.cluster.listOfProteins),strjoin(app.cluster.listOfProteins,', '))
            
            app.cluster.currentSelectedProtein = strcmp(app.ProteinDropDown.Value,app.cluster.listOfProteins); % get the current selected protein
            app.cluster.numberOfStates = length(app.cluster.perProteinStates{app.cluster.currentSelectedProtein,:});
            
            % If number of states is somehow less than 1 =  errordlg, otherwise populate
            % dropdown menus with states found
            
            if app.cluster.numberOfStates < 1
                errordlg('Protein contains no states')
                return
            end

            stateList = app.cluster.perProteinStates{app.cluster.currentSelectedProtein,:}; %  contains a list of states belonging to the currently selected protein

            % Set each dropdown to show the stateList
            app.StateADropDown.Items = stateList;
            app.StateADropDown.Value = app.StateADropDown.Items(1);
            
            app.StateBDropDown.Items = horzcat('None',stateList);
            app.StateA_ControlDropDown.Items = horzcat('None',stateList);
            app.StateB_ControlDropDown.Items = horzcat('None',stateList);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Divides the cluster file into StateA and StateB depending on the selected protein and states
            %   1. Get numbers of peptides from each state, display in text box log
            %   2. Check if the same timepoints are available in each state file
            %   3. Find common peptides between both states, get number and update box
            
            fprintf('\n\n\n   8888888b.                    888                                           .d8888b.       .d8888b.  ')
            fprintf('\n   888  "Y88b                   888                                          d88P  Y88b     d88P  Y88b ')
            fprintf('\n   888    888                   888                                                 888     888    888 ')
            fprintf('\n   888    888  .d88b.  888  888 888888 .d88b.  888d888 .d88b.  .d8888b            .d88P     888    888 ')
            fprintf('\n   888    888 d8P  Y8b 888  888 888   d8P  Y8b 888P"  d88""88b 88K            .od888P"      888    888 ')
            fprintf('\n   888    888 88888888 888  888 888   88888888 888    888  888 "Y8888b.      d88P"          888    888 ')
            fprintf('\n   888  .d88P Y8b.     Y88b 888 Y88b. Y8b.     888    Y88..88P      X88      888"       d8b Y88b  d88P ')
            fprintf('\n   8888888P"   "Y8888   "Y88888  "Y888 "Y8888  888     "Y88P"   88888P"      888888888  Y8P  "Y8888P"  \n\n\n')
            
            fprintf('\n   Please reference the following papers if you show or use data from Deuteros:')
            fprintf('\n      Lau, A. M. C., Ahdash, Z., Martens, C., Politis, A. (2019). Deuteros: software for rapid analysis and visualization of data')
            fprintf('\n      from differential hydrogen deuterium exchange-mass spectrometry. Bioinformatics, btz022.\n\n')
            fprintf('\n      Lau, A. M. C., Ahdash, Z., Martens, C., Politis, A. (2019). Deuteros: software for rapid analysis and visualization of data')
            fprintf('\n      from differential hydrogen deuterium exchange-mass spectrometry. Bioinformatics, btz022.\n')
            
            fprintf('\n   Contact: Andy Lau (andy.m.lau@ucl.ac.uk)\n\n')
            
            % Waitbar 
            f = waitbar(0,'Importing data...');

            % Clear uitables
            app.RawCluster_UITable.Data = [];
            app.Removed_UITable.Data = [];
            app.Correction_UITable.Data = [];
            app.StateAState_UITable.Data = [];
            app.StateBState_UITable.Data = [];
            app.StatesABCommon_UITable.Data = [];
            app.StateBStateA_UITable.Data = [];
            app.BXControl_UITable.Data = [];
            app.FilteredData_UITable.Data = [];
            
            % First check that stateA ~= stateB
            if strcmp(app.StateADropDown.Value,app.StateBDropDown.Value)
                errordlg('The same state cannot be selected for both State A and State B')
                close(f);
                return
            end
            
            % Get the residue numbers from the import panel
            if isempty(app.Residue_EndEditBox.Value) || isempty(app.Residue_StartEditBox.Value) % give error if either of the residues boxes are not filled in
                errordlg('Enter number of residues','Number of residues error');
                close(f);
                return
            end
            
            % Produces 4 state-style structures:
            % A, B, AB (both in the same state for multiple plots) and B-A (for differential)
            
            % Remove all rows with modification or fragments as Deuteros 2.0 cannot handle this yet - Checked 02/05/2020
            app.cluster.table = app.cluster.table(isnan(app.cluster.table.Modification) | isnan(app.cluster.table.Fragment),:);
            state = app.cluster.table(strcmp(app.cluster.table.Protein,app.ProteinDropDown.Value),:); % filter to get only the selected protein
                        
            app.RawCluster_UITable.Data = [app.RawCluster_UITable.Data; state]; % append the raw cluster table to the data table tab
            app.RawCluster_UITable.ColumnName = state.Properties.VariableNames';
            app.RawCluster_UITable.ColumnSortable = true;
            
            state.Exposure = round(state.Exposure.*100)./100; % round all exposures to 2dp because DynamX outputs floats funny
            
            % Check if the user's given start:end limits cover the whole protein - Checked 02/05/2020
            app.startEndResidues = [str2double(app.Residue_StartEditBox.Value) str2double(app.Residue_EndEditBox.Value)]; % returns users start end res
            expectedResidues = app.startEndResidues(2)-app.startEndResidues(1)+1; % number of expected residues based on the user's input, i.e. start=1, end=10 = 10 residues
            
            infile_res_limits = [min(state.Start) max(state.End)];
            
            if (app.startEndResidues(1) > infile_res_limits(1)) & (app.startEndResidues(2) < infile_res_limits(2))
                errordlg(sprintf('Peptides covering the region before and after the given start and end residues were found (residues %d:%d were given while residues %d:%d were found). Please check that the given residue limits are correct.',...
                         app.startEndResidues(1),app.startEndResidues(2),infile_res_limits(1),infile_res_limits(2)))
                close(f);
                return
            else
                if app.startEndResidues(2) < infile_res_limits(2)
                    errordlg(sprintf('Peptides covering the region after the given end residue were found (residue %d was given while residue %d was found). Please check that the start is correct.',...
                             app.startEndResidues(2),infile_res_limits(2)))
                    close(f);
                    return
                         
                elseif app.startEndResidues(1) > infile_res_limits(1)
                    errordlg(sprintf('Peptides covering the region before the given start residue were found (residue %d was given while residue %d was found). Please check that the start is correct.',...
                             app.startEndResidues(1),infile_res_limits(1)))
                    close(f);
                    return
                end
            end

            % ------------------------------------------------------------------------------------------
            % State A Section
            % ------------------------------------------------------------------------------------------
            
            fprintf('\n   --------------------------------------------')
            fprintf('\n   |       Processing state A cluster         |')
            fprintf('\n   --------------------------------------------\n')
            
            waitbar(0.15,f,'Processing state A data...');
            
            [app.A.state, app.A.cluster, app.A.peptides] = app.stateSelect(state,app.StateADropDown.Value,app.DeuteriumEditBox.Value);

            % Get the expanded residue list for each state
            app.A.listOfResidues = app.stateToResidues(app.A.state,app.A.peptides);
            app.A.peptideLengths = app.getLengths(app.A.listOfResidues);
            
            % Get linear list of all residues
            app.A.residues = app.getuniqueresidues(app.A.listOfResidues);
            app.A.nonUniqueResidues = app.getnonuniqueresidues(app.A.listOfResidues);
            
            % Get number of timepoints
            app.A.numberOfTimepoints = length(unique(app.A.state.Exposure));
            
            % Log things
            app.A = app.getStateInfo(app.A,expectedResidues); % annotate app.A with things like peptide lengths, shortest and longest peptide, etc.

            % If controls are available, i.e. for back exchange calculation,
            % Default everything to no correction applied, change to 'corrected' if there
            % is data for back exchange correction in a control state
            
            % Make a new column called Correction, with all values as Not Corrected, for state A
            app.A.state.Correction(:) = {'Not corrected'};
            
            errorToReport_stateA = 0;
            errorToReport_stateB = 0;
            
            % If back exchange is required
            if ~strcmp(app.StateA_ControlDropDown.Value,'None')
                
                waitbar(0.2,f,'Performing back-exchange correction for state A...');
                
                [app.A.state, app.A.state_notCorrected, backExchangeText_A_state, backExchangeText_A] = app.BXcorrection(app.A.state,state,app.StateA_ControlDropDown.Value,app.DeuteriumEditBox.Value);
                
                if ~isempty(app.A.state_notCorrected)
                    errorToReport_stateA = 1;
                end
                
                % Remove non-corrected data from table
                app.A.state = app.A.state(~strcmp(app.A.state.Correction,'Not corrected'),:);
                
                % Print data to uitable
                copy.A.state_notCorrected = app.A.state_notCorrected;
                
                printReps = 0;
                if printReps == 0 | app.printReps == 0
                    repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), copy.A.state_notCorrected.Properties.VariableNames);
                    copy.A.state_notCorrected = removevars(copy.A.state_notCorrected,copy.A.state_notCorrected.Properties.VariableNames(repPosition));
                end
                
                printCompact = 0;
                if printCompact == 1 | app.printCompact == 1
                    copy.A.state_notCorrected = removevars(copy.A.state_notCorrected,{'Modification','Fragment','MHP','z','N'});
                end
                
                app.Correction_UITable.Data = [app.Correction_UITable.Data; copy.A.state_notCorrected];
                app.Correction_UITable.ColumnName = copy.A.state_notCorrected.Properties.VariableNames';
                app.Correction_UITable.ColumnSortable = true;
                
                fprintf('\n\n   Back exchange: Back exchange correction selected for state A')
                fprintf('\n   A summary of back exchange control data can be found in the "Data Tables > BX:Control" table')
                fprintf('\n   Mean and IQR values have been added to the data summary box')
            else
                backExchangeText_A = 'N/A'; % otherwise no control printed
                backExchangeText_A_state = 'None';
                errorToReport_stateA = 0;
                
                fprintf('\n\n   Back exchange: No back exchange correction selected for state A')
            end 

            % Add to uitable
            copy.A.state = app.A.state;
                
            printReps = 0;
            if printReps == 0 | app.printReps == 0
                repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), copy.A.state.Properties.VariableNames);
                copy.A.state = removevars(copy.A.state,copy.A.state.Properties.VariableNames(repPosition));
            end
            
            printCompact = 0;
            if printCompact == 1 | app.printCompact == 1
                copy.A.state = removevars(copy.A.state,{'Modification','Fragment','z','N'});
            end
            
            app.StateAState_UITable.Data = [app.StateAState_UITable.Data; copy.A.state];
            app.StateAState_UITable.ColumnName = copy.A.state.Properties.VariableNames';
            app.StateAState_UITable.ColumnSortable = true;

            % ------------------------------------------------------------------------------------------
            % State B Section
            % ------------------------------------------------------------------------------------------
            
            if ~strcmp(app.StateBDropDown.Value,'None')
                
                waitbar(0.3,f,'Processing state B data...');
                
                % Remember how many states were imported
                app.cluster.numberOfImportedStates = 2;

                fprintf('\n\n   --------------------------------------------')
                fprintf('\n   |       Processing state B cluster         |')
                fprintf('\n   --------------------------------------------\n')

                [app.B.state, app.B.cluster, app.B.peptides] = app.stateSelect(state,app.StateBDropDown.Value,app.DeuteriumEditBox.Value);

                % Get the expanded residue list for each state
                app.B.listOfResidues = app.stateToResidues(app.B.state,app.B.peptides);
                app.B.peptideLengths = app.getLengths(app.B.listOfResidues);
                
                % Get linear list of all residues
                app.B.residues = app.getuniqueresidues(app.B.listOfResidues);
                app.B.nonUniqueResidues = app.getnonuniqueresidues(app.B.listOfResidues);
                
                % Log things
                app.B = app.getStateInfo(app.B,expectedResidues); % annotate app.A with things like peptide lengths, shortest and longest peptide, etc.

                % If controls are available, i.e. for back exchange calculation,
                % Default everything to no correction applied, change to 'corrected' if there
                % is data for back exchange correction in a control state
                
                % Make a new column called Correction, with all values as Not Corrected, for state B
                app.B.state.Correction(:) = {'Not corrected'};

                % If back exchange is required
                % Back exchange correction is calculated by:
                % 1. Calculate intensity weighted centroid mass, DU and RFU for all replicates
                %    RFU is calculated as RFU = DU/N*deuterium fraction
                % 2. Use centroid mass, maximally labelled centroid mass (m100) and reference centroid mass (m0) to calculate corrected DU and RFU
                %    Equation according to 1.11 from Masson et al. 2019, Nature Methods, doi:10.1038/s41592-019-0459-y
                % 3. DU_corrected/DU gives the peptide specific BX scaling factor 
                % 4. Apply scaling factor to the replicate non-corrected DU and RFU to get corrected DU and RFU at the replicate-level
                % 5. Change the 'Correction' status in the data table of the peptide to the % back exchange calculated from the control data
                %    Equation used is from 1.10 in Masson et al. 2019, Nature Methods, doi:10.1038/s41592-019-0459-y
                
                if ~strcmp(app.StateB_ControlDropDown.Value,'None')
                    
                    waitbar(0.35,f,'Performing back-exchange correction for state B...');
                    
                    [app.B.state, app.B.state_notCorrected, backExchangeText_B_state, backExchangeText_B] = app.BXcorrection(app.B.state,state,app.StateB_ControlDropDown.Value,app.DeuteriumEditBox.Value);

                    if ~isempty(app.B.state_notCorrected)
                        errorToReport_stateB = 1;
                    end
                    
                    % Remove non-corrected data from table
                    app.B.state = app.B.state(~strcmp(app.B.state.Correction,'Not corrected'),:);
                    
                    % Print data to uitable
                    copy.B.state_notCorrected = app.B.state_notCorrected;
                    
                    printReps = 0;
                    if printReps == 0 | app.printReps == 0
                        repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), copy.B.state_notCorrected.Properties.VariableNames);
                        copy.B.state_notCorrected = removevars(copy.B.state_notCorrected,copy.B.state_notCorrected.Properties.VariableNames(repPosition));
                    end
                    
                    printCompact = 0;
                    if printCompact == 1 | app.printCompact == 1
                        copy.B.state_notCorrected = removevars(copy.B.state_notCorrected,{'Modification','Fragment','MHP','z','N'});
                    end
                    
                    app.Correction_UITable.Data = [app.Correction_UITable.Data; copy.B.state_notCorrected];
                    app.Correction_UITable.ColumnName = copy.B.state_notCorrected.Properties.VariableNames';
                    app.Correction_UITable.ColumnSortable = true;
                    
                    fprintf('\n\n   Back exchange: Back exchange correction selected for state B')
                    fprintf('\n   A summary of back exchange control data can be found in the "Data Tables > BX:Control" table')
                    fprintf('\n   Mean and IQR values have been added to the data summary box')
                else
                    backExchangeText_B = 'N/A';
                    backExchangeText_B_state = 'None';
                    errorToReport_stateB = 0;
                    
                    fprintf('\n\n   Back exchange: No back exchange correction selected for state B')
                end
                
                % Add to uitable
                copy.B.state = app.B.state;
                
                printReps = 0;
                if printReps == 0 | app.printReps == 0
                    repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), copy.B.state.Properties.VariableNames);
                    copy.B.state = removevars(copy.B.state,copy.B.state.Properties.VariableNames(repPosition));
                end
                
                printCompact = 0;
                if printCompact == 1 | app.printCompact == 1
                    copy.B.state = removevars(copy.B.state,{'Modification','Fragment','MHP','z','N'});
                end
                
                app.StateBState_UITable.Data = [app.StateBState_UITable.Data; copy.B.state];
                app.StateBState_UITable.ColumnName = copy.B.state.Properties.VariableNames';
                app.StateBState_UITable.ColumnSortable = true;

                % ------------------------------------------------------------------------------------------
                % Common and Differential
                % ------------------------------------------------------------------------------------------
                
                waitbar(0.4,f,'Calculating common and differential peptides...');
                
                fprintf('\n\n   --------------------------------------------')
                fprintf('\n   |  Processing common/differential peptides |')
                fprintf('\n   --------------------------------------------\n')

                fprintf('\n   Extracting all common peptides found in both states A and B (removed peptides can be found in the "Data Tables > Removed Peptides" table)')
                [app.A, app.B, deletedRows, app.common] = app.getCommon(app.A,app.B,expectedResidues);
                
                if ~isempty(deletedRows)
                    fprintf('\n      %d unique peptide datapoints will be disregarded in any differential analysis (removed peptides can be found in the "Data Tables > Removed Peptides" table)\n', height(deletedRows))
                    
                    % Append reason for removal
                    deletedRows.Reason(:) = {'Unique to state'};
                    deletedRows = app.sortTable(deletedRows,app.timepointOrder,{'Start','End','Exposure'});
                    
                    printReps = 0;
                    if printReps == 0 | app.printReps == 0
                        repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), deletedRows.Properties.VariableNames);
                        deletedRows = removevars(deletedRows,deletedRows.Properties.VariableNames(repPosition));
                    end
                    
                    printCompact = 0;
                    if printCompact == 1 | app.printCompact == 1
                        deletedRows = removevars(deletedRows,{'Modification','Fragment','MHP','z','N'});
                    end
                    
                    app.Removed_UITable.Data = [app.Removed_UITable.Data; deletedRows]; % change second element to whatever rowsToRemove or missingTimepoints
                    app.Removed_UITable.ColumnName = deletedRows.Properties.VariableNames';
                    app.Removed_UITable.ColumnSortable = true;

                else
                    fprintf('\n      No unique peptides found between states A and B, 0 peptide datapoints will be disregarded')
                end
                
                waitbar(0.5,f,'Formatting data tables...');

                % Add the sum time series to the data
                fprintf('\n   Calculating sum exposure for all peptides, peptides with missing timepoints will be removed ..')
                
                [app.A.common, app.A.sum.ignoredPeptides] = app.addSum(app.A.common);
                [app.B.common, app.B.sum.ignoredPeptides] = app.addSum(app.B.common);
                
                ignoredPeptides = [];
                
                if ~isempty(app.A.sum.ignoredPeptides)
                    fprintf('\n      %d datapoints were removed from state A', height(app.A.sum.ignoredPeptides))
                    ignoredPeptides = [ignoredPeptides; app.A.sum.ignoredPeptides];
                else
                    fprintf('\n      No peptides were missing timpoints in State A')
                end
                
                if ~isempty(app.B.sum.ignoredPeptides)
                    fprintf('\n      %d datapoints were removed from state B', height(app.B.sum.ignoredPeptides))
                    ignoredPeptides = [ignoredPeptides; app.B.sum.ignoredPeptides];
                else
                    fprintf('\n      No peptides were missing timpoints in State B')
                end

                % Append reason for removal
                if ~isempty(ignoredPeptides)
                    ignoredPeptides.Reason(:) = {'Missing timepoint'};
                    ignoredPeptides = app.sortTable(ignoredPeptides,app.timepointOrder_Sum,{'Start','End','State','Exposure'});
                    
                    warndlg(sprintf('%d peptides were removed when adding sum due to missing timepoints (see "Removed Peptides" table)',height(ignoredPeptides)))
    
                    printReps = 0;
                    if printReps == 0 | app.printReps == 0
                        repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), ignoredPeptides.Properties.VariableNames);
                        ignoredPeptides = removevars(ignoredPeptides,ignoredPeptides.Properties.VariableNames(repPosition));
                    end
                    
                    printCompact = 0;
                    if printCompact == 1 | app.printCompact == 1
                        ignoredPeptides = removevars(ignoredPeptides,{'Modification','Fragment','MHP','z','N'});
                    end
                    
                    app.Removed_UITable.Data = [app.Removed_UITable.Data; ignoredPeptides];
                    app.Removed_UITable.ColumnName = ignoredPeptides.Properties.VariableNames';
                    app.Removed_UITable.ColumnSortable = true;
                end
                
                app.common.sequences = unique(app.A.common.Sequence);
                
                app.AB.common = [app.A.common; app.B.common];
                app.AB.sequences = app.common.sequences;
                app.AB.timepoints = app.common.timepoints;
                app.AB.residues = app.common.residues;
                app.AB.listOfResidues = app.common.listOfResidues;
                app.AB.peptideLengths = app.common.peptideLengths;
                
                % Get custom sorting order
                app.timepointOrder = strtrim(cellstr(num2str(sort(app.AB.timepoints))));
                
                app.timepointOrder_Sum = app.timepointOrder;
                app.timepointOrder_Sum{end+1} = 'Sum';

                fprintf('\n\n   Calculating differences between State B: %s and State A: %s...',app.StateBDropDown.Value,app.StateADropDown.Value)
                app.diff = app.getDifferenceInfo(app.A,app.B,app.common);

                
                % ----------------------------------------------------------------------------------
                % ----------------------------- Statistical tests here -----------------------------
                
                fprintf('\n\n   --------------------------------------------')
                fprintf('\n   |                 Statistics               |')
                fprintf('\n   --------------------------------------------\n')
                
                waitbar(0.7,f,'Calculating statistics...');
                
                % Calculate the SEM table for the AB common date according to Hageman & Weis, 2019: Hybrid significance testing
                fprintf('\n   Calculating SEM of non-sum data according to Hageman & Weis, 2019, Anal. Chem.\n\n')
                app.statistics.Hybrid = app.calculateHybridSEM(app.AB.common);
                disp(app.statistics.Hybrid)

                % Perform per-peptide significance tests for each comparison
                fprintf('\n   Calculating p-values according to linear model')
                app.diff.common = app.peptideSignificance(app.diff.common);
                
                fprintf('\n      Applying Benjamini & Hochberg FDR-correction for peptide-level significance test')
                app.diff.common = app.FDRcorrection(app.diff.common);
                app.diff.common = app.sortTable(app.diff.common,app.timepointOrder_Sum,{'Start','End','Exposure'});
                
                % --------------------------------------------------------------------------------------------------------------
                % --------------------------------------------------------------------------------------------------------------
                
                % Add AB.common to data table
                copy.AB.common = app.AB.common;

                printReps = 0;
                if printReps == 0 | app.printReps == 0
                    repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'Reps')), copy.AB.common.Properties.VariableNames);
                    copy.AB.common = removevars(copy.AB.common,copy.AB.common.Properties.VariableNames(repPosition));
                end
                
                printCompact = 0;
                if printCompact == 1 | app.printCompact == 1
                    copy.AB.common = removevars(copy.AB.common,{'Modification','Fragment','MHP','z','N'});
                end
                
                app.StatesABCommon_UITable.Data = [app.StatesABCommon_UITable.Data; copy.AB.common];
                app.StatesABCommon_UITable.ColumnName = copy.AB.common.Properties.VariableNames';
                app.StatesABCommon_UITable.ColumnSortable = true;
                
                % Add AB.diff to data table
                copy.diff.common = app.diff.common;
                
                printStats = 0;
                if printStats == 0 | app.printStats == 0
                    copy.diff.common = removevars(copy.diff.common,{'Welch_pValue','Peptide_pValue','LinearModel','FDR_Rank','BH_pValue'});
                end
                
                printReps = 0;
                if printReps == 0 | app.printReps == 0
                    repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'reps')), copy.diff.common.Properties.VariableNames);
                    copy.diff.common = removevars(copy.diff.common,copy.diff.common.Properties.VariableNames(repPosition));
                end
                
                printCompact = 0;
                if printCompact == 1 | app.printCompact == 1
                    copy.diff.common = removevars(copy.diff.common,{'Modification','Fragment','MHP','z','N'});
                end
                
                app.StateBStateA_UITable.Data = [app.StateBStateA_UITable.Data; copy.diff.common];
                app.StateBStateA_UITable.ColumnName = copy.diff.common.Properties.VariableNames';
                app.StateBStateA_UITable.ColumnSortable = true;
                
                % --------------------------------------------------------------------------------------------------------------
                % --------------------------------------------------------------------------------------------------------------
                
                % If the number of states imported is less than 2, then also remove plot
                % types that require two states

                unitsDropdown = [app.Advanced_UnitsDropDown app.Advanced_UnitsDropDownLabel];
                confidenceDropdown = [app.Advanced_ConfidenceDropDown app.Advanced_ConfidenceDropDownLabel];
                plotTypeDropdown = [app.Advanced_PlotTypeDropDown app.Advanced_PlotTypeDropDownLabel];
                
                app.Coverage_PlotTypeDropDown.Items = {'States A/B','State A','State B','Common','Comparative'};
                app.Advanced_StatisticsDropDown.Items = {'Peptide','Hybrid','None'};
                app.Advanced_StatisticsDropDown.Value = 'Peptide';
                app.Advanced_PlotTypeDropDown.Items = {'Woods Differential','Butterfly Differential','Volcano'};
                app.Advanced_PlotTypeDropDown.Value = 'Woods Differential';
                
                set(plotTypeDropdown, 'Enable', 'On');
                set(unitsDropdown, 'Enable', 'On');
                % set(statesDropdown, 'Enable', 'On');
                set(confidenceDropdown, 'Enable', 'On');
                
                set(app.Panel_Advanced, 'AutoResizeChildren','off')
                set(app.ShowCISwitch, 'Enable','On')
                set(app.ModelDropDown, 'Enable','On')
                set(app.alphaDropDown, 'Enable','On')
                
                % --------------------------------------------------------------------------------------------------------------
                % --------------------------------------------------------------------------------------------------------------

                % Update log box
                app.LogTextArea.Value =  {sprintf('State A: %s (%s)',app.ProteinDropDown.Value,app.StateADropDown.Value); ...
                                          sprintf('HDX time course (min): %s',strjoin(string(app.A.timepoints'),', ')); ...
                                          sprintf('HDX control samples: %s',backExchangeText_A_state); ...
                                          sprintf('Back-exchange (mean / IQR): %s',backExchangeText_A); ...
                                          sprintf('# of Peptides: %d',length(app.A.peptideLengths)); ...
                                          sprintf('Sequence coverage: %.02f%%',app.A.coverage); ...
                                          sprintf('Average peptide length / redundancy: %.02f / %.02f',app.A.averagePeptideLength,app.A.averageRedundancy); ...
                                          sprintf('Replicates (technical): %d',app.A.replicates); ...
                                          sprintf('Repeatability: %.4f (average SD)',app.A.repeatability); ...
                                          ' '; ...
                                          sprintf('State B: %s (%s)',app.ProteinDropDown.Value,app.StateBDropDown.Value); ...
                                          sprintf('HDX time course (min): %s',strjoin(string(app.B.timepoints'),', ')); ...
                                          sprintf('HDX control samples: %s',backExchangeText_B_state); ...
                                          sprintf('Back-exchange (mean / IQR): %s',backExchangeText_B); ...
                                          sprintf('# of Peptides: %d',length(app.B.peptideLengths)); ...
                                          sprintf('Sequence coverage: %.02f%%',app.B.coverage); ...
                                          sprintf('Average peptide length / redundancy: %.02f / %.02f',app.B.averagePeptideLength,app.B.averageRedundancy); ...
                                          sprintf('Replicates (technical): %d',app.B.replicates); ...
                                          sprintf('Repeatability: %.4f (average SD)',app.B.repeatability)};

                
                % Get list of all peptides
                app.combinedTables = [app.A.state; app.B.state];
                app.combinedTables = sortrows(app.combinedTables,'Start','ascend');
                peptideList = unique(app.combinedTables.Sequence,'stable');
            else
                % If only A, then 
                app.combinedTables = sortrows(app.A.state,'Start','ascend');
                peptideList = unique(app.A.state.Sequence,'stable');  
                
                if app.A.numberOfTimepoints > 2
                    
                    % Add Sum data to state A
                    fprintf('\n   Calculating sum exposure for all peptides, peptides with missing timepoints will be removed ..')
                    [app.A.state, app.A.sum.ignoredPeptides] = app.addSum(app.A.state);
                end
                
                app.timepointOrder = strtrim(cellstr(num2str(sort(app.A.timepoints))));
                
                % Remember how many states were imported
                app.cluster.numberOfImportedStates = 1;

                % --------------------------------------------------------------------------------------------------------------
                % --------------------------------------------------------------------------------------------------------------
                
                waitbar(0.85,f,'Outputting summary...');
                
                % If the number of states imported is less than 2, then also remove plot types that require two states
                unitsDropdown = [app.Advanced_UnitsDropDown app.Advanced_UnitsDropDownLabel];
                confidenceDropdown = [app.Advanced_ConfidenceDropDown app.Advanced_ConfidenceDropDownLabel];
                plotTypeDropdown = [app.Advanced_PlotTypeDropDown app.Advanced_PlotTypeDropDownLabel];
                
                app.Coverage_PlotTypeDropDown.Items = {'State A'};
                app.Advanced_StatisticsDropDown.Items = {'None'};
                app.Advanced_PlotTypeDropDown.Items = {'Woods Single','Butterfly Single'};
                app.Advanced_PlotTypeDropDown.Value = 'Woods Single';
                
                set(plotTypeDropdown, 'Enable', 'On');
                set(unitsDropdown, 'Enable', 'On');
                set(confidenceDropdown, 'Enable', 'Off');
                
                set(app.Panel_Advanced, 'AutoResizeChildren','off')
                set(app.ShowCISwitch, 'Enable','off')
                set(app.ModelDropDown, 'Enable','off')
                set(app.alphaDropDown, 'Enable','off')
                % --------------------------------------------------------------------------------------------------------------
                % --------------------------------------------------------------------------------------------------------------
                
                % Update log box
                app.LogTextArea.Value =  {sprintf('State A: %s (%s)',app.ProteinDropDown.Value,app.StateADropDown.Value); ...
                                          sprintf('HDX time course (min): %s',strjoin(string(app.A.timepoints'),', ')); ...
                                          sprintf('HDX control samples: %s',backExchangeText_A_state); ...
                                          sprintf('Back-exchange (mean / IQR): %s',backExchangeText_A); ...
                                          sprintf('# of Peptides: %d',length(app.A.peptideLengths)); ...
                                          sprintf('Sequence coverage: %.01f%%',app.A.coverage); ...
                                          sprintf('Average peptide length / redundancy: %.01f / %.01f',app.A.averagePeptideLength,app.A.averageRedundancy); ...
                                          sprintf('Replicates (technical): %d',app.A.replicates); ...
                                          sprintf('Repeatability: %.03f (average SD)',app.A.repeatability)};
                
            end
            
            waitbar(1,f,'Finishing...');
            
            % Everytime the import button is pressed, clear the figure
            delete(allchild(app.Panel_Advanced))
            delete(allchild(app.Panel_Coverage))
            delete(app.PeptideTree.Children)
                
            % Populate peptide tree with peptides
            for row = 1:length(peptideList)
                temp = app.combinedTables(strcmp(app.combinedTables.Sequence,peptideList{row}),:);
                startResidue = unique(temp.Start);
                endResidue = unique(temp.End);
               
                uitreenode(app.PeptideTree,'Text',sprintf('%d \t%d \t%s',startResidue,endResidue,peptideList{row}));
            end
            
            % If back-exchange selected and some peptides not corrected
            if errorToReport_stateA == 1 & errorToReport_stateB == 1
                warndlg(sprintf('%d peptides were not found in control state during back-exchange correction (see "Non-Corrected Data" table)',length(unique(app.A.state_notCorrected.Sequence))+length(unique(app.B.state_notCorrected.Sequence))))
            elseif errorToReport_stateA == 1
                warndlg(sprintf('%d peptides were not found in control state during back-exchange correction for %s (see "Non-Corrected Data" table)',length(unique(app.A.state_notCorrected.Sequence)),app.A.state_notCorrected.State{1}))
            elseif errorToReport_stateB == 1
                warndlg(sprintf('%d peptides were not found in control state during back-exchange correction for %s (see "Non-Corrected Data" table)',length(unique(app.B.state_notCorrected.Sequence)),app.B.state_notCorrected.State{1}))
            end
            
            fprintf('\n\n   Finished.\n')
            close(f);

        end

        % Button pushed function: Coverage_PlotButton
        function Coverage_PlotButtonPushed(app, event)
            % Generates linear coverage maps based on the states selected in the import data panel
            % Requires unfiltered stateA, stateB, commonA and commonB tables
            % Has the following options:
            %       1) States A/B
            %           stateA -> peptides from state A
            %           stateB -> peptides from state B
            %       2) State A -> same as above
            %       3) State B -> same as above
            %       4) Common -> peptide list from either commonA or commonB, plot 1
            
            f = waitbar(0,'Generating plot..');
            
            set(app.Panel_Coverage,'Visible','on');
            delete(allchild(app.Panel_Coverage))
            
            dataType = app.Coverage_DataTypeDropDown.Value;
            
            % Check if individual peptide redundancy map should be plotted
            plotPeptides = 0;
            if strcmp(app.Coverage_PeptidesDropDown.Value,'On')
                plotPeptides = 1;
            end

            % Check which plot type is selected 
            if strcmp(app.Coverage_PlotTypeDropDown.Value,'States A/B')
                if strcmp(dataType,'Coverage')
                    app.polyplotCoverageDouble(app.A.listOfResidues,app.B.listOfResidues,app.Panel_Coverage,...
                        app.StateADropDown.Value,app.StateBDropDown.Value,...
                        app.cBlue, app.cPink, plotPeptides);
                elseif strcmp(dataType,'Redundancy')
                    app.polyplotRedundancyDouble(app.A.nonUniqueResidues,app.B.nonUniqueResidues,app.A.listOfResidues,...
                        app.B.listOfResidues,app.Panel_Coverage,...
                        app.StateADropDown.Value,app.StateBDropDown.Value,plotPeptides);
                elseif strcmp(dataType,'Barcode-DU')
                    type = 1; % 1 for DU, 2 for RU
                    [app.BarcodePlotHandle, app.bc1, app.bc2] = app.polyplotBarcodeDouble(app.A.common,app.diff.common,app.Panel_Coverage,type);
                elseif strcmp(dataType,'Barcode-RU')
                    type = 2; % 1 for DU, 2 for RU
                    [app.BarcodePlotHandle, app.bc1, app.bc2] = app.polyplotBarcodeDouble(app.A.common,app.diff.common,app.Panel_Coverage,type);
                end
                
            elseif strcmp(app.Coverage_PlotTypeDropDown.Value,'State A')
                if strcmp(dataType,'Coverage')
                    app.polyplotCoverage(app.A.listOfResidues,app.Panel_Coverage,app.cBlue,app.StateADropDown.Value,...
                        app.A.coverage,plotPeptides); % function to plot
                elseif strcmp(dataType,'Redundancy')
                    app.polyplotRedundancy(app.A.nonUniqueResidues,app.A.listOfResidues,app.Panel_Coverage,app.StateADropDown.Value,...
                        app.A.coverage,plotPeptides);
                elseif strcmp(dataType,'Barcode-DU')
                    type = 1; % 1 for DU, 2 for RU
                    [app.BarcodePlotHandle, app.bc1] = app.polyplotBarcode(app.A.state,app.Panel_Coverage,type,'State A');
                elseif strcmp(dataType,'Barcode-RU')
                    type = 2; % 1 for DU, 2 for RU
                    [app.BarcodePlotHandle, app.bc1] = app.polyplotBarcode(app.A.state,app.Panel_Coverage,type,'State A');
                end

            elseif strcmp(app.Coverage_PlotTypeDropDown.Value,'State B')
                if strcmp(dataType,'Coverage')
                    app.polyplotCoverage(app.B.listOfResidues,app.Panel_Coverage,app.cPink,app.StateBDropDown.Value,...
                        app.B.coverage,plotPeptides); % function to plot
                elseif strcmp(dataType,'Redundancy')
                    app.polyplotRedundancy(app.B.nonUniqueResidues,app.B.listOfResidues,app.Panel_Coverage,app.StateBDropDown.Value,...
                        app.B.coverage,plotPeptides);
                elseif strcmp(dataType,'Barcode-DU')
                    type = 1; % 1 for DU, 2 for RU
                    [app.BarcodePlotHandle, app.bc1] = app.polyplotBarcode(app.B.state,app.Panel_Coverage,type,'State B');
                elseif strcmp(dataType,'Barcode-RU')
                    type = 2; % 1 for DU, 2 for RU
                    [app.BarcodePlotHandle, app.bc1] = app.polyplotBarcode(app.B.state,app.Panel_Coverage,type,'State B');
                end
                
            elseif strcmp(app.Coverage_PlotTypeDropDown.Value,'Common')
                if strcmp(dataType,'Coverage')
                    app.polyplotCoverage(app.diff.listOfResidues,app.Panel_Coverage,app.cGreen,...
                        sprintf('Common Peptides between States %s and %s',app.StateADropDown.Value,app.StateBDropDown.Value),...
                        app.common.coverage,plotPeptides); % function to plot
                elseif strcmp(dataType,'Redundancy')
                    app.polyplotRedundancy(app.diff.nonUniqueResidues,app.diff.listOfResidues,app.Panel_Coverage,...
                        sprintf('Common Peptides between States %s and %s',app.StateADropDown.Value,app.StateBDropDown.Value),...
                        app.common.coverage,plotPeptides);
                end 
                
            elseif strcmp(app.Coverage_PlotTypeDropDown.Value,'Comparative')
                % The difference map displays a single protein bar for a set of two states
                % and colours peptides according to: common peptides, state A only, state
                % B only
                
                % By default all peptides must be shown
                % Data is in coverage presentation only

                app.polyplotDifference(app.A.listOfResidues,app.B.listOfResidues,app.Panel_Coverage,plotPeptides);
            end
            
            close(f);
        end

        % Button pushed function: Coverage_ExportButton
        function Coverage_ExportButtonPushed(app, event)
            
            f = waitbar(0,'Exporting figure..');
            
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            if contains(app.Coverage_DataTypeDropDown.Value,'Barcode')
                newHandle = copyobj(app.BarcodePlotHandle,fig);
                
                % Pull handle of barcode plot directly 
                if length(newHandle) == 2
                    % Transplant the formatting to each of the new figures
                    fs1 = struct(newHandle(1).Children);
                    
                    fs1.Colorbar.Label.String = app.bc1.Colorbar.Label.String;
                    fs1.Colorbar.TickLabels = app.bc1.Colorbar.TickLabels;
                    fs1.Colorbar.FontSize = app.bc1.Colorbar.FontSize;
                    fs1.Colorbar.Ticks = app.bc1.Colorbar.Ticks;
                    fs1.YLabelHandle.FontSize = app.bc1.YLabelHandle.FontSize;
                    fs1.YAxis.FontSize = app.bc1.YAxis.FontSize;
                    fs1.MissingDataColorbar.TickLabels = app.bc1.MissingDataColorbar.TickLabels;
                    fs1.MissingDataColorbar.FontSize = app.bc1.MissingDataColorbar.FontSize;
                    fs1.EnableInteractions = app.bc1.EnableInteractions;
                    
                    fs2 = struct(newHandle(2).Children);
                    
                    fs2.Colorbar.Label.String = app.bc2.Colorbar.Label.String;
                    fs2.Colorbar.TickLabels = app.bc2.Colorbar.TickLabels;
                    fs2.Colorbar.FontSize = app.bc2.Colorbar.FontSize;
                    fs2.Colorbar.Ticks = app.bc2.Colorbar.Ticks;
                    fs2.YLabelHandle.FontSize = app.bc2.YLabelHandle.FontSize;
                    fs2.YAxis.FontSize = app.bc2.YAxis.FontSize;
                    fs2.MissingDataColorbar.TickLabels = app.bc2.MissingDataColorbar.TickLabels;
                    fs2.MissingDataColorbar.FontSize = app.bc2.MissingDataColorbar.FontSize;
                    fs2.XAxis.FontSize = app.bc2.XAxis.FontSize;
                    
                    % Some of these properties turn off outside of structure method as it seems to revert upon saving
                    fig.Children(1).Children.XDisplayLabels = cellfun(@(x) '',fig.Children(1).Children.XDisplayLabels,'UniformOutput',false);
                    fig.Children(2).Children.XDisplayLabels = app.bc2.XAxis.TickLabels;
                    
                    fig.Position(3:4) = app.Panel_Coverage.Position(3:4); % Resize figure according to dimensions of parent panel_coverage
                else
                    % Transplant the formatting to each of the new figures
                    fs1 = struct(newHandle(1).Children);
                    
                    fs1.Colorbar.Label.String = app.bc1.Colorbar.Label.String;
                    fs1.Colorbar.TickLabels = app.bc1.Colorbar.TickLabels;
                    fs1.Colorbar.FontSize = app.bc1.Colorbar.FontSize;
                    fs1.Colorbar.Ticks = app.bc1.Colorbar.Ticks;
                    fs1.YLabelHandle.FontSize = app.bc1.YLabelHandle.FontSize;
                    fs1.YAxis.FontSize = app.bc1.YAxis.FontSize;
                    fs1.MissingDataColorbar.TickLabels = app.bc1.MissingDataColorbar.TickLabels;
                    fs1.MissingDataColorbar.FontSize = app.bc1.MissingDataColorbar.FontSize;
                    fs1.EnableInteractions = app.bc1.EnableInteractions;
                    
                    % Some of these properties turn off outside of structure method as it seems to revert upon saving
                    fig.Children(1).Children.XDisplayLabels = app.bc1.XAxis.TickLabels;
                    fig.Children.OuterPosition(1:2) = [1 1];
                    fig.Position(3:4) = app.Panel_Coverage.Position(3:4); % Resize figure according to dimensions of parent panel_coverage
                end
            else
                % Coverage and Redundancy - copy anything in app.Panel_Coverage
                newHandle = copyobj(app.Panel_Coverage,fig);
                
                fig.Children.OuterPosition(1:2) = [1 1]; % bring copied panel to bottom corner
                fig.Position(3:4) = app.Panel_Coverage.Position(3:4);
            end
                
            % Ask user where to save
            [FileName, PathName] = uiputfile('*.svg', 'Save As ..',[app.ClusterFileTextBox.Value(1:end-4),'_',app.ProteinDropDown.Value,'_',app.Coverage_DataTypeDropDown.Value]);
            if PathName==0
                close(f);
                return
            end
            
            name = fullfile(PathName,FileName);
            
            print(fig, name,'-dsvg');
            delete(fig);
            
            close(f);
            
        end

        % Button pushed function: Advanced_PlotButton
        function Advanced_PlotButtonPushed(app, event)
            % Performs plotting when button is pressed 
            
            f = waitbar(0,'Generating plot..');
            
            % Clear current UI Tables
            app.FilteredData_UITable.Data = [];
            
            % Get current selections on plot options
            app.plotOptions.Statistics     = app.Advanced_StatisticsDropDown.Value;
            app.plotOptions.PlotType       = app.Advanced_PlotTypeDropDown.Value;
            % plotOptions.States         = app.Advanced_StatesDropDown.Value;
            app.plotOptions.DataType       = app.Advanced_UnitsDropDown.Value;
            app.plotOptions.Confidence     = str2double(app.Advanced_ConfidenceDropDown.Value);
            
            % Also get and format reisude range to highlight in plots
            if ~isempty(app.Advanced_HighlightEditField.Value)
                if ischar(app.Advanced_HighlightEditField.Value)
                    residueList = split(split(app.Advanced_HighlightEditField.Value,','),'-');
                    app.plotOptions.Highlight = str2double(residueList);
                end
            else
                app.plotOptions.Highlight = [];
            end
            
            % ---------------------------------------------------------------------
            % Prepare statistics --------------------------------------------------
            if ~strcmp(app.plotOptions.Statistics,'None')
                % Calculate CI for hybrid
                hybrid = app.statistics.Hybrid;
                hybrid.alpha = tinv(1-(app.plotOptions.Confidence/2),hybrid.df);
                hybrid.CI = hybrid.SEM.*hybrid.alpha;
                
                app.plotOptions.hybrid = hybrid;
            end

            % Depending on which plot type is selected, execute relevant code
            % --------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------
            if strcmp(app.plotOptions.PlotType,'Woods Differential') % ------------------------------------------------------------------------------------------------------------------------------------------
                
                % Format Data
                if strcmp(app.plotOptions.Statistics,'Hybrid') | strcmp(app.plotOptions.Statistics,'Peptide')
                    data = app.diff.common(~strcmp(app.diff.common.Exposure,"0") & ~strcmp(app.diff.common.Exposure,"Sum"),:);
                else
                    data = app.diff.common(~strcmp(app.diff.common.Exposure,"0"),:);
                end

                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [app.Advanced_scrollPanel, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                % Plot the Woods plot
                app.filteredTable = app.plotWoodsDifferential(app.plotHandle,data,app.plotOptions);

            elseif strcmp(app.plotOptions.PlotType,'Woods Double') % ------------------------------------------------------------------------------------------------------------------------------------------
                
                % Get AB data for plotting, excluding 0 timepoint
                data = app.AB.common(~strcmp(app.AB.common.Exposure,"0"),:);
                
                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [app.Advanced_scrollPanel, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                % Plot the Woods plot
                stateA_name = app.StateADropDown.Value;
                stateB_name = app.StateBDropDown.Value;
                
                app.filteredTable = app.plotWoodsDouble(app.plotHandle,data,app.plotOptions,stateA_name,stateB_name);
                
            elseif strcmp(app.plotOptions.PlotType,'Woods Single') % ------------------------------------------------------------------------------------------------------------------------------------------
                
                % Get A data for plotting, excluding 0 timepoint
                stateA = app.A.state;
                stateA.Exposure = string(stateA.Exposure);
                
                data = stateA(~strcmp(stateA.Exposure,"0"),:);
                
                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [app.Advanced_scrollPanel, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                % Plot the Woods plot
                stateA_name = app.StateADropDown.Value;
                
                app.filteredTable = app.plotWoodsSingle(app.plotHandle,data,app.plotOptions,stateA_name);
                
            elseif strcmp(app.plotOptions.PlotType,'Butterfly Differential')
                
                % Format Data
                if strcmp(app.plotOptions.Statistics,'Hybrid') | strcmp(app.plotOptions.Statistics,'Peptide')
                    data = app.diff.common(~strcmp(app.diff.common.Exposure,"0") & ~strcmp(app.diff.common.Exposure,"Sum"),:);
                else
                    data = app.diff.common(~strcmp(app.diff.common.Exposure,"0"),:);
                end
                
                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [app.Advanced_scrollPanel, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                % Plot the Butterfly plot
                app.filteredTable = app.plotButterflyDifferential(app.plotHandle,data,app.plotOptions);
                
            elseif strcmp(app.plotOptions.PlotType,'Butterfly Double')
                
                % Get AB data for plotting, excluding 0 timepoint
                data = app.AB.common(~strcmp(app.AB.common.Exposure,"0"),:);
                
                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [~, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                % Plot the Woods plot
                stateA_name = app.StateADropDown.Value;
                stateB_name = app.StateBDropDown.Value;
                
                % Plot the Butterfly plot
                app.filteredTable = app.plotButterflyDouble(app.plotHandle,data,app.plotOptions,stateA_name,stateB_name);
                
            elseif strcmp(app.plotOptions.PlotType,'Butterfly Single') % ------------------------------------------------------------------------------------------------------------------------------------------
                
                % Get A data for plotting, excluding 0 timepoint
                stateA = app.A.state;
                stateA.Exposure = string(stateA.Exposure);
                
                data = stateA(~strcmp(stateA.Exposure,"0"),:);
                
                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [app.Advanced_scrollPanel, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                % Plot the Woods plot
                stateA_name = app.StateADropDown.Value;
                
                app.filteredTable = app.plotButterflySingle(app.plotHandle,data,app.plotOptions,stateA_name);
                
            elseif strcmp(app.plotOptions.PlotType,'Volcano')
                % Format Data
                data = app.diff.common(~strcmp(app.diff.common.Exposure,"0") & ~strcmp(app.diff.common.Exposure,"Sum"),:);
                
                % Plot order 
                plotOrder = app.determineOrder(data);
                
                % Set up the subplots
                [app.Advanced_scrollPanel, app.plotHandle] = app.initialiseSubplots(app.Panel_Advanced,data,app.plotOptions,plotOrder);
                
                app.filteredTable = app.plotVolcano(app.plotHandle,data,app.plotOptions);
            end

            % Format filteredTable for uitable
            copy.filteredTable = app.filteredTable;
            
            printReps = 0;
            if printReps == 0 | app.printReps == 0
                repPosition = cellfun(@(c) ischar(c) && ~isempty(strfind(c, 'reps')), copy.filteredTable.Properties.VariableNames);
                copy.filteredTable = removevars(copy.filteredTable,copy.filteredTable.Properties.VariableNames(repPosition));
            end
            
            printCompact = 0;
            if printCompact == 1 | app.printCompact == 1
                copy.filteredTable = removevars(copy.filteredTable,{'Modification','Fragment','MHP','z','N'});
            end

            if strcmp(app.plotOptions.Statistics,'Hybrid')
                % Remove Peptide_pValue, LinearModel, FDR_Rank, BH_pValue, BH_critValue, BH_sig, ColorMap
                copy.filteredTable = removevars(copy.filteredTable,{'Peptide_pValue','LinearModel','FDR_Rank','BH_pValue','ColorMap'});
            elseif strcmp(app.plotOptions.Statistics,'Peptide')
                % Remove Welch_pValue, LinearModel, BH_critValue, BH_sig, ColorMap
                copy.filteredTable = removevars(copy.filteredTable,{'Welch_pValue','LinearModel','ColorMap'});
            end
            
            app.FilteredData_UITable.Data = [app.FilteredData_UITable.Data; copy.filteredTable];
            app.FilteredData_UITable.ColumnName = copy.filteredTable.Properties.VariableNames';
            app.FilteredData_UITable.ColumnSortable = true;
            
            close(f)
            
        end

        % Button pushed function: Advanced_ExportButton
        function Advanced_ExportButtonPushed(app, event)
            % Gets children of WoodsPanel and exports as pdf
            
            f = waitbar(0,'Exporting figure..');
            
            % Copy each subplot to new figure and save and delete figure
            fig = figure();
            set(fig, 'InvertHardCopy', 'off','Visible','off');
            fig.Position(3:4) = app.Panel_Advanced.Position(3:4);
            
            % Count how many plots there are and re-create in the new figure
            uiaxes = allchild(app.Panel_Advanced.Children);
            rows = round(length(uiaxes)/2);
            if length(uiaxes)>1, cols = 2; else; cols = 1; end

            % Copy each plot onto the new figure
            for p = 1:length(uiaxes)
                sub(p) = subplot(rows,cols,p,'Parent',fig);
                copyobj(uiaxes(p).Children, sub(p));
                
                uiAxParams = get(uiaxes(p));
                uiAxParamNames = fieldnames(uiAxParams);
                editableParams = fieldnames(set(sub(p)));
                badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
                badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'InnerPosition';'OuterPosition'];
                uiAxGoodParams = rmfield(uiAxParams,badFields);
                
                set(sub(p), uiAxGoodParams); % transplant gui figure properties onto external figure
            end
            
            % Ask user where to save
            [FileName, PathName] = uiputfile('*.svg', 'Save As ..',[app.ClusterFileTextBox.Value(1:end-4),'_',app.ProteinDropDown.Value,'_',app.Advanced_PlotTypeDropDown.Value]);
            if PathName==0
                close(f);
                return
            end
            
            name = fullfile(PathName,FileName);
            
            print(fig, name,'-dsvg');
            delete(fig);
            
            close(f);
            
        end

        % Button pushed function: MG_ExportButton
        function MG_ExportButtonPushed(app, event)
            
            f = waitbar(0,'Generating formatting scripts.. ');
            
            % Exports formatting script to molecular graphics viewer
            
            % Get options from dropdowns
            MGV = app.MG_ExportToDropDown.Value;
            exportLinear = app.MG_ExportCoverageCheckBox.Value;
            exportAdvanced = app.MG_ExportAdvancedCheckBox.Value;
            
            % For chain, test if single letter or multiple, if multiple separate
            if ischar(app.MG_ChainEditBox.Value)
                chainStr = strsplit(app.MG_ChainEditBox.Value,',');

                if sum(~isnan(str2double(chainStr))) > 0
                    errordlg('Chain IDs must be non numeric!')
                    return
                end
                
                chain.PyMOL = char(strcat({'('},strjoin(strcat({'Chain '},chainStr),' | '),{')'}));
                chain.Chimera = strcat({'.'},chainStr);
            else
                errordlg('Invalid chain ID!')
                return
            end
            
            if exportLinear
                % Also get colour options
                colorCoverage = app.MG_ColorCoverageDropDown.Value;
                colorNoCoverage = app.MG_ColorNoCoverageDropDown.Value;
                colorRedundancy = app.MG_ColorRedundancyDropDown.Value;
                
                % calculate the colours for redundancy
                StateA_plotted = app.redundancyCols(app.A.nonUniqueResidues,colorRedundancy);
                StateB_plotted = app.redundancyCols(app.B.nonUniqueResidues,colorRedundancy);
                Common_plotted = app.redundancyCols(app.common.nonUniqueResidues,colorRedundancy);

                if strcmp(MGV,'PyMOL')
                    textBlock = {}; % store the text here
                    
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = '# Please reference the following paper if you show or use data from Deuteros:';
                    textBlock{end+1,1} = '#   Lau, A. M. C., Ahdash, Z., Martens, C., Politis, A. (2019). Deuteros: software for rapid analysis and visualization of data';
                    textBlock{end+1,1} = '#   from differential hydrogen deuterium exchange-mass spectrometry. Bioinformatics, btz022.';
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = sprintf('# Data generated on %s from cluster file %s',date,app.ClusterFileTextBox.Value);
                    textBlock{end+1,1} = sprintf('# For protein: %s',app.ProteinDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected State A: %s',app.StateADropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected State B: %s',app.StateBDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected Control A: %s',app.StateA_ControlDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected Control B: %s',app.StateB_ControlDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Deuterium %%: %s%%',app.DeuteriumEditBox.Value);
                    textBlock{end+1,1} = sprintf('# Protein length: %s-%s',app.Residue_StartEditBox.Value,app.Residue_EndEditBox.Value);
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = '# PyMOL Formatting';
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = 'as cartoon;';
                    textBlock{end+1,1} = 'set opaque_background, off;';
                    textBlock{end+1,1} = 'util.performance(0);';
                    textBlock{end+1,1} = 'set specular, off;';
                    textBlock{end+1,1} = 'space cmyk;';
                    textBlock{end+1,1} = 'bg_color black;';
                    textBlock{end+1,1} = 'set ray_shadow, off;';              
                    textBlock{end+1,1} = '﻿set cartoon_oval_length, 0.9;';
                    textBlock{end+1,1} = ' ';
                   
                    textBlock{end+1,1} = '# ---- Coverage ----';
                    textBlock{end+1,1} = ' ';
                    
                    textBlock = [textBlock; app.coverage2MG(app.A.residues,colorCoverage,colorNoCoverage,chain.PyMOL,'State_A','PyMOL')];
                    textBlock = [textBlock; app.coverage2MG(app.B.residues,colorCoverage,colorNoCoverage,chain.PyMOL,'State_B','PyMOL')];
                    textBlock = [textBlock; app.coverage2MG(app.common.residues,colorCoverage,colorNoCoverage,chain.PyMOL,'Common','PyMOL')];
                    
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = '# --- Redundancy ---';
                    textBlock{end+1,1} = ' ';
                    textBlock = [textBlock; app.redundancy2MG(StateA_plotted,colorNoCoverage,chain.PyMOL,'State_A','A','PyMOL')];
                    textBlock = [textBlock; app.redundancy2MG(StateB_plotted,colorNoCoverage,chain.PyMOL,'State_B','B','PyMOL')];
                    textBlock = [textBlock; app.redundancy2MG(Common_plotted,colorNoCoverage,chain.PyMOL,'Common','Common','PyMOL')];
                    
                    textBlock{end+1,1} = 'residues = []';
                    textBlock{end+1,1} = sprintf('iterate_state 1, %s & n. CA, residues.append(int(resi))',chain.PyMOL);
                    textBlock{end+1,1} = 'original_list = [x for x in range(residues[0], residues[-1] + 1)]';
                    textBlock{end+1,1} = 'num_list = set(residues)';
                    textBlock{end+1,1} = 'missing_residues = list(num_list ^ set(original_list))';
                    textBlock{end+1,1} = 'if len(missing_residues) > 0: print "NOTE: %s Missing residues were found: %s" % (len(missing_residues), ", ".join(str(x) for x in missing_residues))  ';
                    textBlock{end+1,1} = '';

                    % Open the .py file and save 
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),'_coverage_output_PyMOL.pml');
                    fid = fopen(filename,'w');
                    fprintf(fid,'%s\n', textBlock{:});
                    fclose(fid);
                    
                elseif strcmp(MGV,'Chimera')
                    textBlock = {}; % store the text here
                    
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = '# Please reference the following paper if you show or use data from Deuteros:';
                    textBlock{end+1,1} = '#   Lau, A. M. C., Ahdash, Z., Martens, C., Politis, A. (2019). Deuteros: software for rapid analysis and visualization of data';
                    textBlock{end+1,1} = '#   from differential hydrogen deuterium exchange-mass spectrometry. Bioinformatics, btz022.';
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = sprintf('# Data generated on %s from cluster file %s',date,app.ClusterFileTextBox.Value);
                    textBlock{end+1,1} = sprintf('# For protein: %s',app.ProteinDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected State A: %s',app.StateADropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected State B: %s',app.StateBDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected Control A: %s',app.StateA_ControlDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Selected Control B: %s',app.StateB_ControlDropDown.Value);
                    textBlock{end+1,1} = sprintf('# Deuterium %%: %s%%',app.DeuteriumEditBox.Value);
                    textBlock{end+1,1} = sprintf('# Protein length: %s-%s',app.Residue_StartEditBox.Value,app.Residue_EndEditBox.Value);
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = 'from chimera import runCommand';
                    textBlock{end+1,1} = ' ';

                    % Create and save each StateA, StateB and Common for coverage and redundancy
                    textBlock_cov_A = [textBlock; app.coverage2MG(app.A.residues,colorCoverage,colorNoCoverage,chain.Chimera,'State_A','Chimera')];
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),sprintf('_coverage_%s_Chimera.py',app.StateADropDown.Value));
                    fid = fopen(filename,'w'); fprintf(fid,'%s\n', textBlock_cov_A{:}); fclose(fid);
                    
                    textBlock_cov_B = [textBlock; app.coverage2MG(app.B.residues,colorCoverage,colorNoCoverage,chain.Chimera,'State_B','Chimera')];
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),sprintf('_coverage_%s_Chimera.py',app.StateBDropDown.Value));
                    fid = fopen(filename,'w'); fprintf(fid,'%s\n', textBlock_cov_B{:}); fclose(fid);
                    
                    textBlock_cov_com = [textBlock; app.coverage2MG(app.common.residues,colorCoverage,colorNoCoverage,chain.Chimera,'Common','Chimera')];
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),'_coverage_common_Chimera.py');
                    fid = fopen(filename,'w'); fprintf(fid,'%s\n', textBlock_cov_com{:}); fclose(fid);

                    textBlock_red_A = [textBlock; app.redundancy2MG(StateA_plotted,colorNoCoverage,chain.Chimera,'State_A','A','Chimera')];
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),sprintf('_redundancy_%s_Chimera.py',app.StateADropDown.Value));
                    fid = fopen(filename,'w'); fprintf(fid,'%s\n', textBlock_red_A{:}); fclose(fid);
                    
                    textBlock_red_B = [textBlock; app.redundancy2MG(StateB_plotted,colorNoCoverage,chain.Chimera,'State_B','B','Chimera')];
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),sprintf('_redundancy_%s_Chimera.py',app.StateBDropDown.Value));
                    fid = fopen(filename,'w'); fprintf(fid,'%s\n', textBlock_red_B{:}); fclose(fid);
                    
                    textBlock_com_A = [textBlock; app.redundancy2MG(Common_plotted,colorNoCoverage,chain.Chimera,'Common','Common','Chimera')];
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),'_redundancy_common_Chimera.py');
                    fid = fopen(filename,'w'); fprintf(fid,'%s\n', textBlock_com_A{:}); fclose(fid);

                end
            end
            
            if exportAdvanced % Section for exporting advanced data

                % Set up empty cell block for storing per-timepoint text
                textBlock = {}; 
                
                textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                textBlock{end+1,1} = '# Please reference the following paper if you show or use data from Deuteros:';
                textBlock{end+1,1} = '#   Lau, A. M. C., Ahdash, Z., Martens, C., Politis, A. (2019). Deuteros: software for rapid analysis and visualization of data';
                textBlock{end+1,1} = '#   from differential hydrogen deuterium exchange-mass spectrometry. Bioinformatics, btz022.';
                textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                textBlock{end+1,1} = ' ';
                textBlock{end+1,1} = sprintf('# Data generated on %s from cluster file %s',date,app.ClusterFileTextBox.Value);
                textBlock{end+1,1} = sprintf('# For protein: %s',app.ProteinDropDown.Value);
                textBlock{end+1,1} = sprintf('# Selected State A: %s',app.StateADropDown.Value);
                textBlock{end+1,1} = sprintf('# Selected State B: %s',app.StateBDropDown.Value);
                textBlock{end+1,1} = sprintf('# Selected Control A: %s',app.StateA_ControlDropDown.Value);
                textBlock{end+1,1} = sprintf('# Selected Control B: %s',app.StateB_ControlDropDown.Value);
                textBlock{end+1,1} = sprintf('# Deuterium %%: %s%%',app.DeuteriumEditBox.Value);
                textBlock{end+1,1} = sprintf('# Protein length: %s-%s',app.Residue_StartEditBox.Value,app.Residue_EndEditBox.Value);
                textBlock{end+1,1} = ' ';
                textBlock{end+1,1} = sprintf('# Plot type: %s',app.plotOptions.PlotType);
                textBlock{end+1,1} = sprintf('# Data type: %s',app.plotOptions.DataType);
                textBlock{end+1,1} = sprintf('# Filter using: %s',app.plotOptions.Statistics);
                textBlock{end+1,1} = sprintf('# Confidence level: %s%%',app.plotOptions.Confidence);
                
                if strcmp(MGV,'PyMOL')
                    
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = '# PyMOL Formatting';
                    textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                    textBlock{end+1,1} = 'as cartoon;';
                    textBlock{end+1,1} = 'set opaque_background, off;';
                    textBlock{end+1,1} = 'util.performance(0);';
                    textBlock{end+1,1} = 'set specular, off;';
                    textBlock{end+1,1} = 'space cmyk;';
                    textBlock{end+1,1} = 'bg_color black;';
                    textBlock{end+1,1} = 'set ray_shadow, off;';
                    textBlock{end+1,1} = '﻿set cartoon_oval_length, 0.9;';
                    textBlock{end+1,1} = ' ';
                    
                    % If Differential data selected (Woods or Volcano) 
                    if ~isempty(strfind(app.plotOptions.PlotType,'Differential')) | ~isempty(strfind(app.plotOptions.PlotType,'Volcano'))
                        
                        % Get differential data
                        data = app.filteredTable;
                        timepoints = unique(data.Exposure);
                        
                        % Loop over the number of timepoints in the filtered data
                        for t = 1:length(timepoints)
                            
                            % Get the timepoint data
                            timepointData = data(strcmp(data.Exposure,timepoints{t}),:);
                            
                            % Get per category
                            nonsignificant = timepointData(strcmp(timepointData.Significance,'Non significant'),:);
                            deprotected = timepointData(strcmp(timepointData.Significance,'Deprotected'),:);
                            protected = timepointData(strcmp(timepointData.Significance,'Protected'),:);
                            
                            textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                            textBlock{end+1,1} = sprintf('# %s plot data for %s min timepoint', app.plotOptions.PlotType,timepoints{t});
                            textBlock{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                            textBlock{end+1,1} = sprintf('# Number of non-significant peptides: %d', height(nonsignificant));
                            textBlock{end+1,1} = sprintf('# Number of deprotected peptides: %d', height(deprotected));
                            textBlock{end+1,1} = sprintf('# Number of protected peptides: %d', height(protected));
                            textBlock{end+1,1} = ' ';
                            textBlock{end+1,1} = sprintf('hide everything, not %s;', chain.PyMOL);
                            textBlock{end+1,1} = ' ';
                            textBlock{end+1,1} = sprintf('color gray50, %s; # no coverage', chain.PyMOL);
                            textBlock{end+1,1} = ' ';
                            
                            for n = 1:height(nonsignificant)
                                textBlock{end+1,1} = sprintf('color white, %s & resi %d-%d; # coverage, non-significant', chain.PyMOL, nonsignificant.Start(n), nonsignificant.End(n));
                            end
                            
                            textBlock{end+1,1} = ' ';
                            
                            for n = 1:height(protected)
                                textBlock{end+1,1} = sprintf('color tv_blue, %s & resi %d-%d; # coverage, protected', chain.PyMOL, protected.Start(n), protected.End(n));
                            end
                            
                            textBlock{end+1,1} = ' ';
                            
                            for n = 1:height(deprotected)
                                textBlock{end+1,1} = sprintf('color tv_red, %s & resi %d-%d; # coverage, deprotected', chain.PyMOL, deprotected.Start(n), deprotected.End(n));
                            end
                            
                            textBlock{end+1,1} = ' ';
                            
                            textBlock{end+1,1} = ' ';
                            textBlock{end+1,1} = sprintf('scene %s_timepoint_%s, store, view=0;',app.plotOptions.PlotType,timepoints{t});
                            textBlock{end+1,1} = ' ';
                        end
                    end
                    
                    % Open the .py file and save 
                    filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),sprintf('_advanced_%s_PyMOL.pml',app.plotOptions.PlotType));
                    fid = fopen(filename,'w');
                    fprintf(fid,'%s\n', textBlock{:});
                    fclose(fid);
                    
                elseif strcmp(MGV,'Chimera')
                    
                    textBlock{end+1,1} = ' ';
                    textBlock{end+1,1} = 'from chimera import runCommand';
                    textBlock{end+1,1} = ' ';
                    
                    if ~isempty(strfind(app.plotOptions.PlotType,'Differential')) | ~isempty(strfind(app.plotOptions.PlotType,'Volcano'))
                        
                        % Get differential data
                        data = app.filteredTable;
                        timepoints = unique(data.Exposure);
                        
                        % Loop over the number of timepoints in the filtered data
                        for t = 1:length(timepoints)
                            
                            % Get the timepoint data
                            timepointData = data(strcmp(data.Exposure,timepoints{t}),:);
                            
                            % Get per category
                            nonsignificant = timepointData(strcmp(timepointData.Significance,'Non significant'),:);
                            deprotected = timepointData(strcmp(timepointData.Significance,'Deprotected'),:);
                            protected = timepointData(strcmp(timepointData.Significance,'Protected'),:);
                            
                            % Copy a new textBlock, add new timepoint specific text and then save
                            perTimepoint_Block = textBlock;
                            
                            perTimepoint_Block{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                            perTimepoint_Block{end+1,1} = sprintf('# %s plot data for %s min timepoint', app.plotOptions.PlotType,timepoints{t});
                            perTimepoint_Block{end+1,1} = '# --------------------------------------------------------------------------------------------------------------------------------';
                            perTimepoint_Block{end+1,1} = sprintf('# Number of non-significant peptides: %d', height(nonsignificant));
                            perTimepoint_Block{end+1,1} = sprintf('# Number of deprotected peptides: %d', height(deprotected));
                            perTimepoint_Block{end+1,1} = sprintf('# Number of protected peptides: %d', height(protected));
                            perTimepoint_Block{end+1,1} = ' ';
                            perTimepoint_Block{end+1,1} = sprintf('runCommand("~ribbon ~ :%s")', strjoin(chain.Chimera,','));
                            perTimepoint_Block{end+1,1} = sprintf('runCommand("color dimgray :%s")', strjoin(chain.Chimera,','));
                            perTimepoint_Block{end+1,1} = ' ';
                            
                            for n = 1:height(nonsignificant)
                                perTimepoint_Block{end+1,1} = sprintf('runCommand("color white :%s")', strjoin(strcat(sprintf('%d-%d',nonsignificant.Start(n),nonsignificant.End(n)),chain.Chimera),','));
                            end
                            
                            perTimepoint_Block{end+1,1} = ' ';
                            
                            for n = 1:height(protected)
                                perTimepoint_Block{end+1,1} = sprintf('runCommand("color blue :%s")', strjoin(strcat(sprintf('%d-%d',protected.Start(n),protected.End(n)),chain.Chimera),','));
                            end
                            
                            perTimepoint_Block{end+1,1} = ' ';
                            
                            for n = 1:height(deprotected)
                                perTimepoint_Block{end+1,1} = sprintf('runCommand("color red :%s")', strjoin(strcat(sprintf('%d-%d',deprotected.Start(n),deprotected.End(n)),chain.Chimera),','));
                            end
                            
                            perTimepoint_Block{end+1,1} = ' ';

                            % Open the .py file and save 
                            filename = strcat(app.cluster.pathname,app.cluster.filename(1:end-4),sprintf('_advanced_%s_Chimera_%s.py',app.plotOptions.PlotType,timepoints{t}));
                            fid = fopen(filename,'w');
                            fprintf(fid,'%s\n', perTimepoint_Block{:});
                            fclose(fid);
                        end
                    end
                end
            end

            close(f);

        end

        % Value changed function: MG_ExportToDropDown
        function MG_ExportToDropDownValueChanged(app, event)
            % This function auto populates the coverage and nocoverage colour selection dropdowns
            % depending on which MGV is selected
            
            value = app.MG_ExportToDropDown.Value;
            switch value
                case "PyMOL"
                    app.MG_ColorCoverageDropDown.Items = {'Red',	'Raspberry',	'Darksalmon',	'Salmon',	'Deepsalmon',	'Warmpink',	'Firebrick',	'Ruby',	'Chocolate',	'Brown',' ', 'Green',	'Cartreuse',	'Splitpea',	'Smudge',	'Palegreen',	'Limegreen',	'Lime',	'Limon',	'Forest',' ', 'Blue',	'Marine',	'Slate',	'Lightblue',	'Whiteblue','Skyblue',	'Purpleblue',	'Deepblue',	'Density',' ', 'Yellow',	'Paleyellow',	'Yelloworange',	'Wheat',	'Sand',' ', 'Magenta',	'Lightmagenta',	'Hotpink',	'Pink',	'Lightpink',	'Dirtyviolet',	'Violet',	'Violetpurple',	'Purpleblue',	'Deeppurple',' ', 'Cyan',	'Palecyan',	'Aquamarine',	'Greencyan',	'Teal',	'Deepteal',	'Lightteal',' ', 'Orange',	'Brightorange',	'Lightorange',	'Yelloworange',	'Olive',	'Deepolive',' ', 'White',	'Gray90',	'Gray80',	'Gray70',	'Gray60',	'Gray50',	'Gray40',	'Gray30',	'Gray20',	'Gray10',	'Black',};
                    app.MG_ColorNoCoverageDropDown.Items = {'Red',	'Raspberry',	'Darksalmon',	'Salmon',	'Deepsalmon',	'Warmpink',	'Firebrick',	'Ruby',	'Chocolate',	'Brown',' ', 'Green',	'Cartreuse',	'Splitpea',	'Smudge',	'Palegreen',	'Limegreen',	'Lime',	'Limon',	'Forest',' ', 'Blue',	'Marine',	'Slate',	'Lightblue',	'Whiteblue','Skyblue',	'Purpleblue',	'Deepblue',	'Density',' ', 'Yellow',	'Paleyellow',	'Yelloworange',	'Wheat',	'Sand',' ', 'Magenta',	'Lightmagenta',	'Hotpink',	'Pink',	'Lightpink',	'Dirtyviolet',	'Violet',	'Violetpurple',	'Purpleblue',	'Deeppurple',' ', 'Cyan',	'Palecyan',	'Aquamarine',	'Greencyan',	'Teal',	'Deepteal',	'Lightteal',' ', 'Orange',	'Brightorange',	'Lightorange',	'Yelloworange',	'Olive',	'Deepolive',' ', 'White',	'Gray90',	'Gray80',	'Gray70',	'Gray60',	'Gray50',	'Gray40',	'Gray30',	'Gray20',	'Gray10',	'Black',};
                    app.MG_ColorCoverageDropDown.Value = {'Blue'};
                    app.MG_ColorNoCoverageDropDown.Value = {'Gray60'};
                
                case "Chimera"
                    app.MG_ColorCoverageDropDown.Items = {'red','orange red','orange','yellow','green','forest green','cyan','light sea green','blue','cornflower blue','medium blue','purple','hot pink','magenta','white','light gray','gray','dark gray','dim gray','black'};
                    app.MG_ColorNoCoverageDropDown.Items = {'red','orange red','orange','yellow','green','forest green','cyan','light sea green','blue','cornflower blue','medium blue','purple','hot pink','magenta','white','light gray','gray','dark gray','dim gray','black'};
                    app.MG_ColorCoverageDropDown.Value = {'blue'};
                    app.MG_ColorNoCoverageDropDown.Value = {'dim gray'};
            
            end

        end

        % Value changed function: ProteinDropDown
        function ProteinDropDownValueChanged(app, event)

            % Get the number of states for the selected protein
            app.cluster.currentSelectedProtein = strcmp(app.ProteinDropDown.Value,app.cluster.listOfProteins); % get the current selected protein
            app.cluster.numberOfStates = length(app.cluster.perProteinStates{app.cluster.currentSelectedProtein,:});

            % If number of states is somehow less than 1 =  errordlg, otherwise populate
            % dropdown menus with states found
            
            if app.cluster.numberOfStates < 1
                errordlg('Protein contains no states')
                return
            end

            stateList = app.cluster.perProteinStates{app.cluster.currentSelectedProtein,:}; %  contains a list of states belonging to the currently selected protein
            
            % Set each dropdown to show the stateList
            app.StateADropDown.Items = stateList;
            app.StateADropDown.Value = app.StateADropDown.Items(1);
            app.StateA_ControlDropDown.Items = horzcat('None',stateList);
            app.StateB_ControlDropDown.Items = horzcat('None',stateList);
            
            app.StateBDropDown.Items = horzcat('None',stateList);
            app.StateA_ControlDropDown.Items = horzcat('None',stateList);
            app.StateB_ControlDropDown.Items = horzcat('None',stateList);

        end

        % Value changed function: Coverage_PlotTypeDropDown
        function Coverage_PlotTypeDropDownValueChanged(app, event)
            value = app.Coverage_PlotTypeDropDown.Value;
            
            DataTypeDropDown = app.Coverage_DataTypeDropDown;
            PeptidesDropDown = app.Coverage_PeptidesDropDown;
            
            % Control per-plot type menu visibility
            if strcmp(value,'States A/B') | strcmp(value,'State A') | strcmp(value,'State B')
                app.Coverage_DataTypeDropDown.Items = {'Coverage','Redundancy','Barcode-DU','Barcode-RU'};
                
                if contains(app.Coverage_DataTypeDropDown.Value,'Barcode')
                    set(DataTypeDropDown,     'Visible','on', 'Enable','on')
                    set(PeptidesDropDown,     'Visible','on', 'Enable','off')
                else
                    set(DataTypeDropDown,     'Visible','on', 'Enable','on')
                    set(PeptidesDropDown,     'Visible','on', 'Enable','on')
                end
            elseif strcmp(value,'Common')
                app.Coverage_DataTypeDropDown.Items = {'Coverage','Redundancy'};
                set(DataTypeDropDown,     'Visible','on', 'Enable','on')
                set(PeptidesDropDown,     'Visible','on', 'Enable','on')
                
            elseif strcmp(value,'Comparative')
                app.Coverage_DataTypeDropDown.Items = {'Coverage','Redundancy'};
                set(DataTypeDropDown,     'Visible','on', 'Enable','off')
                set(PeptidesDropDown,     'Visible','on', 'Enable','off')
                PeptidesDropDown.Value = 'On';
            end

            
        end

        % Value changed function: Coverage_PeptidesDropDown
        function Coverage_PeptidesDropDownValueChanged(app, event)
            value = app.Coverage_PeptidesDropDown.Value;
            
            PeptideControls = [app.Coverage_BorderColorDropDown, app.Coverage_BarSpacingEditBox, app.Coverage_BarThicknessEditBox];
            
            if strcmp(value,'Off')
                set(PeptideControls,      'Visible','on', 'Enable','off')
            elseif strcmp(value,'On')
                set(PeptideControls,      'Visible','on', 'Enable','on')
            end
            
        end

        % Value changed function: StateBDropDown
        function StateBDropDownValueChanged(app, event)
            value = app.StateBDropDown.Value;

            if strcmp(value,'None')
                app.StateB_ControlDropDown.Value = 'None';
                set(app.StateB_ControlDropDown, 'Visible','on','Enable','off')
            else
                set(app.StateB_ControlDropDown, 'Visible','on','Enable','on')
            end
            
        end

        % Selection changed function: PeptideTree
        function PeptideTreeSelectionChanged(app, event)
            selectedNodes = app.PeptideTree.SelectedNodes;
            
            % First get plotting options
            logTime  = app.LogtimeSwitch.Value;  % 'On' or 'Off'
            Datapoints = app.DatapointsSwitch.Value; % 'On' or 'Off'
            Line = app.LineSwitch.Value; % 'On' or 'Off'
            LineWidth = app.LinewidthEditField.Value; % numerical value
            Markersize = app.MarkersizeEditField.Value; % numerical value
            CapSize = app.CapsizeEditField.Value; % numerical value
            Filter = app.ModelDropDown.Value; % Global or peptide
            alpha = app.alphaDropDown.Value; % 0.001, 0.01, 0.02 or 0.05
            ShowSD = app.ShowSDSwitch.Value; % 'On' or 'Off'
            Confidencelimits = app.ShowCISwitch.Value; % 'On' or 'Off'
            FilterUsing = app.ModelDropDown.Value; % Global CI or Peptide filter
            
            % Clear the log text box
            app.PeptidesStatisticsTextArea.Value = '';
            
            % store all options in a table
            optionTable = cell2table({logTime, Datapoints, Line, LineWidth, Markersize, CapSize, Filter, alpha, ShowSD, Confidencelimits},...
                'VariableNames',{'logTime', 'Datapoints',...
                'Line', 'LineWidth', 'Markersize', 'CapSize', 'Filter', 'alpha', 'ShowSD', 'Confidencelimits'});

            % Get the peptide that the user clicks on
            peptideSequence = split(selectedNodes.Text,' ');
            selectedPeptideName = strtrim(peptideSequence{end}); % get the peptide sequence and remove any spaces
            
            % Grab data for the selected peptide 
            selectedPeptideData = app.combinedTables(strcmp(app.combinedTables.Sequence,selectedPeptideName),:);
            
            % How many states are in the data? Dynamic allocation depending on which peptide is clicked
            numberOfStates = length(unique(selectedPeptideData.State));
            
            % Set up the axes for plotting kinetics 
            ax = app.initialiseKineticsPlot(app.KineticsPanel,optionTable);
            
            % Plot data
            app.plotKinetics(ax,selectedPeptideData,numberOfStates,optionTable)
            
            % Axis scaling settings
            % If the user has entered values into the axis boxes, register them
            x_cond1 = ~isempty(app.xaxis_low.Value);
            x_cond2 = ~isempty(app.xaxis_high.Value);
            y_cond1 = ~isempty(app.yaxis_low.Value);
            y_cond2 = ~isempty(app.yaxis_high.Value);
            
            if x_cond1 && x_cond2
                xlim(ax,[str2double(app.xaxis_low.Value) str2double(app.xaxis_high.Value)])
            end
            
            if y_cond1 && y_cond2
                ylim(ax,[str2double(app.yaxis_low.Value) str2double(app.yaxis_high.Value)])
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.PeptideTreeSelectionChanged
        end

        % Value changed function: LogtimeSwitch
        function LogtimeSwitchValueChanged(app, event)
            value = app.LogtimeSwitch.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: DatapointsSwitch
        function DatapointsSwitchValueChanged(app, event)
            value = app.DatapointsSwitch.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: LineSwitch
        function LineSwitchValueChanged(app, event)
            value = app.LineSwitch.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: ShowSDSwitch
        function ShowSDSwitchValueChanged(app, event)
            value = app.ShowSDSwitch.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: ShowCISwitch
        function ShowCISwitchValueChanged(app, event)
            value = app.ShowCISwitch.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: LinewidthEditField
        function LinewidthEditFieldValueChanged(app, event)
            value = app.LinewidthEditField.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: MarkersizeEditField
        function MarkersizeEditFieldValueChanged(app, event)
            value = app.MarkersizeEditField.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: CapsizeEditField
        function CapsizeEditFieldValueChanged(app, event)
            value = app.CapsizeEditField.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: alphaDropDown
        function alphaDropDownValueChanged(app, event)
            value = app.alphaDropDown.Value;
            app.PeptideTreeSelectionChanged;
        end

        % Value changed function: Advanced_StatisticsDropDown
        function Advanced_StatisticsDropDownValueChanged(app, event)
            value = app.Advanced_StatisticsDropDown.Value;
            
            % statesDropdown = [app.Advanced_StatesDropDown app.Advanced_StatesDropDownLabel];
            unitsDropdown = [app.Advanced_UnitsDropDown app.Advanced_UnitsDropDownLabel];
            confidenceDropdown = [app.Advanced_ConfidenceDropDown app.Advanced_ConfidenceDropDownLabel];
            plotTypeDropdown = [app.Advanced_PlotTypeDropDown app.Advanced_PlotTypeDropDownLabel];
            highlight = [app.Advanced_HighlightEditField];
            
            if strcmp(value,'Hybrid')
                app.Advanced_PlotTypeDropDown.Items = {'Woods Differential','Butterfly Differential','Volcano'};
                app.Advanced_PlotTypeDropDown.Value = 'Woods Differential';
                
                set(plotTypeDropdown, 'Enable', 'On');
                set(unitsDropdown, 'Enable', 'On');
                set(confidenceDropdown, 'Enable', 'On');
                set(highlight, 'Enable', 'on');
            elseif strcmp(value,'Peptide')
                app.Advanced_PlotTypeDropDown.Items = {'Woods Differential','Butterfly Differential'};
                app.Advanced_PlotTypeDropDown.Value = 'Woods Differential';
                
                set(plotTypeDropdown, 'Enable', 'On');
                set(unitsDropdown, 'Enable', 'On');
                set(confidenceDropdown, 'Enable', 'On');
                set(highlight, 'Enable', 'on');
            elseif strcmp(value,'None')
                app.Advanced_PlotTypeDropDown.Items = {'Woods Differential','Woods Double','Woods Single',...
                                                       'Butterfly Differential','Butterfly Double','Butterfly Single'};
                app.Advanced_PlotTypeDropDown.Value = 'Woods Differential';
                
                set(plotTypeDropdown, 'Enable', 'On');
                set(unitsDropdown, 'Enable', 'On');
                set(confidenceDropdown, 'Enable', 'Off');
                set(highlight, 'Enable', 'on');
            end
        end

        % Button pushed function: RawCluster_SaveButton
        function RawCluster_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_RawCluster.csv'];
            if ~isempty(app.RawCluster_UITable.Data)
                writetable(app.RawCluster_UITable.Data,filename)
            end
        end

        % Button pushed function: Removed_SaveButton
        function Removed_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_RemovedPeptides.csv'];
            if ~isempty(app.Removed_UITable.Data)
                writetable(app.Removed_UITable.Data,filename)
            end
        end

        % Button pushed function: BXControl_SaveButton
        function BXControl_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_BX-ControlState.csv'];
            if ~isempty(app.BXControl_UITable.Data)
                writetable(app.BXControl_UITable.Data,filename)
            end
        end

        % Button pushed function: Correction_SaveButton
        function Correction_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_BX-NotCorrected.csv'];
            if ~isempty(app.Correction_UITable.Data)
                writetable(app.Correction_UITable.Data,filename)
            end
        end

        % Button pushed function: StateAState_SaveButton
        function StateAState_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_StateA-all.csv'];
            if ~isempty(app.StateAState_UITable.Data)
                writetable(app.StateAState_UITable.Data,filename)
            end
        end

        % Button pushed function: StateBState_SaveButton
        function StateBState_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_StateB-all.csv'];
            if ~isempty(app.StateBState_UITable.Data)
                writetable(app.StateBState_UITable.Data,filename)
            end
        end

        % Button pushed function: StatesABCommon_SaveButton
        function StatesABCommon_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_StatesAB-Common.csv'];
            if ~isempty(app.StatesABCommon_UITable.Data)
                writetable(app.StatesABCommon_UITable.Data,filename)
            end
        end

        % Button pushed function: StateBStateA_SaveButton
        function StateBStateA_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_difference.csv'];
            if ~isempty(app.StateBStateA_UITable.Data)
                writetable(app.StateBStateA_UITable.Data,filename)
            end
        end

        % Button pushed function: FilteredData_SaveButton
        function FilteredData_SaveButtonPushed(app, event)
            filename = [app.cluster.pathname,app.cluster.filename(1:end-4),'_Table_FilteredPeptides.csv'];
            if ~isempty(app.FilteredData_UITable.Data)
                writetable(app.FilteredData_UITable.Data,filename)
            end
        end

        % Value changed function: Coverage_DataTypeDropDown
        function Coverage_DataTypeDropDownValueChanged(app, event)
            value = app.Coverage_DataTypeDropDown.Value;
           
            if contains(value,'Barcode')
                app.Coverage_PeptidesDropDown.Enable = 'off';
                app.Coverage_PeptidesDropDownLabel.Enable = 'off';
                app.Coverage_BorderColorDropDown.Enable = 'off';
                app.Coverage_BorderColorDropDownLabel.Enable = 'off';
                app.Coverage_BarSpacingEditBox.Enable = 'off';
                app.Coverage_BarspacingEditFieldLabel.Enable = 'off';
                app.Coverage_BarThicknessEditBox.Enable = 'off';
                app.Coverage_BarthicknessEditFieldLabel.Enable = 'off';
            else
                app.Coverage_PeptidesDropDown.Enable = 'on';
                app.Coverage_PeptidesDropDownLabel.Enable = 'on';
                app.Coverage_BorderColorDropDown.Enable = 'on';
                app.Coverage_BorderColorDropDownLabel.Enable = 'on';
                app.Coverage_BarSpacingEditBox.Enable = 'on';
                app.Coverage_BarspacingEditFieldLabel.Enable = 'on';
                app.Coverage_BarThicknessEditBox.Enable = 'on';
                app.Coverage_BarthicknessEditFieldLabel.Enable = 'on';
            end
        end

        % Button pushed function: Advanced_clear
        function Advanced_clearButtonPushed(app, event)
            app.Advanced_yaxis_low.Value = '';
            app.Advanced_yaxis_high.Value = '';
        end

        % Value changed function: Advanced_PlotTypeDropDown
        function Advanced_PlotTypeDropDownValueChanged(app, event)
            value = app.Advanced_PlotTypeDropDown.Value;
            
            % statesDropdown = [app.Advanced_StatesDropDown app.Advanced_StatesDropDownLabel];
            unitsDropdown = [app.Advanced_UnitsDropDown app.Advanced_UnitsDropDownLabel];
            confidenceDropdown = [app.Advanced_ConfidenceDropDown app.Advanced_ConfidenceDropDownLabel];
            highlight = [app.Advanced_HighlightEditField];
            
            if contains(value,'Woods')
                if contains(value,'Double') | contains(value,'Single')
                    set(unitsDropdown, 'Enable', 'On');
                    set(confidenceDropdown, 'Enable', 'off');
                    set(highlight, 'Enable', 'On');
                else
                    set(unitsDropdown, 'Enable', 'On');
                    set(confidenceDropdown, 'Enable', 'On');
                    set(highlight, 'Enable', 'On');
                end
            elseif contains(value,'Butterfly')
                if contains(value,'Double') | contains(value,'Single')
                    set(unitsDropdown, 'Enable', 'On');
                    set(confidenceDropdown, 'Enable', 'off');
                    set(highlight, 'Enable', 'off');
                else
                    set(unitsDropdown, 'Enable', 'On');
                    set(confidenceDropdown, 'Enable', 'On');
                    set(highlight, 'Enable', 'off');
                end
            elseif contains(value,'Volcano')
                set(unitsDropdown, 'Enable', 'On');
                set(confidenceDropdown, 'Enable', 'On');
                set(highlight, 'Enable', 'Off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeuterosGUI = uifigure('Visible', 'off');
            app.DeuterosGUI.Name = 'UI Figure';
            app.Panel_Import = uipanel(app.DeuterosGUI);
            app.DataImport_Heading = uilabel(app.Panel_Import);
            app.DataImport_Heading.Text = 'Data Import';
            app.ImportButton = uibutton(app.Panel_Import, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = 'import_icon.png';
            app.ImportButton.Text = 'Import';
            app.ClusterBrowseButton = uibutton(app.Panel_Import, 'push');
            app.ClusterBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterBrowseButtonPushed, true);
            app.ClusterBrowseButton.Text = 'Browse';
            app.ClusterFileTextBoxLabel = uilabel(app.Panel_Import);
            app.ClusterFileTextBoxLabel.Text = 'Cluster';
            app.ClusterFileTextBox = uieditfield(app.Panel_Import, 'text');
            app.ClusterFileTextBox.Editable = 'off';
            app.StateBDropDownLabel = uilabel(app.Panel_Import);
            app.StateBDropDownLabel.Text = 'State B';
            app.StateBDropDown = uidropdown(app.Panel_Import);
            app.StateBDropDown.Items = {'None'};
            app.StateBDropDown.ValueChangedFcn = createCallbackFcn(app, @StateBDropDownValueChanged, true);
            app.StateBDropDown.Value = 'None';
            app.StateADropDown = uidropdown(app.Panel_Import);
            app.StateADropDown.Items = {};
            app.StateADropDown.Value = {};
            app.StateADropDownLabel = uilabel(app.Panel_Import);
            app.StateADropDownLabel.Text = 'State A';
            app.ProteinDropDown = uidropdown(app.Panel_Import);
            app.ProteinDropDown.Items = {};
            app.ProteinDropDown.ValueChangedFcn = createCallbackFcn(app, @ProteinDropDownValueChanged, true);
            app.ProteinDropDown.Value = {};
            app.ProteinDropDownLabel = uilabel(app.Panel_Import);
            app.ProteinDropDownLabel.Text = 'Protein';
            app.DeuteriumEditBoxLabel = uilabel(app.Panel_Import);
            app.DeuteriumEditBoxLabel.Text = 'Deuterium (%):';
            app.DeuteriumEditBox = uieditfield(app.Panel_Import, 'text');
            app.DeuteriumEditBox.Tooltip = {'Percentage deuterium in the labelling buffer, e.g. 0.8, 0.9, 0.95'};
            app.DeuteriumEditBox.Value = '100';
            app.Residue_StartEditBox = uieditfield(app.Panel_Import, 'text');
            app.Residue_StartEditBox.Tooltip = {'First residue number, e.g. 1'};
            app.Residue_ColonLabel = uilabel(app.Panel_Import);
            app.Residue_ColonLabel.Text = ':';
            app.Residue_Label = uilabel(app.Panel_Import);
            app.Residue_Label.Text = 'Residues';
            app.Residue_EndEditBox = uieditfield(app.Panel_Import, 'text');
            app.Residue_EndEditBox.Tooltip = {'Last residue number, e.g. 400'};
            app.BXCorrectionLabel = uilabel(app.Panel_Import);
            app.BXCorrectionLabel.Text = 'BX Correction';
            app.StateA_ControlDropDown = uidropdown(app.Panel_Import);
            app.StateA_ControlDropDown.Items = {'None', ''};
            app.StateA_ControlDropDown.Tooltip = {'(Optional) Select a state containing back-exchange control data for State A'};
            app.StateA_ControlDropDown.Value = 'None';
            app.StateB_ControlDropDown = uidropdown(app.Panel_Import);
            app.StateB_ControlDropDown.Items = {'None', ''};
            app.StateB_ControlDropDown.Tooltip = {'(Optional) Select a state containing back-exchange control data for State B'};
            app.StateB_ControlDropDown.Value = 'None';
            app.LogTextArea = uitextarea(app.Panel_Import);
            app.LogTextArea.Editable = 'off';
            app.TabGroup_Figure = uitabgroup(app.DeuterosGUI);
            app.UptakePlotsTab = uitab(app.TabGroup_Figure);
            app.UptakePlotsTab.Title = 'Uptake Plots';
            app.KineticsPanel = uipanel(app.UptakePlotsTab);
            app.OptionsPanel = uipanel(app.UptakePlotsTab);
            app.PlotStylingLabel = uilabel(app.OptionsPanel);
            app.PlotStylingLabel.Text = 'Plot Styling';
            app.DataOptionsLabel = uilabel(app.OptionsPanel);
            app.DataOptionsLabel.Text = 'Data Options';
            app.StatisticsLabel = uilabel(app.OptionsPanel);
            app.StatisticsLabel.Text = 'Statistics';
            app.LogtimeSwitchLabel = uilabel(app.OptionsPanel);
            app.LogtimeSwitchLabel.Text = 'Log time';
            app.LogtimeSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.LogtimeSwitch.ValueChangedFcn = createCallbackFcn(app, @LogtimeSwitchValueChanged, true);
            app.LogtimeSwitch.Tooltip = {'Switch to a log(time) x-axis'};
            app.DatapointsSwitchLabel = uilabel(app.OptionsPanel);
            app.DatapointsSwitchLabel.Text = 'Datapoints';
            app.DatapointsSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.DatapointsSwitch.ValueChangedFcn = createCallbackFcn(app, @DatapointsSwitchValueChanged, true);
            app.DatapointsSwitch.Tooltip = {'Switch datapoints on or off'};
            app.DatapointsSwitch.Value = 'On';
            app.LineSwitchLabel = uilabel(app.OptionsPanel);
            app.LineSwitchLabel.Text = 'Line';
            app.LineSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.LineSwitch.ValueChangedFcn = createCallbackFcn(app, @LineSwitchValueChanged, true);
            app.LineSwitch.Tooltip = {'Switch lines on or off'};
            app.LineSwitch.Value = 'On';
            app.LinewidthEditFieldLabel = uilabel(app.OptionsPanel);
            app.LinewidthEditFieldLabel.Text = 'Linewidth';
            app.LinewidthEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.LinewidthEditField.Limits = [0.5 3];
            app.LinewidthEditField.ValueChangedFcn = createCallbackFcn(app, @LinewidthEditFieldValueChanged, true);
            app.LinewidthEditField.Value = 1.8;
            app.MarkersizeEditFieldLabel = uilabel(app.OptionsPanel);
            app.MarkersizeEditFieldLabel.Text = 'Marker size';
            app.MarkersizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.MarkersizeEditField.Limits = [30 100];
            app.MarkersizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersizeEditFieldValueChanged, true);
            app.MarkersizeEditField.Value = 50;
            app.ShowSDSwitchLabel = uilabel(app.OptionsPanel);
            app.ShowSDSwitchLabel.Text = 'Show SD';
            app.ShowSDSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ShowSDSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowSDSwitchValueChanged, true);
            app.ShowSDSwitch.Tooltip = {'Show standard deviations for datapoints'};
            app.ShowSDSwitch.Value = 'On';
            app.ShowCISwitchLabel = uilabel(app.OptionsPanel);
            app.ShowCISwitchLabel.Text = 'Show CI';
            app.ShowCISwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ShowCISwitch.ValueChangedFcn = createCallbackFcn(app, @ShowCISwitchValueChanged, true);
            app.ShowCISwitch.Tooltip = {'Show confidence intervals for the data'};
            app.ShowCISwitch.Value = 'On';
            app.PeptidesStatisticsTextArea = uitextarea(app.OptionsPanel);
            app.PeptidesStatisticsTextArea.Editable = 'off';
            app.CapsizeEditFieldLabel = uilabel(app.OptionsPanel);
            app.CapsizeEditFieldLabel.Text = 'Cap size';
            app.CapsizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CapsizeEditField.Limits = [10 20];
            app.CapsizeEditField.ValueChangedFcn = createCallbackFcn(app, @CapsizeEditFieldValueChanged, true);
            app.CapsizeEditField.Value = 10;
            app.xaxisEditFieldLabel = uilabel(app.OptionsPanel);
            app.xaxisEditFieldLabel.Tooltip = {'(Optional; Int or none) Enter x-axis limits for the kinetics plot'};
            app.xaxisEditFieldLabel.Text = 'x axis:';
            app.xaxis_low = uieditfield(app.OptionsPanel, 'text');
            app.xaxis_low.Tooltip = {'(Optional; Int or none) Enter x-axis limits for the kinetics plot'};
            app.xaxis_high = uieditfield(app.OptionsPanel, 'text');
            app.xaxis_high.Tooltip = {'(Optional; Int or none) Enter x-axis limits for the kinetics plot'};
            app.yaxisLabel = uilabel(app.OptionsPanel);
            app.yaxisLabel.Tooltip = {'(Optional; Int or none) Enter y-axis limits for the kinetics plot'};
            app.yaxisLabel.Text = 'y axis:';
            app.yaxis_low = uieditfield(app.OptionsPanel, 'text');
            app.yaxis_low.Tooltip = {'(Optional; Int or none) Enter y-axis limits for the kinetics plot'};
            app.yaxis_high = uieditfield(app.OptionsPanel, 'text');
            app.yaxis_high.Tooltip = {'(Optional; Int or none) Enter y-axis limits for the kinetics plot'};
            app.ModelDropDownLabel = uilabel(app.OptionsPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.OptionsPanel);
            app.ModelDropDown.Items = {'Peptide', 'Global'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Tooltip = {'Select to determine statistical significance using either the peptide significance test, or ''Global'' deuterium uptake threshold from Hageman & Weis 2019'};
            app.ModelDropDown.Value = 'Peptide';
            app.alphaDropDown = uidropdown(app.OptionsPanel);
            app.alphaDropDown.Items = {'0.001', '0.01', '0.02', '0.05'};
            app.alphaDropDown.ValueChangedFcn = createCallbackFcn(app, @alphaDropDownValueChanged, true);
            app.alphaDropDown.Tooltip = {'Select an alpha to use for statistical filtering'; ''; 'Confidence level = 100(1-alpha)%'};
            app.alphaDropDown.Value = '0.001';
            app.alphaDropDownLabel = uilabel(app.OptionsPanel);
            app.alphaDropDownLabel.Text = 'alpha';
            app.KineticsPlotLabel = uilabel(app.UptakePlotsTab);
            app.KineticsPlotLabel.Text = 'Kinetics Plot';
            app.Panel = uipanel(app.UptakePlotsTab);
            app.PeptideTree = uitree(app.Panel);
            app.PeptideTree.SelectionChangedFcn = createCallbackFcn(app, @PeptideTreeSelectionChanged, true);
            app.PeptideTreeLabel = uilabel(app.Panel);
            app.PeptideTreeLabel.Text = 'Peptide Tree';
            app.RefreshButton = uibutton(app.UptakePlotsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refreshes the kinetics plot'};
            app.RefreshButton.Text = 'Refresh';
            app.StandardMenuTab = uitab(app.TabGroup_Figure);
            app.StandardMenuTab.Title = 'Global Plots';
            app.Panel_Coverage = uipanel(app.StandardMenuTab);
            app.Panel_Advanced = uipanel(app.StandardMenuTab);
            app.Panel_PlotOptions = uipanel(app.StandardMenuTab);
            app.Coverage_Heading = uilabel(app.Panel_PlotOptions);
            app.Coverage_Heading.Text = 'Coverage Plot Options';
            app.Advanced_Heading = uilabel(app.Panel_PlotOptions);
            app.Advanced_Heading.Text = 'Advanced Plot Options';
            app.Coverage_DataTypeDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Coverage_DataTypeDropDownLabel.Text = 'Data type:';
            app.Coverage_DataTypeDropDown = uidropdown(app.Panel_PlotOptions);
            app.Coverage_DataTypeDropDown.Items = {'Coverage', 'Redundancy', 'Barcode-DU', 'Barcode-RU'};
            app.Coverage_DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Coverage_DataTypeDropDownValueChanged, true);
            app.Coverage_DataTypeDropDown.Value = 'Coverage';
            app.Coverage_PlotButton = uibutton(app.Panel_PlotOptions, 'push');
            app.Coverage_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Coverage_PlotButtonPushed, true);
            app.Coverage_PlotButton.Icon = 'plot_icon.png';
            app.Coverage_PlotButton.Text = 'Plot';
            app.Coverage_ExportButton = uibutton(app.Panel_PlotOptions, 'push');
            app.Coverage_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Coverage_ExportButtonPushed, true);
            app.Coverage_ExportButton.Icon = 'export_icon.png';
            app.Coverage_ExportButton.Text = 'Export';
            app.Advanced_StatisticsDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Advanced_StatisticsDropDownLabel.Text = 'Statistics:';
            app.Advanced_StatisticsDropDown = uidropdown(app.Panel_PlotOptions);
            app.Advanced_StatisticsDropDown.Items = {'Peptide', 'Hybrid', 'None'};
            app.Advanced_StatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @Advanced_StatisticsDropDownValueChanged, true);
            app.Advanced_StatisticsDropDown.Tooltip = {''};
            app.Advanced_StatisticsDropDown.Value = 'Peptide';
            app.MG_Heading = uilabel(app.Panel_PlotOptions);
            app.MG_Heading.Text = 'Export to Molecular Graphics';
            app.Advanced_PlotButton = uibutton(app.Panel_PlotOptions, 'push');
            app.Advanced_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Advanced_PlotButtonPushed, true);
            app.Advanced_PlotButton.Icon = 'plot_icon.png';
            app.Advanced_PlotButton.Text = 'Plot';
            app.Advanced_ExportButton = uibutton(app.Panel_PlotOptions, 'push');
            app.Advanced_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Advanced_ExportButtonPushed, true);
            app.Advanced_ExportButton.Icon = 'export_icon.png';
            app.Advanced_ExportButton.Text = 'Export';
            app.MG_ExportCoverageCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.MG_ExportCoverageCheckBox.Tooltip = {'Tick to include coverage and redundancy projections in the export'};
            app.MG_ExportCoverageCheckBox.Text = 'Export Coverage data';
            app.MG_ColorCoverageDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.MG_ColorCoverageDropDownLabel.Text = 'Coverage:';
            app.MG_ColorCoverageDropDown = uidropdown(app.Panel_PlotOptions);
            app.MG_ColorCoverageDropDown.Items = {'Red', 'Raspberry', 'Darksalmon', 'Salmon', 'Deepsalmon', 'Warmpink', 'Firebrick', 'Ruby', 'Chocolate', 'Brown', ' ', 'Green', 'Cartreuse', 'Splitpea', 'Smudge', 'Palegreen', 'Limegreen', 'Lime', 'Limon', 'Forest', ' ', 'Blue', 'Marine', 'Slate', 'Lightblue', 'Bluewhite', 'Skyblue', 'Purpleblue', 'Deepblue', 'Density', ' ', 'Yellow', 'Paleyellow', 'Yelloworange', 'Wheat', 'Sand', ' ', 'Magenta', 'Lightmagenta', 'Hotpink', 'Pink', 'Lightpink', 'Dirtyviolet', 'Violet', 'Violetpurple', 'Purpleblue', 'Deeppurple', ' ', 'Cyan', 'Palecyan', 'Aquamarine', 'Greencyan', 'Teal', 'Deepteal', 'Lightteal', ' ', 'Orange', 'Brightorange', 'Lightorange', 'Yelloworange', 'Olive', 'Deepolive', ' ', 'White', 'Gray90', 'Gray80', 'Gray70', 'Gray60', 'Gray50', 'Gray40', 'Gray30', 'Gray20', 'Gray10', 'Black'};
            app.MG_ColorCoverageDropDown.Tooltip = {'Select a colour to use for representing areas with coverage'};
            app.MG_ColorCoverageDropDown.Value = 'Blue';
            app.MG_ColorNoCoverageDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.MG_ColorNoCoverageDropDownLabel.Text = 'No coverage:';
            app.MG_ColorNoCoverageDropDown = uidropdown(app.Panel_PlotOptions);
            app.MG_ColorNoCoverageDropDown.Items = {'Red', 'Raspberry', 'Darksalmon', 'Salmon', 'Deepsalmon', 'Warmpink', 'Firebrick', 'Ruby', 'Chocolate', 'Brown', ' ', 'Green', 'Cartreuse', 'Splitpea', 'Smudge', 'Palegreen', 'Limegreen', 'Lime', 'Limon', 'Forest', ' ', 'Blue', 'Marine', 'Slate', 'Lightblue', 'Bluewhite', 'Skyblue', 'Purpleblue', 'Deepblue', 'Density', ' ', 'Yellow', 'Paleyellow', 'Yelloworange', 'Wheat', 'Sand', ' ', 'Magenta', 'Lightmagenta', 'Hotpink', 'Pink', 'Lightpink', 'Dirtyviolet', 'Violet', 'Violetpurple', 'Purpleblue', 'Deeppurple', ' ', 'Cyan', 'Palecyan', 'Aquamarine', 'Greencyan', 'Teal', 'Deepteal', 'Lightteal', ' ', 'Orange', 'Brightorange', 'Lightorange', 'Yelloworange', 'Olive', 'Deepolive', ' ', 'White', 'Gray90', 'Gray80', 'Gray70', 'Gray60', 'Gray50', 'Gray40', 'Gray30', 'Gray20', 'Gray10', 'Black'};
            app.MG_ColorNoCoverageDropDown.Tooltip = {'Select a colour to use for representing areas with no coverage'};
            app.MG_ColorNoCoverageDropDown.Value = 'Gray60';
            app.MG_ColorRedundancyDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.MG_ColorRedundancyDropDownLabel.Text = 'Redundancy:';
            app.MG_ColorRedundancyDropDown = uidropdown(app.Panel_PlotOptions);
            app.MG_ColorRedundancyDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', '', 'White_Blue1', 'White_Blue2', 'White_Blue3', 'White_Purple1', 'White_Purple2', 'White_Purple3', 'White_Red', 'White_Pink1', 'White_Pink2', 'White_Brown', 'White_Orange', 'White_Yellow', 'White_Black', 'White_Green1', 'White_Green2'};
            app.MG_ColorRedundancyDropDown.Tooltip = {'Select a colourmap to use for displaying redundancy'};
            app.MG_ColorRedundancyDropDown.Value = 'parula';
            app.MG_ExportAdvancedCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.MG_ExportAdvancedCheckBox.Tooltip = {'Tick to include Woods plot projections in the export'};
            app.MG_ExportAdvancedCheckBox.Text = 'Export Advanced data';
            app.MG_ExportButton = uibutton(app.Panel_PlotOptions, 'push');
            app.MG_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @MG_ExportButtonPushed, true);
            app.MG_ExportButton.Icon = 'export_icon.png';
            app.MG_ExportButton.Text = 'Export';
            app.Coverage_PlotTypeDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Coverage_PlotTypeDropDownLabel.Text = 'Plot type:';
            app.Coverage_PlotTypeDropDown = uidropdown(app.Panel_PlotOptions);
            app.Coverage_PlotTypeDropDown.Items = {'States A/B', 'State A', 'State B', 'Common', 'Comparative'};
            app.Coverage_PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Coverage_PlotTypeDropDownValueChanged, true);
            app.Coverage_PlotTypeDropDown.Value = 'States A/B';
            app.MG_ExportToDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.MG_ExportToDropDownLabel.Text = 'Export to:';
            app.MG_ExportToDropDown = uidropdown(app.Panel_PlotOptions);
            app.MG_ExportToDropDown.Items = {'PyMOL', 'Chimera'};
            app.MG_ExportToDropDown.ValueChangedFcn = createCallbackFcn(app, @MG_ExportToDropDownValueChanged, true);
            app.MG_ExportToDropDown.Tooltip = {'Select which Molecular Graphics Viewer: PyMOL or Chimera to export to'};
            app.MG_ExportToDropDown.Value = 'PyMOL';
            app.MG_ChainEditBoxLabel = uilabel(app.Panel_PlotOptions);
            app.MG_ChainEditBoxLabel.Text = 'Chain:';
            app.MG_ChainEditBox = uieditfield(app.Panel_PlotOptions, 'text');
            app.MG_ChainEditBox.Tooltip = {'Input a PDB chain for mapping, e.g. ''A'' (separate by comma if more than one)'};
            app.MG_ChainEditBox.Value = 'A,B,C';
            app.Advanced_ConfidenceDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Advanced_ConfidenceDropDownLabel.Text = 'alpha:';
            app.Advanced_ConfidenceDropDown = uidropdown(app.Panel_PlotOptions);
            app.Advanced_ConfidenceDropDown.Items = {'0.001', '0.01', '0.02', '0.05'};
            app.Advanced_ConfidenceDropDown.Tooltip = {'Alpha to determine statstical significance to. Confidence level = 100(1-alpha)%'};
            app.Advanced_ConfidenceDropDown.Value = '0.01';
            app.Coverage_PeptidesDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Coverage_PeptidesDropDownLabel.Text = 'Peptides:';
            app.Coverage_PeptidesDropDown = uidropdown(app.Panel_PlotOptions);
            app.Coverage_PeptidesDropDown.Items = {'On', 'Off'};
            app.Coverage_PeptidesDropDown.ValueChangedFcn = createCallbackFcn(app, @Coverage_PeptidesDropDownValueChanged, true);
            app.Coverage_PeptidesDropDown.Value = 'On';
            app.Coverage_BorderColorDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Coverage_BorderColorDropDownLabel.Text = 'Border:';
            app.Coverage_BorderColorDropDown = uidropdown(app.Panel_PlotOptions);
            app.Coverage_BorderColorDropDown.Items = {'Black', 'White', 'Off'};
            app.Coverage_BorderColorDropDown.Tooltip = {'Toggle between whether or not a border is shown for peptide bars'};
            app.Coverage_BorderColorDropDown.Value = 'Off';
            app.Coverage_BarspacingEditFieldLabel = uilabel(app.Panel_PlotOptions);
            app.Coverage_BarspacingEditFieldLabel.Text = 'Spacing:';
            app.Coverage_BarSpacingEditBox = uieditfield(app.Panel_PlotOptions, 'numeric');
            app.Coverage_BarSpacingEditBox.Limits = [0 1];
            app.Coverage_BarSpacingEditBox.Value = 0.5;
            app.Coverage_BarthicknessEditFieldLabel = uilabel(app.Panel_PlotOptions);
            app.Coverage_BarthicknessEditFieldLabel.Text = 'Thickness:';
            app.Coverage_BarThicknessEditBox = uieditfield(app.Panel_PlotOptions, 'numeric');
            app.Coverage_BarThicknessEditBox.Limits = [0.1 1];
            app.Coverage_BarThicknessEditBox.Value = 0.2;
            app.Advanced_UnitsDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Advanced_UnitsDropDownLabel.Text = 'Units:';
            app.Advanced_UnitsDropDown = uidropdown(app.Panel_PlotOptions);
            app.Advanced_UnitsDropDown.Items = {'Absolute'};
            app.Advanced_UnitsDropDown.Tooltip = {''};
            app.Advanced_UnitsDropDown.Value = 'Absolute';
            app.Advanced_PlotTypeDropDownLabel = uilabel(app.Panel_PlotOptions);
            app.Advanced_PlotTypeDropDownLabel.Text = 'Plot type:';
            app.Advanced_PlotTypeDropDown = uidropdown(app.Panel_PlotOptions);
            app.Advanced_PlotTypeDropDown.Items = {'Woods Differential', 'Butterfly Differential', 'Volcano'};
            app.Advanced_PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Advanced_PlotTypeDropDownValueChanged, true);
            app.Advanced_PlotTypeDropDown.Tooltip = {''};
            app.Advanced_PlotTypeDropDown.Value = 'Woods Differential';
            app.HighlightEditFieldLabel = uilabel(app.Panel_PlotOptions);
            app.HighlightEditFieldLabel.Text = 'Highlight:';
            app.Advanced_HighlightEditField = uieditfield(app.Panel_PlotOptions, 'text');
            app.Advanced_HighlightEditField.Tooltip = {'(Optional) Enter residue ranges (separated by commas if more than one range) to highlight on the Advanced Plot (Woods plot only), e.g. 1-20, 40-50, 100-120'};
            app.Advanced_yaxisLabel = uilabel(app.StandardMenuTab);
            app.Advanced_yaxisLabel.Text = 'y axis:';
            app.Advanced_yaxis_low = uieditfield(app.StandardMenuTab, 'text');
            app.Advanced_yaxis_high = uieditfield(app.StandardMenuTab, 'text');
            app.Advanced_clear = uibutton(app.StandardMenuTab, 'push');
            app.Advanced_clear.ButtonPushedFcn = createCallbackFcn(app, @Advanced_clearButtonPushed, true);
            app.Advanced_clear.Text = 'Clear';
            app.DataTables = uitab(app.TabGroup_Figure);
            app.DataTables.Title = 'Data Tables';
            app.TabGroup = uitabgroup(app.DataTables);
            app.RawClusterTab = uitab(app.TabGroup);
            app.RawClusterTab.Tooltip = {'Contains raw data filtered by protein'};
            app.RawClusterTab.Title = 'Raw Cluster';
            app.RawCluster_UITable = uitable(app.RawClusterTab);
            app.RawCluster_UITable.ColumnName = {''};
            app.RawCluster_UITable.RowName = {};
            app.RawCluster_SaveButton = uibutton(app.RawClusterTab, 'push');
            app.RawCluster_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @RawCluster_SaveButtonPushed, true);
            app.RawCluster_SaveButton.Icon = 'export_icon.png';
            app.RawCluster_SaveButton.Text = {'Save'; ''};
            app.RemovedPeptidesTab = uitab(app.TabGroup);
            app.RemovedPeptidesTab.Tooltip = {'Contains peptides that have been removed from differential analysis due to either being unique to one state, or have missing timepoints'; ''};
            app.RemovedPeptidesTab.Title = 'Removed Peptides';
            app.Removed_UITable = uitable(app.RemovedPeptidesTab);
            app.Removed_UITable.ColumnName = {''};
            app.Removed_UITable.RowName = {};
            app.Removed_SaveButton = uibutton(app.RemovedPeptidesTab, 'push');
            app.Removed_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Removed_SaveButtonPushed, true);
            app.Removed_SaveButton.Icon = 'export_icon.png';
            app.Removed_SaveButton.Text = {'Save'; ''};
            app.BXControlTab = uitab(app.TabGroup);
            app.BXControlTab.Tooltip = {'Contains back-exchange control data in state-like format'};
            app.BXControlTab.Title = 'BX: Control';
            app.BXControl_UITable = uitable(app.BXControlTab);
            app.BXControl_UITable.ColumnName = {''};
            app.BXControl_UITable.RowName = {};
            app.BXControl_SaveButton = uibutton(app.BXControlTab, 'push');
            app.BXControl_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @BXControl_SaveButtonPushed, true);
            app.BXControl_SaveButton.Icon = 'export_icon.png';
            app.BXControl_SaveButton.Text = {'Save'; ''};
            app.BXNoncorrectedTab = uitab(app.TabGroup);
            app.BXNoncorrectedTab.Tooltip = {'Contains peptides that were missing from the provided back-exchange control state and have not been corrected'};
            app.BXNoncorrectedTab.Title = 'BX: Non-corrected';
            app.Correction_UITable = uitable(app.BXNoncorrectedTab);
            app.Correction_UITable.ColumnName = {''};
            app.Correction_UITable.RowName = {};
            app.Correction_SaveButton = uibutton(app.BXNoncorrectedTab, 'push');
            app.Correction_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Correction_SaveButtonPushed, true);
            app.Correction_SaveButton.Icon = 'export_icon.png';
            app.Correction_SaveButton.Text = {'Save'; ''};
            app.StateAStateTab = uitab(app.TabGroup);
            app.StateAStateTab.Tooltip = {'Contains all State A peptides in state-like format'};
            app.StateAStateTab.Title = 'State A (State)';
            app.StateAState_UITable = uitable(app.StateAStateTab);
            app.StateAState_UITable.ColumnName = {''};
            app.StateAState_UITable.RowName = {};
            app.StateAState_SaveButton = uibutton(app.StateAStateTab, 'push');
            app.StateAState_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @StateAState_SaveButtonPushed, true);
            app.StateAState_SaveButton.Icon = 'export_icon.png';
            app.StateAState_SaveButton.Text = {'Save'; ''};
            app.StateBStateTab = uitab(app.TabGroup);
            app.StateBStateTab.Tooltip = {'Contains all State B peptides in state-like format'};
            app.StateBStateTab.Title = 'State B (State) ';
            app.StateBState_UITable = uitable(app.StateBStateTab);
            app.StateBState_UITable.ColumnName = {''};
            app.StateBState_UITable.RowName = {};
            app.StateBState_SaveButton = uibutton(app.StateBStateTab, 'push');
            app.StateBState_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @StateBState_SaveButtonPushed, true);
            app.StateBState_SaveButton.Icon = 'export_icon.png';
            app.StateBState_SaveButton.Text = {'Save'; ''};
            app.StatesABCommonTab = uitab(app.TabGroup);
            app.StatesABCommonTab.Tooltip = {'Contains peptides common to both states A and B'};
            app.StatesABCommonTab.Title = 'States AB (Common)';
            app.StatesABCommon_UITable = uitable(app.StatesABCommonTab);
            app.StatesABCommon_UITable.ColumnName = {''};
            app.StatesABCommon_UITable.RowName = {};
            app.StatesABCommon_SaveButton = uibutton(app.StatesABCommonTab, 'push');
            app.StatesABCommon_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @StatesABCommon_SaveButtonPushed, true);
            app.StatesABCommon_SaveButton.Icon = 'export_icon.png';
            app.StatesABCommon_SaveButton.Text = {'Save'; ''};
            app.StateBStateATab = uitab(app.TabGroup);
            app.StateBStateATab.Tooltip = {'Contains difference data between States B and A. Differences are calculated as StateB-StateA'};
            app.StateBStateATab.Title = 'Δ(State B - State A)';
            app.StateBStateA_UITable = uitable(app.StateBStateATab);
            app.StateBStateA_UITable.ColumnName = {''};
            app.StateBStateA_UITable.RowName = {};
            app.StateBStateA_SaveButton = uibutton(app.StateBStateATab, 'push');
            app.StateBStateA_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @StateBStateA_SaveButtonPushed, true);
            app.StateBStateA_SaveButton.Icon = 'export_icon.png';
            app.StateBStateA_SaveButton.Text = {'Save'; ''};
            app.FilteredPeptidesTab = uitab(app.TabGroup);
            app.FilteredPeptidesTab.Tooltip = {'Modified table of peptides from the ''Δ(State B - State A)'' table. Additionally lists the significance category after using the ''Advanced Plot'' function.'};
            app.FilteredPeptidesTab.Title = 'Filtered Peptides';
            app.FilteredData_UITable = uitable(app.FilteredPeptidesTab);
            app.FilteredData_UITable.ColumnName = {''};
            app.FilteredData_UITable.RowName = {};
            app.FilteredData_SaveButton = uibutton(app.FilteredPeptidesTab, 'push');
            app.FilteredData_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredData_SaveButtonPushed, true);
            app.FilteredData_SaveButton.Icon = 'export_icon.png';
            app.FilteredData_SaveButton.Text = {'Save'; ''};
            app.DeuterosLogo = uiimage(app.DeuterosGUI);
            app.DeuterosLogo.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.DeuterosGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_20200510

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeuterosGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeuterosGUI)
        end
    end
end